classdef CaSignalsAnalysisXYT < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Menu_openImg                    matlab.ui.container.Menu
        Menu_settings                   matlab.ui.container.Menu
        Menu_loadAnalyzedImgSeries      matlab.ui.container.Menu
        Menu_saveAxesAsImgs             matlab.ui.container.Menu
        Menu_convertMatFileToTIFFs      matlab.ui.container.Menu
        TabGroup                        matlab.ui.container.TabGroup
        Tab_CaEventsDetection           matlab.ui.container.Tab
        Panel_cellMask                  matlab.ui.container.Panel
        CheckBox_cellMaskForEachFrame   matlab.ui.control.CheckBox
        Panel_cellMaskManual            matlab.ui.container.Panel
        CheckBox_cellMask_manulalCorrection_fillHoles  matlab.ui.control.CheckBox
        CheckBox_cellMask_manualCorrection_toAll  matlab.ui.control.CheckBox
        Button_cellMask_manualCorrection  matlab.ui.control.Button
        CheckBox_cellMask_show          matlab.ui.control.CheckBox
        EditField_kernelSz_cellMask     matlab.ui.control.NumericEditField
        kernelsizeLabel                 matlab.ui.control.Label
        Button_cellMask                 matlab.ui.control.Button
        Panel_imgVisual                 matlab.ui.container.Panel
        Panel_imgFilters                matlab.ui.container.Panel
        Button_butterworthFilter        matlab.ui.control.Button
        UITable_filters                 matlab.ui.control.Table
        filterimageserieswithselectedfiltersButton  matlab.ui.control.Button
        Panel_cropImageSeries           matlab.ui.container.Panel
        Button_restart                  matlab.ui.control.Button
        EditField_cropStartFrame        matlab.ui.control.NumericEditField
        startframeLabel                 matlab.ui.control.Label
        EditField_cropEndFrame          matlab.ui.control.NumericEditField
        endframeLabel                   matlab.ui.control.Label
        Button_crop                     matlab.ui.control.Button
        Panel_visualisation             matlab.ui.container.Panel
        EditField_setglobalmax          matlab.ui.control.NumericEditField
        setglobalmaxEditFieldLabel      matlab.ui.control.Label
        EditField_setglobalmin          matlab.ui.control.NumericEditField
        setglobalminEditFieldLabel      matlab.ui.control.Label
        DropDown_lookuptable            matlab.ui.control.DropDown
        lookuptableDropDownLabel        matlab.ui.control.Label
        Lamp_state                      matlab.ui.control.Lamp
        stateLampLabel                  matlab.ui.control.Label
        EditField_imgTime               matlab.ui.control.NumericEditField
        timemsLabel                     matlab.ui.control.Label
        EditField_framesPerSec          matlab.ui.control.NumericEditField
        framessEditFieldLabel           matlab.ui.control.Label
        Spinner_imgFrame                matlab.ui.control.Spinner
        frame1SpinnerLabel              matlab.ui.control.Label
        Button_stop                     matlab.ui.control.Button
        CheckBox_playInLoop             matlab.ui.control.CheckBox
        Button_play                     matlab.ui.control.Button
        Slider_imgFrame                 matlab.ui.control.Slider
        Panel_caEventsDetection         matlab.ui.container.Panel
        CheckBox_saveDetection          matlab.ui.control.CheckBox
        Panel_batchMode                 matlab.ui.container.Panel
        CheckBox_calcSelfRatioInBatch   matlab.ui.control.CheckBox
        CheckBox_findCellMaskInBatch    matlab.ui.control.CheckBox
        DropDown_typeOfAnalysisInBatch  matlab.ui.control.DropDown
        typeofprocessDropDownLabel      matlab.ui.control.Label
        CheckBox_includeSubfolders      matlab.ui.control.CheckBox
        Button_batchAnalysis            matlab.ui.control.Button
        Panel_unetThresholds            matlab.ui.container.Panel
        EditField_spark_thr             matlab.ui.control.NumericEditField
        sparkEditField_5Label           matlab.ui.control.Label
        EditField_wave_thr              matlab.ui.control.NumericEditField
        waveEditFieldLabel              matlab.ui.control.Label
        EditField_puff_thr              matlab.ui.control.NumericEditField
        puffEditFieldLabel              matlab.ui.control.Label
        Button_detectevents             matlab.ui.control.Button
        DropDown_detectionMethod        matlab.ui.control.DropDown
        selectmethodDropDownLabel       matlab.ui.control.Label
        UITable_imgMeta                 matlab.ui.control.Table
        UIAxes_avrgCellSignal           matlab.ui.control.UIAxes
        UIAxes_img                      matlab.ui.control.UIAxes
        Tab_analysedEventsBrowser       matlab.ui.container.Tab
        Button_save                     matlab.ui.control.Button
        Panel_imgVisual_events          matlab.ui.container.Panel
        CheckBox_showSelectedEvent      matlab.ui.control.CheckBox
        CheckBox_showCellMask_events    matlab.ui.control.CheckBox
        CheckBox_showBB_events          matlab.ui.control.CheckBox
        CheckBox_showmask_events        matlab.ui.control.CheckBox
        CheckBox_showevents_events      matlab.ui.control.CheckBox
        Panel_visualisation_events      matlab.ui.container.Panel
        EditField_setglobalmax_events   matlab.ui.control.NumericEditField
        setglobalmaxEditFieldLabel_2    matlab.ui.control.Label
        EditField_setglobalmin_events   matlab.ui.control.NumericEditField
        setglobalminEditFieldLabel_2    matlab.ui.control.Label
        DropDown_lookuptable_events     matlab.ui.control.DropDown
        lookuptableDropDownLabel_3      matlab.ui.control.Label
        Lamp_state_events               matlab.ui.control.Lamp
        stateLampLabel_2                matlab.ui.control.Label
        EditField_imgTime_events        matlab.ui.control.NumericEditField
        timemsLabel_2                   matlab.ui.control.Label
        EditField_framesPerSec_events   matlab.ui.control.NumericEditField
        framessEditFieldLabel_2         matlab.ui.control.Label
        Spinner_imgFrame_events         matlab.ui.control.Spinner
        frame1SpinnerLabel_2            matlab.ui.control.Label
        Button_stop_events              matlab.ui.control.Button
        CheckBox_playInLoop_events      matlab.ui.control.CheckBox
        Button_play_events              matlab.ui.control.Button
        Slider_imgFrame_events          matlab.ui.control.Slider
        SelectedeventPanel              matlab.ui.container.Panel
        Button_refitEvent               matlab.ui.control.Button
        DropDown_spatialProfile         matlab.ui.control.DropDown
        UITable_eventParams             matlab.ui.control.Table
        UIAxes_eventXYprof              matlab.ui.control.UIAxes
        UIAxes_eventTprof               matlab.ui.control.UIAxes
        Panel_detectedEvents            matlab.ui.container.Panel
        UITable_events                  matlab.ui.control.Table
        UIAxes_img_events               matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        % save data used/created during analysis
        data = struct( ...
            'imgPath', [], ...
            'batchMode', false, ...
            'loadAnalyzedImgSeriesFlag', false, ...
            'pxSz', [], ...     % spatial pixel size
            'pxSzT', [], ...      % time step
            't', [], ... % time in ms
            'imgDataAll', [], ... % all image data as loaded by bioformats
            'imgDataRaw', [], ... % raw data, cropped, not filtered or normalized
            'imgData', [], ...    % fluorescent image data, filtered, normalized etc
            'cellMask', [], ...   % mask of cell in image series
            'crop_s_frame', [], ...   % crop start, end and crop ROI position data
            'crop_e_frame', [], ...
            'crop_roi_xy_pos', [], ...
            'detectedEvents', table(), ... % table with detected events, each row is event with data, position, mask, type, ...
            'selectedEvent', [], ...
            'condaEnvPath', [], ...
            'previousAppState', struct() ... % saved properties of closed app, such as paths to conda env... 
            );
    end
    
    properties (Access = private)
        % filter names used in app
        filterNames = {'none', 'gaussian', 'average', ...
            'median', 'anisotropic diffusion', 'PURE-let', ...
            'Butterworth filter'};
        classsesInDLM = {'none', 'background', 'spark', 'puff', ...
            'wave', 'transient', 'undefined'};
    end
    
    methods (Access = private)
        
        function showImgAndRescaleAxes(app, event)
            % get image data
            imgData = app.data.imgData;
            % resize axis based on image size (can be only smaller as original size)
            switch event.Source.Tag 
                case 'detectEvents'
                    %if strcmp(event.Source.Text, 'detect events')
                        % old positon of axes
                        old_pos = app.data.orgCompPos.axImg_events;
                        % handles to uicontrols in respective tab
                        ax = app.UIAxes_img_events;
                        dropDown_cmap = app.DropDown_lookuptable_events;
                        panel_visual = app.Panel_imgVisual_events;
                        edit_imgTime = app.EditField_imgTime_events;
                        slider_imgFrame = app.Slider_imgFrame_events;
                        spinner_imgFrame = app.Spinner_imgFrame_events;
                        spinner_label = app.frame1SpinnerLabel_2;
                        edit_imgGlobalMin = app.EditField_setglobalmin_events;
                        edit_imgGlobalMax = app.EditField_setglobalmax_events;
                        
                        % show tab for processing detected events
                        app.TabGroup.SelectedTab = app.Tab_analysedEventsBrowser;

                        % show detected events in table
                        app.UITable_events.Data = app.data.detectedEvents(:,[1,6,2,3,16]);
                        % add contex menu to export event
                        cm = uicontextmenu(app.UIFigure,'Tag', 'contexmenu_exportEvent');
                        m1 = uimenu(cm,'Text','export event as TIFF', ...
                            'MenuSelectedFcn', {@app.showSelectedEventInTableOfEvents});
                        m2 = uimenu(cm,'Text','export event as GIF', ...
                            'MenuSelectedFcn', {@app.showSelectedEventInTableOfEvents});
                        app.UITable_events.ContextMenu = cm;
                    %end

                case {'menu_openImg', 'cropImage', 'restartAnalysis'}
                    % old positon of axes
                    old_pos = app.data.orgCompPos.axImg;
                    % handles to uicontrols in respective tab
                    ax = app.UIAxes_img;
                    dropDown_cmap = app.DropDown_lookuptable;
                    panel_visual = app.Panel_imgVisual;
                    edit_imgTime = app.EditField_imgTime;
                    slider_imgFrame = app.Slider_imgFrame;
                    spinner_imgFrame = app.Spinner_imgFrame;
                    spinner_label = app.frame1SpinnerLabel;
                    edit_imgGlobalMin = app.EditField_setglobalmin;
                    edit_imgGlobalMax = app.EditField_setglobalmax;
            end

            if size(imgData,1) < size(imgData,2)
                r = size(imgData,1) / size(imgData,2);
                new_ax_h = ceil(old_pos(3)*r)*1.25;
                if new_ax_h > old_pos(4)
                    new_ax_h = old_pos(4);
                end
                % how much to shift axes and sliders
                dy = old_pos(4)-new_ax_h;
                new_pos = [ old_pos(1), old_pos(2)+dy, ...
                    old_pos(3), new_ax_h ];
            
            else
                r = size(imgData,2) / size(imgData,1);
                new_ax_w = ceil(old_pos(4)*r)*1.25;
                if new_ax_w > old_pos(3)
                    new_ax_w = old_pos(3);
                end
                dy = 0;
                new_pos = [ old_pos(1)+(old_pos(3)-new_ax_w)/2, ...
                    old_pos(2)+dy, new_ax_w, old_pos(4) ];
            end
            
            % change size of axis
            ax.InnerPosition = new_pos;
            
            % show first frame in tiff image series in image axes
            imgFirst = imgData(:,:,1);
            img_caxis_lims = prctile(imgData(:),[1 99]);
            cla(ax)

            imshow(imgFirst,'Parent',ax, ...
                'InitialMagnification','fit', ...
                'Border', 'tight');
            %image(imgFirst, ...
            %    'CDataMapping','scaled', ...
            %    'Parent',app.UIAxes_img);
            set(ax, 'XLim', [1 size(imgData,2)], ...
                'YLim', [1 size(imgData,1)])
            colormap(ax, dropDown_cmap.Value)
            caxis(ax, img_caxis_lims)
            ax.XAxis.Visible = "on";
            ax.YAxis.Visible = "on";
            
            % set position of panel for visualization control, move up and
            % down
            panel_visual.Position(2) = ... 
                ax.Position(2)-panel_visual.Position(4)-20;
            
            % setup time, image slider and selector
            edit_imgTime.Value = app.data.t(1);
            slider_imgFrame.Value = 1;
            slider_imgFrame.Limits = [1 size(imgData,3)];
            spinner_imgFrame.Value = 1;
            spinner_imgFrame.Limits = [1 size(imgData,3)];
            spinner_label.Text = 'frame #:';
            
            % set up limits for image as 
            edit_imgGlobalMin.Value = double(img_caxis_lims(1));
            edit_imgGlobalMax.Value = double(img_caxis_lims(2));
             
            % turn of axes interactivity
            disableDefaultInteractivity(ax)
            
            % save caxis limits
            app.data.imgDataCaxisLimits = img_caxis_lims;
            
            % specific for first tab
            if strcmp(event.Source.Tag, 'menu_openImg')
                % set up limints for crop edit fields
                app.EditField_cropStartFrame.Value = 1;
                app.EditField_cropEndFrame.Value = size(imgData,3);

                % maybe add, also with some parameters of function
                % set up GUI
                % set up pushbutton for cropping
                app.Button_crop.Text = 'select xy area to crop';
                app.Button_crop.FontWeight = 'normal';
            end
            
        end
        
        
        function showFilteredImgWithCellMask(app, event, resetCmapLims)
            
            % check if there are some image data
            if isempty(app.data.imgData)
                return
            end
           
            % get current selected image frame
            curr_frame = app.Spinner_imgFrame.Value;
            
            % show image
            img_series_h = findobj(app.UIAxes_img, 'Type', 'Image');
            img_series_h.CData = app.data.imgData(:,:,curr_frame);
     
            % change caxis limits only after filtering or cropping, but not
            % when moving with slider or spinner
            if resetCmapLims
                % set up and save caxis limits
                app.data.imgDataCaxisLimits = ...
                    prctile(app.data.imgData(:),[1 99]);
               
                img_caxis_lims = app.data.imgDataCaxisLimits;
                app.UIAxes_img.CLim = [img_caxis_lims(1) img_caxis_lims(2)];
                app.UIAxes_img.Title.String = 'XYT image series';
                
                app.EditField_setglobalmin.Value = double(img_caxis_lims(1));
                app.EditField_setglobalmax.Value = double(img_caxis_lims(2));
                
            end

            % check if to show cell boundary
            if app.CheckBox_cellMask_show.Value
                % get cell boundary
                cellBoundary = bwboundaries( ...
                    app.data.cellMask(:,:,curr_frame), 8, 'noholes');
                % delete already shown cell boundary, as patch
                delete( findobj(app.UIAxes_img, ...
                    'Type', 'Patch', '-or', 'Tag', 'cellMask') );
                % replot cell boundary as patch
                % select color for cell mask patch based on lookup table
                % used for image series visualization
                patch_color = app.cellMaskPatchColor;
                cell_boundary_h = patch( ...
                            'Faces',(1:numel(cellBoundary{1}(:,2))), ...
                            'Vertices',[cellBoundary{1}(:,2), ...
                            cellBoundary{1}(:,1)], ...
                            'FaceColor',patch_color, 'FaceAlpha',0.2, ...
                            'EdgeColor',patch_color, 'EdgeAlpha',1, ...
                            'Parent', app.UIAxes_img, ...
                            'LineWidth',1, 'Tag', 'cellMask');
            end

            % show global signal in axis
            avrg_F = zeros(size(app.data.imgData, 3),1);
            for i=1:size(app.data.imgData, 3)
                img = app.data.imgData(:,:,i);
                if isfield(app.data, 'cellMask')
                    % from cell area
                    m_cell = app.data.cellMask(:,:,i);
                    title_str = 'average signal from cell area';
                else
                    % from whole frame
                    m_cell = true(size(app.data.imgData, [1,2]));
                    title_str = 'average signal from whole frame';
                end
                avrg_F(i) = mean(img(m_cell));
            end
            % plot it
            plot(app.UIAxes_avrgCellSignal, ...
                (1:size(app.data.imgData, 3)), avrg_F, ...
                'Marker','.', 'MarkerSize',10, ...
                'Color','k', 'LineStyle','-')
            % set up axis
            set(app.UIAxes_avrgCellSignal, ...
                'XLim', [1 size(app.data.imgData, 3)], ...
                'YLim', [min(avrg_F)*0.99 max(avrg_F)*1.01])
            app.UIAxes_avrgCellSignal.Title.String = title_str;
            
        end
        
        
        function filterImgForAnalysis(app, type) 
            % get data
            imgDataRaw = app.data.imgDataRaw;
            imgDataFilt = ones(size(imgDataRaw));
            switch type
                case {'blank', 'cellEdge'}
                    % apply median and average filter, one after each other
                    kernel_sz = 5;
                    % median
                    for i=1:size(imgDataRaw,3)
                        imgDataFilt(:,:,i) = medfilt2(imgDataRaw(:,:,i), ...
                            [kernel_sz kernel_sz]);
                    end
                    % average
                    H = fspecial('average',[kernel_sz kernel_sz]);
                    for i=1:size(imgDataRaw,3)
                        imgDataFilt(:,:,i) = imfilter(imgDataFilt(:,:,i), ...
                            H, 'symmetric');
                    end
                
                case 'calculation_deltaF_div_F0'
                    kernel_sz = 3;
                    % median
                    for i=1:size(imgDataRaw,3)
                        imgDataFilt(:,:,i) = medfilt2(imgDataRaw(:,:,i), ...
                            [kernel_sz kernel_sz]);
                    end
                    
                case 'eventsDetection'
                    
                    
                case 'eventsAnalysis'
                    
            end
            % save filtered data
            app.data.imgData = imgDataFilt;
        end
        
        
        function showFilteredImgWithEventsBoundaries(app, event, ...
                resetCmapLims, createEventsHandlesInAllFrames)
        
            % get table with detected events
            try
                detectedEvents = app.data.detectedEvents;
            catch
                return
            end
            if isempty(detectedEvents)
                return
            end

            % get current selected image frame
            curr_frame = app.Spinner_imgFrame_events.Value;
            if curr_frame==size(app.data.imgData, 3)
                curr_frame = 1;
            end

            % show image and detected events
            img_series_h = findobj(app.UIAxes_img_events, 'Type', 'Image');
            if isempty(img_series_h)
                cla(app.UIAxes_img_events)
            else
                img_series_h.CData = app.data.imgData(:,:,curr_frame);
            end

            % change caxis limits only after filtering or cropping, but not
            % when moving with slider or spinner
            if resetCmapLims
               
                % set up and save caxis limits
                app.data.imgDataCaxisLimits = ...
                    prctile(app.data.imgData(:),[1 99]);
                
                img_caxis_lims = app.data.imgDataCaxisLimits;
                app.UIAxes_img_events.CLim = [img_caxis_lims(1) img_caxis_lims(2)];
                app.UIAxes_img_events.Title.String = 'XYT image series';
                
                app.EditField_setglobalmin_events.Value = double(img_caxis_lims(1));
                app.EditField_setglobalmax_events.Value = double(img_caxis_lims(2));
            
            end
           
            % check if to show cell boundary
            if app.CheckBox_showCellMask_events.Value
                % get calculated cell boundaries
                % get cell boundary
                cellBoundary = bwboundaries( ...
                    app.data.cellMask(:,:,curr_frame), 8, 'noholes');
                % get handle of already shown cell boundary
                cell_boundary_h = findobj(app.UIAxes_img_events, 'Type', 'Patch', ...
                    'Tag', 'cellMask');
                % get color of patch 
                patch_color = app.cellMaskPatchColor;
                if isempty(cell_boundary_h)
                    patch( ...
                        'Faces',(1:numel(cellBoundary{1}(:,2))), ...
                        'Vertices',[cellBoundary{1}(:,2), ...
                                    cellBoundary{1}(:,1)], ...
                        'FaceColor',patch_color, 'FaceAlpha',0, ... % 0.2
                        'EdgeColor',patch_color, 'EdgeAlpha',1, ...
                        'Parent', app.UIAxes_img_events, ...
                        'LineWidth', 1, 'Tag', 'cellMask');
                else
                    cell_boundary_h.Faces = (1:numel(cellBoundary{1}(:,2)));
                    cell_boundary_h.Vertices = [cellBoundary{1}(:,2), ...
                        cellBoundary{1}(:,1)];
                    
                end
            end
           
            % check if to create cell array of handles of events for each frame, 
            % plot them in events axis and then set Visible to off
            % create uicontextmenu to change type of event later
            % classes in analysis (background and none not included)
            % set in settings
            evntDLMclasses = cellstr(app.data.DLMclasses{ ...
                ~ismember(app.data.DLMclasses.("class name"), ...
                categorical({'background'; 'none'})), 1});
            % if threshold method was used add class "undefined"
            if strcmp(app.DropDown_detectionMethod.Value, 'threshold')
                 evntDLMclasses = [evntDLMclasses; {'undefined'}];
            end
            % uicontext menu
            cm = uicontextmenu(app.UIFigure, 'Tag','changeEventType');
            for m = 1:numel(evntDLMclasses)
                uimenu(cm, 'Text', evntDLMclasses{m}, ...
                    'MenuSelectedFcn',{@app.showSelectedEventInTableOfEvents});
            end

            if createEventsHandlesInAllFrames
                finalTableOfEvents = app.data.detectedEvents;
                eventsInAllFrames = cell(size(app.data.imgData,3),1);
                % get patches of all events in current frame
                for f=1:size(app.data.imgData,3)
                    eventsInFrame = gobjects(0);
                    for i=1:height(finalTableOfEvents)
                        % check if event is present in selected frame
                        eventPresence = ismember( ...
                            (finalTableOfEvents.imgNum_s(i):finalTableOfEvents.imgNum_e(i)), f);
                        if any(eventPresence)
                            % find boundary of event in selected frame
                            % in case it propagates to multiple frames
                            idxBoundary = find(eventPresence, 1, 'first');
                            eventsInFrame = [ ...
                                eventsInFrame; ...
                                patch( ...
                                'Faces',(1:numel(finalTableOfEvents.boundary{i}{idxBoundary}(:,2))), ...
                                'Vertices',[finalTableOfEvents.boundary{i}{idxBoundary}(:,2), ...
                                finalTableOfEvents.boundary{i}{idxBoundary}(:,1)], ...
                                'FaceColor', finalTableOfEvents.color(i,:), ...
                                'FaceAlpha', 0, ...
                                'EdgeColor', finalTableOfEvents.color(i,:), ...
                                'EdgeAlpha', 0, ...
                                'Parent', app.UIAxes_img_events, ...
                                'LineWidth', 2, ...
                                'Tag', sprintf('%d', finalTableOfEvents.eventID(i)), ...
                                'Visible','off', ...
                                'ButtonDownFcn', {@app.showSelectedEventInTableOfEvents}, ...
                                'UIContextMenu', cm) ...
                                ];
                        end
                    end
                    eventsInAllFrames(f) = {eventsInFrame};
                end
                app.data.eventsInAllFrames = eventsInAllFrames;
            end
                        
            % show events in current/selected frame
            % check if to show detected events
            if ~isempty(app.data.detectedEvents) && ...
                    app.CheckBox_showevents_events.Value
                % keyboard
                % get boundaries and colors of evens in current frame
                showEvents = true;
                eventsInAllFrames = app.data.eventsInAllFrames;
                
                % check if to show mask
                if app.CheckBox_showmask_events.Value
                    showMaskOfEvents = true;
                else
                    showMaskOfEvents = false;
                end
                % and boundary
                if app.CheckBox_showBB_events.Value
                    showBBofEvents = true;
                else
                    showBBofEvents = false;
                end
            else
                showEvents = false;
            end

            % set invisible previous ones
            switch event.Source.Type             
                
                case 'uispinner'
                    previousFrame = event.PreviousValue;
                    set(eventsInAllFrames{previousFrame}, ...
                       'Visible','off', 'FaceAlpha',0, 'EdgeAlpha',0)
                    
                case 'uislider'
                    cellfun(@(x) set(x, ...
                       'Visible','off', 'FaceAlpha',0, 'EdgeAlpha',0), ...
                        eventsInAllFrames)                    
                otherwise
            end

            % show only the ones in frame of interest
            % check if to show all or only selected event
            if app.CheckBox_showSelectedEvent.Value
                m_selectedEvents = arrayfun( @(x) ...
                    strcmp(x.Tag, num2str(app.data.selectedEvent.eventID)), ...
                    eventsInAllFrames{curr_frame} );
            else
                m_selectedEvents = true(numel(eventsInAllFrames{curr_frame}),1);
            end
            % show only accepted events
            m_acceptedEvents = true(numel(eventsInAllFrames{curr_frame}),1);
            for e = 1:numel(eventsInAllFrames{curr_frame}) 
                m_acceptedEvents(e) = ...
                    app.UITable_events.Data.accepted( ...
                        str2double(eventsInAllFrames{curr_frame}(e).Tag)==...
                        app.UITable_events.Data.eventID );
            end
            m_selectedEvents = m_selectedEvents & m_acceptedEvents;
        
            if showEvents
                set(eventsInAllFrames{curr_frame}(m_selectedEvents), ...
                   'Visible', 'on')
            
            else
                set(eventsInAllFrames{curr_frame}(m_selectedEvents), ...
                   'Visible', 'off')
            end

            if showMaskOfEvents
                set(eventsInAllFrames{curr_frame}(m_selectedEvents), ...
                    'FaceAlpha', 0.2)
            else
                set(eventsInAllFrames{curr_frame}(m_selectedEvents), ...
                    'FaceAlpha', 0)
            end

            if showBBofEvents
                set(eventsInAllFrames{curr_frame}(m_selectedEvents), ...
                    'EdgeAlpha', 1)
            else
                set(eventsInAllFrames{curr_frame}(m_selectedEvents), ...
                    'EdgeAlpha', 0)
            end

        end


        function updateMask(app, hROI, event, hf)
            % hf = handle to uifigure
            % update cell mask with mask created from ROI
            switch hf.Tag
                case 'refitFig'
                    maskData = getappdata(hf, 'evntMaskCorr');
                otherwise
                    maskData = app.data.cellMaskCorr;
            end
            ROImask = createMask(hROI, maskData.correctedMask);
            prevCellMask = maskData.correctedMask;
            switch hROI.Tag
                case 'removeMask'
                    % remove mask created by ROI
                    maskData.correctedMask = ...
                        prevCellMask & ~(prevCellMask & ROImask);
                case 'addMask'
                    % add mask created by ROI
                    maskData.correctedMask = ...
                        prevCellMask | ROImask;
            end
            % update image of mask in dummy axes
            maskData.hImg_tempMaskCell.CData = ...
                maskData.correctedMask;
            maskData.hImg_tempMaskCell.AlphaData = ...
                double(maskData.correctedMask) .* ...
                max(maskData.hImg_tempMaskCell.AlphaData,[],'all');
            % save data
            switch hf.Tag
                case 'refitFig'
                    setappdata(hf, 'evntMaskCorr', maskData);
                otherwise
                    app.data.cellMaskCorr = maskData;
            end
        end
        
        
        function eventProfFits = ...
                getProfileAndParamsOfSelectedEvent(app, ...
                eventBoundary, imgNum_s, imgNum_e, ...
                eventType, tProfFitFun, smoothSpan, eID)
            % calculate profile and parameters of selected event
            % distinguish between [puff, spark] and wave
            % t-profile is calculated by averaging 1 um^2 around centre of event
            % x-profile is calculated by averaging of 5 ms around peak of
            % time profile
            % eventBoundary=boundary of event
            % imgNum_s=image where event starts
            % imgNum_e=image where event ends
            % eventType=type: puff or spark or wave
            if isempty(smoothSpan) 
                % used to smooth profile when looking for baseline 
                % and estimated knots in spline fitting
                smoothSpan = 50; % ms
            end
            if isempty(tProfFitFun)
                % t-profile fit function
                tProfFitFun = 'spline';
            end
            % number of pixels in xy, to calculate t-profile
            n_px_xy = ceil(1/app.data.pxSz); % in um
            % make sure it is odd
            if mod(n_px_xy, 2) == 0
                n_px_xy = n_px_xy+1;
            end
            % radius around centre in xy
            r_xy = (n_px_xy - 1)/2;
            if r_xy < 1
                r_xy = 1;
            end
            % keyboard
            % number of pixels for x profile, in case of local signals
            n_px_t = ceil(5/app.data.pxSzT); % in ms
            if mod(n_px_t,2)==0
                n_px_t = n_px_t+1;
            end
            % radius around centre in t
            r_t = (n_px_t - 1)/2;
            if r_t < 1
                r_t = 1;
            end
            % mask of individual event in whole movie
            indEvntMask = (app.data.eventsIDs == eID);
            % get event properties from xyt mask of event
            eventProps_xyt = regionprops3(indEvntMask, app.data.imgData, ...
                {'WeightedCentroid', 'Volume'});
            [~,maxVol_idx] = max(eventProps_xyt.Volume);
            eventProps_xyt = eventProps_xyt(maxVol_idx,:);
            % also calculate centroids of event in individual frames
            evntCenterPerFrame = zeros(numel(eventBoundary{1}), 2);
            for i = 1:(imgNum_e-imgNum_s+1)
                % get weighted centroid in respective frame
                eventProps = regionprops('table', ...
                    indEvntMask(:,:,i+imgNum_s-1), ...
                    app.data.imgData(:,:,i+imgNum_s-1), ...
                    'WeightedCentroid','Area');
                [~,idxMaxArea] = max([eventProps.Area]);
                % save centroids
                evntCenterPerFrame(i,:) = ...
                    eventProps.WeightedCentroid(idxMaxArea,:);
            end

            switch eventType{1}
                case {'spark', 'puff', 'undefined'} % local events
                    % event is local, centre is not moving much
                    % take average of centroids as center of event
                    centr_avrg = round(mean(evntCenterPerFrame,1));
                    centr_avrg = round(eventProps_xyt.WeightedCentroid);
                    % check if starting or endig rows are not negative 
                    % or bigger than size of image
                    r_s = centr_avrg(2)-r_xy;
                    if r_s < 1
                        r_s = 1;
                    end
                    r_e = centr_avrg(2)+r_xy;
                    if r_e > size(app.data.imgData,1)
                        r_e = size(app.data.imgData,1);
                    end
                    % check if starting or endig columns are not negative 
                    % or bigger than size of image
                    c_s = centr_avrg(1)-r_xy;
                    if c_s < 1
                        c_s = 1;
                    end
                    c_e = centr_avrg(1)+r_xy;
                    if c_e > size(app.data.imgData,2)
                        c_e = size(app.data.imgData,2);
                    end
                    % calculate t-profile of event from whole series
                    t_prof_all = zeros(1, size(app.data.imgData, 3));
                    t_prof_all_detectedEvnts_mask = ...
                        false(1, size(app.data.imgData, 3));
                    for p = 1:size(app.data.imgData, 3)
                        t_prof_all(p) = ...
                            mean(app.data.imgData(r_s:r_e, c_s:c_e, p), 'all');
                        t_prof_all_detectedEvnts_mask(p) = ...
                            any(app.data.eventsIDs(r_s:r_e, c_s:c_e, p)>0, 'all');
                    end
                    % time of whole profile
                    t_all = (0:numel(t_prof_all)-1).*app.data.pxSzT;
                    % normalize whole time profile by fitting it without
                    % events
                    % try also check if there are some events in baseline fit,
                    % which were not detected
                    thrs_evnt = median(t_prof_all(~t_prof_all_detectedEvnts_mask)) + ...
                        mad(t_prof_all(~t_prof_all_detectedEvnts_mask),1);
                    t_prof_all_evnts_mask = t_prof_all>thrs_evnt;

                    if sum(t_prof_all_detectedEvnts_mask | t_prof_all_evnts_mask) / ...
                            numel(t_prof_all_detectedEvnts_mask) > 0.5
                        t_prof_all_bs = repmat( ...
                            prctile(t_prof_all(~(t_prof_all_detectedEvnts_mask | t_prof_all_evnts_mask)), 10), ...
                            [1,numel(t_prof_all)] );
                    else
                        % check if there are baseline data at beggining and end of profile
                        % if no then fit with line (means profile starts with event
                        % (not necessary the one we are analyzing) directly)
                        if t_prof_all_detectedEvnts_mask(1) || ...
                                t_prof_all_detectedEvnts_mask(end) || ...
                                t_prof_all_evnts_mask(1) || ...
                                t_prof_all_evnts_mask(end)
                            fo_line = fit(t_all(:), t_prof_all(:), 'poly1', ...
                                'Normalize','on', ...
                                'Exclude', t_prof_all_detectedEvnts_mask | t_prof_all_evnts_mask, ...
                                'Robust','bisquare');
                            t_prof_all_bs = feval(fo_line, t_all(:));
                        else
                            t_prof_all_bs = t_prof_all;
                            t_prof_all_bs( ...
                                t_prof_all_detectedEvnts_mask | t_prof_all_evnts_mask) = nan;
                            t_prof_all_bs = smooth(t_all, t_prof_all_bs, 0.5, 'rloess');
                        end
                    end

                    % normalization to deltaF/F0
                    t_prof_all = (t_prof_all(:) - t_prof_all_bs(:)) ./ ...
                        (t_prof_all_bs(:) - app.data.img_blank);
                    % find resonable start and end of event, with some
                    % baseline
                    [s_evt_bs, e_evt_bs, ...
                        sE_inProfWithAdjustedBs, eE_inProfWithAdjustedBs] = ...
                        app.findMaskOfEventWithBaseline( ...
                        t_all, t_prof_all, app.data.pxSzT, ...
                        imgNum_s, imgNum_e, 't', eventType{1});
                    % t-profile of event of interest with added baseline
                    event_prof_t = t_prof_all(s_evt_bs:e_evt_bs);
                    % xy-profiles of event
                    % mask to find maximum in profile,
                    % to be sure it is in part of detected event,
                    % just in case if there is outlier in baseline,
                    % which was smoothed out
                    m_event = nan(size(event_prof_t));
                    m_event(sE_inProfWithAdjustedBs:...
                        eE_inProfWithAdjustedBs) = 1;
                    [~, max_pos_tprof] = max(event_prof_t.*m_event);
                    % frame to calculate FWHMs of event from
                    maxOfEventFrame = max_pos_tprof + s_evt_bs - 1;
                    % get mask of event max frame
                    BW_event = indEvntMask(:,:,maxOfEventFrame);
                    % get position of maximum of event
                    imgE_frame = double( ...
                        app.data.imgData(:,:,maxOfEventFrame) );
                    % get x and y profiles of event
                    % from frame with max value
                    imgE_frame_masked = imgE_frame;
                    imgE_frame_masked(~BW_event) = nan;
                    [r_evnt_m, c_evnt_m] = find( ...
                        imgE_frame_masked==max(imgE_frame(BW_event)), ...
                        1 , 'first');
                    % x-profile
                    r_profX_s = r_evnt_m - r_t;
                    if r_profX_s<1, r_profX_s=1; end
                    r_profX_e = r_evnt_m + r_t;
                    if r_profX_e > size(app.data.imgData,1)
                        r_profX_e = size(app.data.imgData,1);
                    end
                    x_prof_all = mean(imgE_frame(r_profX_s:r_profX_e, :), 1);
                    x_all = (0:app.data.pxSz:(size(imgE_frame,2)-1)*app.data.pxSz);
                    % y-profile
                    c_profY_s = c_evnt_m - r_t;
                    if c_profY_s<1, c_profY_s=1; end
                    c_profY_e = c_evnt_m + r_t;
                    if c_profY_e > size(app.data.imgData,2)
                        c_profY_e = size(app.data.imgData,2);
                    end
                    y_prof_all = mean(imgE_frame(:, c_profY_s:c_profY_e), 2);
                    y_all = (0:app.data.pxSz:(size(imgE_frame,1)-1)*app.data.pxSz);

                    % find resonable start and end of event, with some
                    % baseline also for x and y profiles
                    [s_evt_bs_x, e_evt_bs_x, ...
                        sE_inProfWithAdjustedBs_x, eE_inProfWithAdjustedBs_x] = ...
                        app.findMaskOfEventWithBaseline( ...
                        x_all, x_prof_all, app.data.pxSz, ...
                        find(BW_event(r_evnt_m, :),1,'first'), ...
                        find(BW_event(r_evnt_m, :),1,'last'), 's', eventType{1});
                    [s_evt_bs_y, e_evt_bs_y, ...
                        sE_inProfWithAdjustedBs_y, eE_inProfWithAdjustedBs_y] = ...
                        app.findMaskOfEventWithBaseline( ...
                        y_all, y_prof_all, app.data.pxSz, ...
                        find(BW_event(:,c_evnt_m),1,'first'), ...
                        find(BW_event(:,c_evnt_m),1,'last'), 's', eventType{1});
                    % x-profile of event of interest with added baseline
                    event_prof_x = x_prof_all(s_evt_bs_x:e_evt_bs_x);
                    % y-profile of event of interest with added baseline
                    event_prof_y = y_prof_all(s_evt_bs_y:e_evt_bs_y);
                    % fit t-profile of event
                    switch tProfFitFun
                        case {'EMG', 'Gauss', 'mean'}
                            piecewiseFit = 'no';
                        otherwise
                            piecewiseFit = 'yes';
                    end
                    % fit t-profile of event
                    eventProfFit_t = app.fitEventProfile( ...
                        t_all(s_evt_bs:e_evt_bs), ...
                        event_prof_t, ...
                        sE_inProfWithAdjustedBs, ...
                        eE_inProfWithAdjustedBs, ...
                        piecewiseFit, tProfFitFun, smoothSpan);
                    % fit x and y profiles of event
                    eventProfFit_x = app.fitEventProfile( ...
                        x_all(s_evt_bs_x:e_evt_bs_x), ...
                        event_prof_x, ...
                        sE_inProfWithAdjustedBs_x, ...
                        eE_inProfWithAdjustedBs_x, ...
                        'no', 'Gauss', smoothSpan);
                    eventProfFit_y = app.fitEventProfile( ...
                        y_all(s_evt_bs_y:e_evt_bs_y), ...
                        event_prof_y, ...
                        sE_inProfWithAdjustedBs_y, ...
                        eE_inProfWithAdjustedBs_y, ...
                        'no', 'Gauss', smoothSpan);
                    % calculate parameters of event from fits of its profiles
                    eventParams_t = app.getParametersOfEventFromProfileFit(...
                        eventProfFit_t.x_ups, eventProfFit_t.yFit_ups, ...
                        eventProfFit_t.bs, eventProfFit_t.t0, ...
                        [], eventProfFit_t.y_m_ups, true, 't');
                    eventParams_x = app.getParametersOfEventFromProfileFit(...
                        eventProfFit_x.x_ups, eventProfFit_x.yFit_ups, ...
                        eventProfFit_x.bs, [], [], eventProfFit_x.y_m_ups, ...
                        false, 's');
                    eventParams_y = app.getParametersOfEventFromProfileFit(...
                        eventProfFit_y.x_ups, eventProfFit_y.yFit_ups, ...
                        eventProfFit_y.bs, [], [], eventProfFit_y.y_m_ups, ...
                        false, 's');
                    % create final output structure add fit and params = p
                    evntProfs.tProf.fit = eventProfFit_t;
                    evntProfs.tProf.p = eventParams_t;
                    evntProfs.xProf.fit = eventProfFit_x;
                    evntProfs.xProf.p = eventParams_x;
                    evntProfs.yProf.fit = eventProfFit_y;
                    evntProfs.yProf.p = eventParams_y;
                    % support and calculated parameters (SP)
                    evntSP.eventMass = ...
                        eventParams_t.amplitude * 1.206 * ...
                        mean([eventParams_x.FWHM,eventParams_y.FWHM])^3;
                    % wave speed calculation from pseudo linescan and from
                    % position of centroids
                    evntSP.waveSpeed_pls = nan; 
                    evntSP.waveSpeed_centroids = nan;
                    evntSP.SNR = eventParams_t.amplitude / ...
                        eventProfFit_t.sd_noise;
                    evntSP.maxOfEventFrame = maxOfEventFrame;
                    % start and end positions of rows and cols in 
                    % imgage series data to calculate x,y,t profiles
                    evntSP.tProf_row_s = r_s;
                    evntSP.tProf_row_e = r_e;
                    evntSP.tProf_col_s = c_s;
                    evntSP.tProf_col_e = c_e;
                    whole_tProf_evntMask = false(size(t_prof_all));
                    whole_tProf_evntMask(imgNum_s:imgNum_e) = true;
                    evntSP.whole_tProf_evntMask = whole_tProf_evntMask;
                    evntSP.xProf_row_s = r_profX_s;
                    evntSP.xProf_row_e = r_profX_e;
                    evntSP.yProf_col_s = c_profY_s;
                    evntSP.yProf_col_e = c_profY_e;
                    % event image position in whole max frame
                    evntSP.evntImg_row_s = s_evt_bs_y;
                    evntSP.evntImg_row_e = e_evt_bs_y;
                    evntSP.evntImg_col_s = s_evt_bs_x;
                    evntSP.evntImg_col_e = e_evt_bs_x;
                    % event maximum position in whole max frame
                    evntSP.eventMaxInWholeFrame = [r_evnt_m, c_evnt_m];
                    % event maximum position in event image
                    evntSP.eventMaxInEvntImg = ...
                        [r_evnt_m-s_evt_bs_y+1, c_evnt_m-s_evt_bs_x+1];
                    
                case 'wave'
                    % global event, wave is traveling through the cell
                    % calculate wave speed using centroids (c) of detected
                    % wave in each frame and also calculate wave speed from
                    % pseudo line scan
                    % calculate speed of wave from weighted centroids of
                    % individulal frames of detected wave
                    waveSpeed_all = nan(imgNum_e-imgNum_s,1);
                    for i = 1:(imgNum_e-imgNum_s)
                        dist_change = pdist2( evntCenterPerFrame(i,:), ...
                            evntCenterPerFrame(i+1,:), 'euclidean') * ...
                            app.data.pxSz;
                        % calculate wave speed in µm/s
                        waveSpeed_all(i) = (dist_change/app.data.pxSzT)*1000;    
                    end
                    % check for outliers
                    mOutlWaveSeedAll = isoutlier(waveSpeed_all, 'quartiles');
                    % calculate wave speed without outliers
                    waveSpeed_centriodsPos = ...
                        median(waveSpeed_all(~mOutlWaveSeedAll));

                    % calculate wave speed from pseudo linescans
                    % find major (longitudinal) and minor (transversal) axes of cell
                    cellFrame_bw = any( ...
                        app.data.cellMask(:, :, imgNum_s:imgNum_e), 3);
                    % find pseudo linescan by taking position of centroids of
                    % detected wave in individual frames
                    if sum(any(cellFrame_bw, 1)) >= sum(any(cellFrame_bw, 2))
                        % sort based on position of columns
                        evntCenterPerFrame = sortrows(evntCenterPerFrame, 1);
                    else
                        % sort based on position of rows
                        evntCenterPerFrame = sortrows(evntCenterPerFrame, 2);
                    end
                    % get mask of line through centroids of event from
                    % individual frames
                    % smmoth the line
                    n_points_s = ceil(5 / ...
                        (max(pdist(evntCenterPerFrame))/size(evntCenterPerFrame,1)));
                    centreOfWavePerFrame_s = smooth( ...
                        evntCenterPerFrame(:,1), ...
                        evntCenterPerFrame(:,2), ...
                        n_points_s, 'rloess');
                    % figure
                    % plot(centreOfWavePerFrame(:,1),centreOfWavePerFrame(:,2),'ok')
                    % hold on
                    % plot(centreOfWavePerFrame(:,1),centreOfWavePerFrame_s, 'r')
                    % add line representing pseudo linescan on axes in app
                    hl = drawpolyline(app.UIAxes_img, ...
                        'Position',[evntCenterPerFrame(:,1) ...
                        centreOfWavePerFrame_s], ...
                        'InteractionsAllowed','none', ...
                        'LineWidth',2, 'Color','k', 'Visible','off');
                    % get line mask
                    line_mask = createMask(hl);
                    delete(hl)
                    % fill gaps in line if any
                    line_mask = bwskel(bwmorph(line_mask, 'bridge', inf));
                    % get positions of pixels for pseudo linescan
                    [ls_row_ind, ls_col_ind] = find(line_mask);
                    % construct pseudo linescan with mask
                    ls_img = zeros(numel(ls_row_ind), size(app.data.imgData,3));
                    ls_m = false(numel(ls_row_ind), size(app.data.imgData,3));
                    for ii = 1:numel(ls_row_ind)
                        ls_img(ii, :) = ...
                            app.data.imgData(ls_row_ind(ii), ls_col_ind(ii), :);
                        ls_m(ii, :) = ...
                            indEvntMask(ls_row_ind(ii), ls_col_ind(ii), :);
                    end
                    % calculate wave speed from pseudo linescan 
                    % going through centroids
                    try
                        ls_analysis = app.analyzeWaveInPseudoLineScan( ...
                            ls_img, ls_m);
                        % create final output structure add fit and params = p
                        evntProfs.tProf.fit = ls_analysis.deskewedWaveProfFit;
                        evntProfs.tProf.p = ls_analysis.waveParams;
                        % support and calculated parameters (SP)
                        evntSP.eventMass = nan;
                        % wave speed calculation from pseudo linescan and from
                        % position of centroids
                        evntSP.waveSpeed_pls = ls_analysis.waveSpeed.average;
                        evntSP.waveSpeed_centroids = waveSpeed_centriodsPos;
                        evntSP.SNR = ls_analysis.waveParams.amplitude / ...
                            ls_analysis.deskewedWaveProfFit.sd_noise;
                        % pseudo line scan
                        evntSP.wholeImg = ls_analysis.wholeImg;
                        evntSP.wholeImg_m = ls_analysis.wholeImg_m;
                        evntSP.pls_img = ls_analysis.imgE_bs;
                        evntSP.pls_img_m = ls_analysis.imgE_bs_m;
                        evntSP.pls_imgDeskewed = ls_analysis.deskewed_imgE_bs;
                        evntSP.pls_imgDeskewed_m = ls_analysis.deskewed_imgE_bs_m;
                        evntSP.params.ls_row_ind = ls_row_ind;
                        evntSP.params.ls_col_ind = ls_col_ind;
                        % wave start in image
                        evntSP.waveStartT = ls_analysis.waveStartT;
                        evntSP.waveStartFit = ls_analysis.waveStartFit;
                        evntSP.waveStartX = ls_analysis.waveStartX;
                    catch
                        evntProfs.tProf = [];
                        evntSP = [];
                    end
            end
            % output
            eventProfFits = struct( ...
                'evntProfs', evntProfs, ...
                'evntSP', evntSP);
        end
        
        
        function showSelectedEventInTableOfEvents(app, src, event)
            % mouse button 1:
            % - show selected event in image axis in table of events
            % - remove previously highlighted row
            % mouse button 3:
            % - option to change type of calcium event
            switch src.Type
                case 'patch'
                    switch event.Button
                        case 1
                            % remove highlight in table
                            removeStyle(app.UITable_events)

                            idx = find(str2double(src.Tag) == ...
                                app.UITable_events.Data.eventID);
                            % highlight row with selected event
                            s = uistyle('BackgroundColor','yellow');
                            addStyle(app.UITable_events, s, 'row', idx)

                            % save selected event
                            app.data.selectedEvent = app.UITable_events.Data(idx,:);
                            % scroll to highlighted row
                            scroll(app.UITable_events,'Row',idx)

                            % show profile of selected event
                            app.showProfilesAndFitsOfSelectedEvent()

                        case 3
                    end

                case 'uimenu'
                    switch src.Parent.Tag
                        case 'changeEventType'
                            % remove highlight in table
                            removeStyle(app.UITable_events)

                            % handle to current object
                            hCurrentPatchObj = gco(app.UIFigure);

                            idx = find(str2double(hCurrentPatchObj.Tag) == ...
                                app.UITable_events.Data.eventID);

                            % change type of event in table and also in saved
                            % matrix
                            app.data.detectedEvents.eventType( ...
                                app.data.detectedEvents.eventID == ...
                                str2double(hCurrentPatchObj.Tag) ) = {src.Text};

                            app.UITable_events.Data.eventType(idx) = {src.Text};

                            % highlight row with selected event
                            s = uistyle('BackgroundColor','yellow');
                            addStyle(app.UITable_events, s, 'row', idx)

                            % scroll to highlighted row
                            scroll(app.UITable_events,'Row',idx)

                            % save selected event
                            app.data.selectedEvent = app.UITable_events.Data(idx,:);

                        case 'contexmenu_exportEvent'
                            % export selected event
                            idx = find(app.data.selectedEvent.eventID == ...
                                app.data.detectedEvents.eventID);
                            % event data
                            allEventData = app.data.detectedEvents(idx, :);
                            evnt_rows = allEventData.fits_params_event.eventMaxImg2DFit.event_bs_rows;
                            evnt_cols = allEventData.fits_params_event.eventMaxImg2DFit.event_bs_cols;
                            s_evnt = allEventData.fits_params_event.tProfFit.s_evt_bs;
                            e_evnt = allEventData.fits_params_event.tProfFit.e_evt_bs;
                            try
                                event_imgData = app.data.imgData(...
                                    evnt_rows, evnt_cols, s_evnt:e_evnt);
                            catch
                                % rows and colls are nans
                                event_imgData = app.data.imgData(...
                                    :, :, s_evnt:e_evnt);
                            end
                            % export it as movie with added alpha channel
                            % for events and cell boundaries
                            [p,n,e] = fileparts(app.data.imgPath);
                            switch src.Text
                                case 'export event as TIFF'
                                    filePath = fullfile(p, ...
                                        sprintf('%s_eventID-%d_%s.tif', ...
                                        n, allEventData.eventID, ...
                                        allEventData.eventType{1}) );
                                    % where to save
                                    % get path to image
                                    [fn, fp] = uiputfile( ...
                                        {'*.tif'}, ...
                                        'save event', ...
                                        filePath);
                                    drawnow;
                                    figure(app.UIFigure);
                                    if fp==0
                                        return
                                    end
                                    % save to tif file
                                    app.saveDataToTIFFfile(...
                                        event_imgData, [], fullfile(fp,fn))

                                case 'export event as GIF'
                                    % create animated GIF
                                    filePath = fullfile(p, ...
                                        sprintf('%s_eventID-%d_%s.gif', ...
                                        n, allEventData.eventID, ...
                                        allEventData.eventType{1}) );
                                    % where to save
                                    % get path to image
                                    [fn, fp] = uiputfile( ...
                                        {'*.gif'}, ...
                                        'save event', ...
                                        filePath);
                                    drawnow;
                                    figure(app.UIFigure);
                                    if fp==0
                                        return
                                    end
                                    % save to gif file
                                    % get colormap
                                    map = feval( ...
                                        app.DropDown_lookuptable_events.Value, ...
                                        256);
                                    % save gif file
                                    event_imgData = im2uint8(event_imgData);
                                    for i = 1:size(event_imgData,3)
                                        if i == 1
                                            imwrite(event_imgData(:,:,i), ...
                                                map, ...
                                                fullfile(fp,fn), ...
                                                'gif', ...
                                                'LoopCount',Inf, ...
                                                'DelayTime',1/app.EditField_framesPerSec_events.Value);
                                        else
                                            imwrite(event_imgData(:,:,i), ...
                                                map, ...
                                                fullfile(fp,fn), ...
                                                'gif', ...
                                                'WriteMode','append', ...
                                                'DelayTime',1/app.EditField_framesPerSec_events.Value);
                                        end
                                    end                                    
                            end
                    end
            end
        end % end function


        function combineDetectedEventsInTime(app, ...
                propsOfDetectedEvents, frame, detectedEventType)

            % check if there are some events in frame
            if isempty(propsOfDetectedEvents{frame})
                return
            end

            % get current eventID
            if isempty(app.data.eventsIDs_types)
                eventID = 0;
            else
                eventID = app.data.eventsIDs_types{end,1};
            end

            %%%%%
         
            % do it separately, problem with different types, return
            % eventsIDs for each type and after sum them or make 4D
            % eventsIDs matrix, now skipping events

            % in every image mask go through all events
            for event = 1:numel(propsOfDetectedEvents{frame})
                % for each event for through all image masks to see if
                % the events are connected
                % assigne ID to each event, if some event masks were
                % already processed, skip them
                maskOfEventInFrame = false(size(app.data.eventsIDs,[1,2]));
                maskOfEventInFrame(propsOfDetectedEvents{frame}(event).PixelIdxList) = true;

                idOfEventInFrame = app.data.eventsIDs(:,:,frame);
                % if roi already has a number assigned skip it
                if unique(idOfEventInFrame(maskOfEventInFrame))~=0
                    continue
                else
                    % update event ID and assign it to ROI
                    eventID = eventID+1;
                    idOfEventInFrame(maskOfEventInFrame) = eventID;
                    app.data.eventsIDs(:,:,frame) = idOfEventInFrame;

                    % save ID and type of event
                    if strcmp(app.DropDown_detectionMethod.Value, ...
                            'threshold')
                        detectedEventType = 'wave';
                    end
                    app.data.eventsIDs_types = [app.data.eventsIDs_types; ...
                        {eventID, detectedEventType}];
                end

                % check overlap of events in following frames of xyt series
                % with current event in current frame
                for nextFrame = frame+1:size(app.data.eventsIDs, 3)

                    % for the deeper frames update maskOfEventInFrame
                    if nextFrame > size(app.data.eventsIDs, 3)
                        continue
                    end

                    % previous frame and event index to compare with
                    if nextFrame == frame+1 || isnan(event_in_prevFrame)
                        prevEventIdx = event;
                        event_in_prevFrame = event;
                    end

                    maskOfEventInPrevFrame = false(size(app.data.eventsIDs,[1,2]));
                    maskOfEventInPrevFrame(propsOfDetectedEvents{nextFrame-1}(event_in_prevFrame).PixelIdxList) = true;

                    % check overlap of current event in frame with
                    % position nextFrame with events in next frame and repeat until no
                    % significant overlap or end of series
                    rois_iou = zeros(numel(propsOfDetectedEvents{nextFrame}),1);
                    centr_events_dist = zeros(numel(propsOfDetectedEvents{nextFrame}),1);
                    for event_in_nextFrame = 1:numel(propsOfDetectedEvents{nextFrame})
                        % initialize masks of events in previous and
                        maskOfEventInNextFrame = false(size(app.data.eventsIDs,[1,2]));
                        maskOfEventInNextFrame(...
                            propsOfDetectedEvents{nextFrame}(event_in_nextFrame).PixelIdxList) = true;
                        % check overlap
                        roi_intersection = maskOfEventInPrevFrame & maskOfEventInNextFrame;
                        roi_union = maskOfEventInPrevFrame | maskOfEventInNextFrame;
                        
                        rois_iou(event_in_nextFrame) = sum(roi_intersection(:)) / ...
                            sum(roi_union(:));
                        %min(sum(maskOfEventInPrevFrame(:)), sum(maskOfEventInNextFrame(:)));

                        % check also Euclidean distance of centroids of
                        % events in subsequent frames, in micrometers
                        centrOfEventInPrevFrame = ...
                            propsOfDetectedEvents{nextFrame-1}(event_in_prevFrame).WeightedCentroid;
                        centrOfEventInNextFrame = ...
                            propsOfDetectedEvents{nextFrame}(event_in_nextFrame).WeightedCentroid;

                        centr_events_dist(event_in_nextFrame) = ...
                            pdist([centrOfEventInPrevFrame; centrOfEventInNextFrame]) * ...
                            app.data.pxSz;
                    end

                    % check if any overlaps are bigger than 20%
                    if any( rois_iou > 0.20 )
                        % find idx of maximum overlap between events
                        [~, idx_maxOverlap] = max(rois_iou);
                        % take the biggest overlap
                        maskOfEventInNextFrame = false(size(app.data.eventsIDs,[1,2]));
                        maskOfEventInNextFrame(...
                            propsOfDetectedEvents{nextFrame}(idx_maxOverlap).PixelIdxList) = true;
                        % update eventsIDs matrix, merge overlapping events
                        idOfEventInNextFrame = app.data.eventsIDs(:,:,nextFrame);

                        idOfEventInNextFrame(maskOfEventInNextFrame) = eventID;
                        
                        app.data.eventsIDs(:,:,nextFrame) = idOfEventInNextFrame;
                        
%                         %%%%%%%%%%%%%%%%%%%%%%%
%                         %TEST; CHECK THIS, IT IS SLOW, TRY TO SOLVE
%                         test_array = zeros([64,512,500],"uint32");
%                         test_array = app.data.eventsIDs;
%                         test_matrix = ones([64,512],"uint32");
%                         pos = double(5);
%                         
%                         tic 
%                         test_array(:,:,pos) = test_matrix;
%                         
%                         toc
%                         
%                         %%%%%%%%%%%%%%%%%%%%%%%
                        
                        
                        event_in_prevFrame = idx_maxOverlap;
                    else
                        % no overlap, this event has ended
                        event_in_prevFrame = nan;
                        break
                    end
                end
            end
         

        end % end function


        function profFit = fitEventProfile(app, ...
                x, y, sE, eE, ...
                piecewise, model, smoothSpan)
            % data: x, y
            % start and end of event: sE, eE
            if sE<1, sE=1; end
            if eE>numel(x), eE=numel(x); end
            % create mask of profile
            y_m = false(size(y));
            y_m(sE:eE) = true;
            x = x(:);
            y = y(:);
            % step in x
            dx = mean(diff(x));
            % 5x upscale
            x_ups = linspace( ...
                min(x), max(x), numel(x)*5);
            x_ups = x_ups(:);
            % upscaled mask of event
            [~, sE_ups] = min(abs(x_ups-x(sE)));
            [~, eE_ups] = min(abs(x_ups-x(eE)));
            y_m_ups = false(size(x_ups));
            y_m_ups(sE_ups:eE_ups) = true;
            % max of event profile
            [maxV, maxP] = max(y(y_m));
            % options for fmincon
            opt = optimoptions( 'fmincon', ...
                'TolFun',1e-9, 'TolX',1e-9, 'TolCon',1e-9,...
                'MaxIter',1000, 'MaxFunEvals',3000 );

            % do fitting
            try
                % estimates of p0 = [t0, bs]
                p0 = [x(sE), mean(y(1:sE))];
                % fit rise of event in time profile
                optFit = optimoptions('lsqnonlin', ...
                    'TolFun',1e-9, ...
                    'TolX',1e-9, ...
                    'MaxIter',1000, ...
                    'MaxFunEvals',3000, ...
                    'Display','off');
                funR = @(p,xR,yR) ((xR>=p(1)).*((1-exp(-(xR-p(1))./p(2))).*(p(3)) + p(4)) + ...
                    (xR<p(1)).*(p(4)))-yR;
                funR_eval = @(p,xR) (xR>=p(1)).*((1-exp(-(xR-p(1))./p(2))).*(p(3)) + p(4)) + ...
                    (xR<p(1)).*(p(4));
                funD = @(p,xD,yD) ( p(1).*exp(-(xD-p(2))/p(3)) + p(4) ) - yD;
                funD_eval = @(p,xD) ( p(1).*exp(-(xD-p(2))/p(3)) + p(4) );
                % rise part
                xR = x(1:maxP);
                yR = y(1:maxP);
                % correct for start of mask of event, also x start form 0
                if p0(1) > x(maxP) - dx
                    p0(1) = (maxP+sE-1)*dx - dx - 3;
                end
                if p0(1) < 0, p0(1) = 1; end
                p0Rise = [p0(1) 3 maxV p0(2)];  % [t0 tauR A bs]
                % fit rise
                try
                    coeffRise = lsqnonlin( ...
                        @(p)funR(p,xR,yR),...
                        p0Rise,...
                        [x(1), 0, 0, min(yR)], ...
                        [x(maxP) inf inf inf], ...
                        optFit);
                    t0 = coeffRise(1);
                    bs = coeffRise(4);
                catch
                    t0 = p0(1);
                    bs = p0(2);
                end
                % baseline at the end of event profile
                bs_end = mean(y(end-2:end));
                % estimate tauR
                % t0 in points, adjust for start of event
                t0_p = floor((t0-x(1))/dx);
                if t0_p<1, t0_p=1; end
                % check if tauR make sense
                try
                    if coeffRise(2) > max(xR(t0_p:end))-min(xR(t0_p:end))
                        % recalculate tauR
                        [~,p_tauR] = ...
                            min( abs( yR(t0_p:end) - (0.66*(maxV-bs)+bs) ) );
                        tauR_est = p_tauR*dx;
                    else
                        tauR_est = coeffRise(2);
                    end
                catch
                    % recalculate tauR
                    [~,p_tauR] = min( abs( yR(t0_p:end) - (0.66*(maxV-bs)+bs) ) );
                    tauR_est = p_tauR*dx;
                end
                % estimate tauD
                xD = x(maxP+1:end);
                yD = y(maxP+1:end);
                [~,p_tauD] = min( abs( yD - (0.33*(maxV-bs_end)+bs_end) ) );
                tauD_est = p_tauD*dx;
                % fit decay
                try
                    p0Decay = [maxV xD(1) tauD_est bs_end];
                    coeffDecay = lsqnonlin( ...
                        @(p)funD(p,xD,yD), ...
                        p0Decay,...
                        zeros(1,4), ...
                        ones(1,4).*inf,...
                        optFit);
                    tauDFitExp = coeffDecay(3);
                catch
                    tauDFitExp = tauD_est;
                end
                if isempty(tauDFitExp)
                    tauDFitExp = nan;
                end
                if isempty(tauR_est)
                    tauR_est = nan;
                end
                p0(1) = t0;
                p0(2) = bs;

                switch piecewise
                    case 'yes'
                        switch model
                            % here we used only spline, add more
                            case 'spline'
                                % coefficients: [t0, F01, spline coefficients]
                                nK = ceil( (max(x(x>p0(1))) - min(x(x>p0(1))))/(smoothSpan/2) );
                                splOrd = 4; % spline order, poly3
                                spl0  = spap2(nK, splOrd, x(x>p0(1)), y(x>p0(1)));  % first guess of spline coefficients
                                % newknt for a possibly better knot distribution
                                knots = newknt(spl0);
                                spl0  = spap2(knots, splOrd, x(x>p0(1)), y(x>p0(1)));
                                p0 = [p0(1), p0(2), spl0.coefs];
                                fitFun = @(p,x) (x<=p(1)).*p(2) + ...
                                    (x>p(1)).*fnval(spmak(spl0.knots,p(3:end)),x);
                                % coefficients
                                coef = p0;
                                tauD = tauDFitExp;
                                tauR = tauR_est;
                                % upscaled fit
                                yFit = fitFun(coef,x);
                                if ~isempty(x_ups)
                                    yFit_ups = pchip(x,yFit,x_ups);
                                else
                                    x_ups = [];
                                    yFit_ups = [];
                                end
                                bs = coef(2);
                                t0 = coef(1);

                            case '1expR1expD'
                                % Lacampagne et al, 1999
                                % coefficients: [t0, F01, tauR, A, t1, tauD, F02]
                                % fitFun = @(p,x) (x<p(1)).*p(2) + ...
                                %                 (x>=p(1) & x<=p(5)).*( p(2)+(1-exp(-(x-p(1))./p(3))).*p(4) ) + ...
                                %                 (x>p(5)).*( p(4).*(1-exp(-(p(5)-p(1))./p(3))).*exp(-(x-p(5))./p(6))+p(7) );
                                % maybe add plato - Ardo's function
                                p0 = [t0, bs, tauR_est, ...
                                    maxV-bs, x(maxP), tauD_est, ...
                                    mean(y(end-2:end))];
                                fitFun = @(p,x) (x<p(1)).*p(2) + ...
                                    (x>=p(1) & x<p(5)).*( p(2)+(1-exp(-(x-p(1))./p(3))).* (p(4)-p(2))) + ...
                                    (x>=p(5)).*( (p(4)-p(7)).*(1-exp(-(p(5)-p(1))./p(3))).*exp(-(x-p(5))./p(6))+p(7) );
                                % create function which will be optimize, return scalar
                                fitFunSum = @(p,x,y) sum( (y-fitFun(p,x)).^2 );
                                % refit with no bound
                                lb = zeros(size(p0));
                                ub = inf(size(p0));
                                coef = fmincon(@(p)fitFunSum(p,x,y),p0,[],[],[],[],lb,ub,[],opt);
                                % upscaled fit
                                yFit = fitFun(coef,x);
                                if ~isempty(x_ups)
                                    yFit_ups = fitFun(coef,x_ups);
                                    %yFit_ups = pchip(x,yFit,x_ups);
                                else
                                    x_ups = [];
                                    yFit_ups = [];
                                end
                                t0 = coef(1);
                                bs = coef(2);
                                tauR = coef(3);
                                tauD = coef(6);

                        end
                        % calculate noise estimate
                        try
                            [~, idx_t0] = min(abs(x-t0));
                            sd_noise = std(y(1:idx_t0));
                        catch
                            sd_noise = nan;
                        end

                    case 'no'
                        switch  model
                            case 'Gauss'
                                % fit with gaussian
                                % coefficients: [F0, A, w, xc]
                                % F0-baseline, A-amplitude, w-width of gaussian, xc-center
                                % estimates of p0
                                p0 = [mean(y(1:sE)), ...
                                      (maxV-mean(y(1:sE)))*(std(x(sE:eE))*sqrt(pi/2)), ...
                                      std(x(sE:eE)), ...
                                      x(maxP)];
                                % function definition
                                fitFun = @(p,x) p(1) + ...
                                    (p(2)./(p(3).*sqrt(pi/2))) .* exp( (-2.*(x-p(4)).^2)./(p(3).^2) );
                                % do fitting
                                % create function which will be optimize, return scalar
                                wE = ones(size(y));
                                %wE(sE:eE) = (y(sE:eE)+1).^2;
                                fitFunSum = @(p,x,y) sum( wE.*(y-fitFun(p,x)).^2 );
                                % fit
                                coef = fmincon( @(p)fitFunSum(p,x,y), ...
                                    p0, [], [], [], [], ...
                                    [min(y) max(y)-min(y) dx min(x)],...
                                    [inf inf max(x)-min(x) max(x)], ...
                                    [], opt );
                                % coeff to save
                                bs = coef(1);
                                tauD = tauDFitExp;
                                tauR = tauR_est;
                                yFit = fitFun(coef,x);
                                if ~isempty(x_ups)
                                    yFit_ups = fitFun(coef,x_ups);
                                else
                                    x_ups = [];
                                    yFit_ups = [];
                                end

                            case 'EMG'
                                % fit with exponentially modified gaussian
                                % coefficients: [A, m, sd, tau, F0]
                                % estimates of p0
                                % baseline at the end of event profile
                                bs_end = mean(y(end-2:end));
                                [~,p_tauD] = min(abs(y(maxP+1:end)-(0.33*(maxV-bs_end)+bs_end)));
                                tauD_est = p_tauD*dx;
                                p0 = [maxV x(maxP) std(x(sE:eE)) tauD_est mean(y(1:sE))];
                                % function definition
                                fitFun = @(p,x) p(1).*exp(p(2)/p(4) + p(3)^2/(2*p(4)^2) - x./p(4)) .* ...
                                    cdf('Normal',x,p(2)+(p(3)^2/p(4)),p(3)) + p(5);
                                fitFunSum = @(p,x,y) sum( (y-fitFun(p,x)).^2 );
                                % fit
                                coef = fmincon(@(p)fitFunSum(p,x,y),p0,...
                                    [],[],[],[],zeros(size(p0)),ones(size(p0)).*inf,[],opt);
                                bs = coef(5);
                                tauD = coef(4);
                                tauR = tauR_est;
                                yFit = fitFun(coef,x);
                                if ~isempty(x_ups)
                                    yFit_ups = fitFun(coef,x_ups);
                                else
                                    x_ups = [];
                                    yFit_ups = [];
                                end

                            case 'spline'
                                % fit with spline
                                nK = ceil( (max(x)-min(x))/(dx*3) ); % fit every 1 um
                                splOrd = 3; % spline order, poly3
                                spl0  = spap2(nK, splOrd, x, y);  % first guess of spline coefficients
                                % newknt for a possibly better knot distribution
                                knots = newknt(spl0);
                                spl0  = spap2(knots, splOrd, x, y);
                                fitFun = @(p,x) fnval(spmak(spl0.knots,spl0.coefs),x);
                                coef = spl0.coefs;
                                yFit = fitFun(coef,x);
                                if ~isempty(x_ups)
                                    yFit_ups = fitFun(coef,x_ups);
                                else
                                    x_ups = [];
                                    yFit_ups = [];
                                end
                                bs = nan;
                                tauD = tauDFitExp;
                                tauR = tauR_est;

                            case 'mean'
                                fitFun = @mean;
                                coef = mean(y);
                                bs = mean(y);
                                tauD = nan;
                                tauR = nan;
                                yFit = ones(size(x)).*coef;
                                yFit_ups = ones(size(x_ups)).*coef;
                            
                        end

                        t0 = nan;
                        sd_noise = nan;
                end

            catch ME
                % if fit could not be done, just do mean
                model = 'mean';
                fitFun = @mean;
                coef = mean(y);
                bs = mean(y);
                t0 = nan;
                sd_noise = nan;
                tauD = nan;
                tauR = nan;
                yFit = ones(size(x)).*coef;
                yFit_ups = ones(size(x_ups)).*coef;
            end

            % create output structure
            profFit = struct( ...
                'fitModel',model,...
                'fitFun', fitFun, ...
                'smoothSpan', smoothSpan, ...
                'coeff',coef,...
                'bs',bs,...
                't0',t0,...
                'tauD',tauD,...
                'tauR',tauR,...
                'sd_noise',sd_noise, ...
                'x',x,'y',y,...
                'y_m',y_m, 'y_m_ups', y_m_ups, ...
                'yFit',yFit(:),...
                'x_ups',x_ups(:),...
                'yFit_ups',yFit_ups(:) ...
                );
        end % end function
        
        
        function imgEventFit = fitEventImage(app, imgE, m_imgE, wP)
            % fit elliptical Gaussian to event image
            % https://en.wikipedia.org/wiki/Gaussian_function
            % parameters - p: 
            % [1=A, 2=mu_x, 3=sd_x, 4=mu_y, 5=sd_y, 6=rot, 7=F0]
            % rotation in degrees
            fitFun = @(p,x,y) p(1) .* exp(-( ...
                ((cosd(p(6))^2)/(2*p(3)^2)+(sind(p(6))^2)/(2*p(5)^2)).*(x-p(2)).^2 + ...
                2.*(-sind(2*p(6))/(4*p(3)^2)+sind(2*p(6))/(4*p(5)^2)).*(x-p(2)).*(y-p(4)) + ...
                ((sind(p(6))^2)/(2*p(3)^2)+(cosd(p(6))^2)/(2*p(5)^2)).*(y-p(4)).^2 )) ...
                + p(7);
            
            % imgE = event matrix to fit
            % m_imgE = mask of event; 
            % 1=baseline, >1=event to fit, 0=areas not to fit 
            % pixel size in x and y is the same
            % estimation of initial parameters of 2D gauss
            % baseline
            % set weights for fitting with 2D gauss = wE
            % weights are powered to wP = (1,2,3,...)
            % baseline=1, regions not to fit=0, event=1+event data points

            wE = m_imgE.*imgE;
            wE = wE+1;
            wE(isnan(wE)) = 0;
            wE = wE.^wP;

            bs = prctile(imgE(wE==1),25);

            % maximum
            maxImg = max(imgE(wE>1),[],'all');
            [max_r, max_c] = find(imgE==maxImg, 1, 'first');
            % get rows and columns of event to estimate std_x and std_y
            [r,c] = find(wE>1);
            % initial params
            % amplitude
            A = maxImg - bs;
            mu_x = max_c*app.data.pxSz;
            std_x = std(unique(c))*app.data.pxSz;
            mu_y = max_r*app.data.pxSz;
            std_y = std(unique(r))*app.data.pxSz;
            rot_gauss = 0; % rotation in degrees
            
            p0 = [A mu_x std_x mu_y std_y rot_gauss bs];
            % lower and upper bounds
            lb = zeros(size(p0));
            lb(1) = min(imgE,[],'all');
            lb(6) = -inf;
            lb(7) = min(imgE,[],'all');
            ub = inf(size(p0));
            % grids in micrometers
            [X,Y] = meshgrid((0:size(imgE,2)-1).*app.data.pxSz, ...
                (0:size(imgE,1)-1).*app.data.pxSz);
            
            % sum of squares, scalar
            fitFunSum = @(p,X,Y,D,wE) sum( sum( wE.*(D - fitFun(p,X,Y)).^2 ) );
            
            % options for fmincon
            opt = optimoptions('fmincon', ...
                'TolFun',1e-9, ...
                'TolX',1e-9, ...
                'TolCon',1e-9,...
                'MaxIter',1000, ...
                'MaxFunEvals',3000, ...
                'UseParallel',false);
            try
                coef2DGauss = fmincon(@(p)fitFunSum(p,X,Y,imgE,wE), ...
                    p0,[],[],[],[],lb,ub,[],opt);
            catch
                % estimate FWHM from p0
                coef2DGauss = p0;
            end

            % CHECK IF IT IS CORRECT
            % get FWHM parameter; in um
            FWHMx = 2*sqrt(2*log(2))*coef2DGauss(3); 
            FWHMy = 2*sqrt(2*log(2))*coef2DGauss(5); 
            if FWHMx>(max(X,[],'all')-min(X,[],'all'))
                FWHMx = 2*sqrt(2*log(2))*std_x;
            end
            if FWHMy>(max(Y,[],'all')-min(Y,[],'all'))
                FWHMy = 2*sqrt(2*log(2))*std_y; 
            end
            
            halfMax = coef2DGauss(7) + ...
                (max(fitFun(coef2DGauss,X,Y),[],'all') - coef2DGauss(7))/2;

%             F = fitFun(coef2DGauss,X,Y);
%             figure
%             mesh(X,Y,imgE,'EdgeColor','k','FaceColor','none')
%             hold on
%             surf(X,Y,F,'EdgeColor','none','FaceAlpha',0.75)
%             [M,c] = contour3(X, Y, F, ...
%                 [halfMax,halfMax], ...
%                 'LineWidth',2, ...
%                 'LineColor','k');
            
            % save results
            imgEventFit = struct( ...
                'fitModel', 'ellipticalGaussian', ...
                'fitFun', fitFun, ...
                'k_th_powerOfWeights', wP, ...
                'coeff', coef2DGauss,...
                'bs', coef2DGauss(7),...
                'halfMax', halfMax, ...
                'FWHMx', FWHMx, ...
                'FWHMy', FWHMy, ...
                'X', X, ...
                'Y', Y, ...
                'Z', imgE, ...
                'eventImgMask', wE, ...
                'gaussFit', fitFun(coef2DGauss,X,Y)...
                );

        end % end function


        function eventParams = getParametersOfEventFromProfileFit(app, ...
                x, y, bs, t0, peakData, y_m, ...
                normalized, typeOfProfile)
            % x, y = data (e.g. time and time profile)
            % bs = baseline value
            % t0 = start of event
            % peakData = position and value
            % y_m = mask of event in event profile
            % normalized = true or false
            % typeOfProfile = time ('t') or spatial ('s')

            if isempty(y_m), y_m = true(size(y)); end
            x_indx = (1:1:numel(x));
            x_indx = x_indx(:);
            % start and end of event from event profile mask
            sE = find(y_m,1,'first');
            if isempty(sE), sE = 1; end
            eE = find(y_m,1,'last');
            if isempty(eE), eE = numel(y_m); end

            % (F-F0)/(F0-blank)
            if isempty(peakData)
                if normalized
                    Ampl = max(y(y_m))-bs;
                else
                    Ampl = (max(y(y_m))-bs)/(bs-app.data.img_blank);
                end
            else
                if normalized
                    Ampl = peakData.val-bs;
                else
                    Ampl = (peakData.val-bs)/(bs-app.data.img_blank);
                end
            end

            switch typeOfProfile
                case 't'
                    % time parameters
                    % search for peak
                    if isempty(peakData)
                        %[val_t,pos_t] = max(t_prof);
                        [pks_t,locs_t,w_t,~] = findpeaks( ...
                            y(y_m), x(y_m),...
                            'SortStr','descend');
                    else
                        % look 15 ms around, in points
                        eps = ceil(15/mean(diff(x)));
                        startPoint = peakData.pos-eps;
                        if startPoint<1, startPoint=1; end
                        endPoint = peakData.pos+eps;
                        if endPoint>numel(y) 
                            endPoint=numel(y); 
                        end
                        [pks_t,locs_t,w_t,~] = findpeaks( ...
                            y(startPoint:endPoint),...
                            x(startPoint:endPoint), ...
                            'SortStr','descend');
                    end

                    try
                        val_t = pks_t(1);
                        pos_t = round((locs_t(1) - x(1))/mean(diff(x)));
                    catch
                        pks_t = y(1);
                        locs_t = 1;
                        w_t = 0;
                        val_t = y(1);
                        pos_t = 1;
                    end

                    try
                        % get full duration in half maximum
                        [FDHM, half_max_t, half_max_t_1, half_max_t_2] = ...
                            app.fullDurationCalc(x, y, sE, eE, val_t, pos_t, bs, 50);
                        [FD_05, val_05, pos_05_t_1, pos_05_t_2] = ...
                            app.fullDurationCalc(x, y, sE, eE, val_t, pos_t, bs, 5);

                        [~, ~, ~, ~, pos_25_1] = app.fullDurationCalc( ...
                            x, y, sE, eE, val_t, pos_t, bs, 25);
                        [~, ~, ~, ~, pos_75_1] = app.fullDurationCalc( ...
                            x, y, sE, eE, val_t, pos_t, bs, 75);

                    catch
                        FDHM = w_t(1);
                        half_max_t = nan;
                        half_max_t_1 = nan;
                        half_max_t_2 = nan;
                        FD_05 = nan;
                        val_05 = nan;
                        pos_05_t_1 = nan;
                        pos_05_t_2 = nan;
                        pos_25_1 = nan;
                        pos_75_1 = nan;
                    end

                    % find t0 (start of spark) as a cross section of 0 and line fit of part
                    % of event with amplitudes between 25 and 75 % of max amplitude

                    if pos_25_1 == pos_75_1
                        pos_25_1 = pos_25_1 - 1;
                    end

                    if isempty(t0)
                        try
                            f_line25_75 = fit(x(pos_25_1:pos_75_1), ...
                                y(pos_25_1:pos_75_1), 'poly1');
                            t_fit = x(find(y>bs,1,'first'):pos_t);
                            line25_75 = feval(f_line25_75, t_fit);
                            t0 = t_fit(find(line25_75<bs, 1, 'last'));
                        catch
                            % find t0 (start of spark) as 5% of   increase of  set manually
                            % t0 = t( find(t_prof > ((val_t-bs_t)/50 + bs_t), 1, 'first'));
                            t0 = x( numel(y) - ...
                                find(flipud(y) < ((val_t-bs)/50 + bs), 1, 'first') + 1);
                        end
                    end

                    % get time to peak
                    TTP = x(pos_t) - t0;
                    if TTP < 0
                        TTP = 0;
                    elseif isempty(TTP)
                        TTP = 0;
                    end
                    eventParams.t0 = t0;
                    eventParams.TTP = TTP;
                    eventParams.FDHM = FDHM;
                    eventParams.FD_05 = FD_05;
                    eventParams.t_max = x(pos_t);
                    eventParams.v_max = val_t;
                    eventParams.half_max_t = half_max_t;
                    eventParams.half_max_t_1 = half_max_t_1;
                    eventParams.half_max_t_2 = half_max_t_2;
                    eventParams.val_05 = val_05;
                    eventParams.pos_05_t_1 = pos_05_t_1;
                    eventParams.pos_05_t_2 = pos_05_t_2;

                case 's'
                    % get spatial parameters
                    %[val_x,pos_x] = max(x_prof);
                    if ~isempty(x)
                        [pks_x, locs_x, w_x, ~] = findpeaks(y, x, ...
                            'SortStr','descend', 'Annotate','extents');
                        try
                            if pks_x == max(y)
                                val_x = pks_x(1);
                                pos_x = round((locs_x(1) - x(1))/mean(diff(x)));
                            else
                                [val_x, pos_x] = max(y);
                                w_x = round(numel(y)/2)*mean(diff(x));
                            end
                        catch
                            pks_x = x_prof(1);
                            locs_x = 1;
                            w_x = 0;
                            val_x = pks_x(1);
                            pos_x = 1;
                        end
                    else
                        pks_x = nan;
                        val_x = nan;
                        w_x = nan;
                    end

                    try
                        half_max_x = (val_x(1)-bs)/2 + bs;

                        y_x1 = y(1:pos_x-1);
                        y_x2 = cat(1 ,zeros(pos_x-1,1), y(pos_x:(length(y))));

                        % find position of first half max
                        y_x1_r = flipud(y_x1);
                        d_y_x1 = gradient(y_x1_r);

                        indDer1 = find( d_y_x1>=0 & y_x1_r<half_max_x,1,'first');
                        if ~isempty(indDer1)
                            y_x1_r(indDer1:end) = 0;
                        end

                        y_x1 = flipud(y_x1_r);

                        [~,pos1_x] = min(abs(y_x1 - half_max_x));

                        % find position of second half max
                        d_y_x2 = gradient(y_x2);

                        indDer2 = find( d_y_x2>=0 & y_x2<half_max_x & x_indx>pos_x,1,'first');
                        if ~isempty(indDer2)
                            y_x2(indDer2:end) = 0;
                        end

                        [~,pos2_x] = min(abs(y_x2 - half_max_x));

                        half_max_x_1 = x(pos1_x);
                        half_max_x_2 = x(pos2_x);

                        FWHM = half_max_x_2 - half_max_x_1;

                        if isempty(FWHM) || isnan(FWHM)
                            FWHM = w_x(1);
                        end

                        if FWHM > max(x), FWHM = max(x); end

                    catch
                        FWHM = w_x(1);
                        half_max_x = nan;
                        half_max_x_1 = nan;
                        half_max_x_2 = nan;
                    end
                    % create output structure
                    eventParams.FWHM = FWHM;
                    eventParams.half_max_x = half_max_x;
                    eventParams.half_max_x_1 = half_max_x_1;
                    eventParams.half_max_x_2 = half_max_x_2;
            end
            % create output structure
            eventParams.amplitude = Ampl;
            eventParams.bs = bs;
        end % end function
        
        

        function showProfilesAndFitsOfSelectedEvent(app)
            % get selected event from uitable of events
            selectedEvent = app.data.selectedEvent;
            % get data and fits
            idx = find(selectedEvent.eventID == ...
                app.data.detectedEvents.eventID);
            selectedEventAll = app.data.detectedEvents(idx,:);
            eventFits = selectedEventAll.eventFits{1};
            % data
            % t-profile
            tProf = eventFits.evntProfs.tProf.fit.y;
            t = eventFits.evntProfs.tProf.fit.x;
            % upscaled
            t_ups = eventFits.evntProfs.tProf.fit.x_ups;
            tProfFit = eventFits.evntProfs.tProf.fit.yFit_ups;
            % spatial fits, only for local signals
            switch selectedEvent.eventType
                case {'wave', 'transient'}
                    x = [];
                    xProf = [];
                    y = [];
                    yProf = [];
                    FWHMx = nan;
                    FWHMy = nan;
                otherwise
                    x = eventFits.evntProfs.xProf.fit.x;
                    xProf = eventFits.evntProfs.xProf.fit.y;
                    x_ups = eventFits.evntProfs.xProf.fit.x_ups;
                    xProfFit = eventFits.evntProfs.xProf.fit.yFit_ups;
                    y = eventFits.evntProfs.yProf.fit.x;
                    yProf = eventFits.evntProfs.yProf.fit.y;
                    y_ups = eventFits.evntProfs.yProf.fit.x_ups;
                    yProfFit = eventFits.evntProfs.yProf.fit.yFit_ups;
                    % support parameters
                    half_max_x = eventFits.evntProfs.xProf.p.half_max_x;
                    half_max_x_1 = eventFits.evntProfs.xProf.p.half_max_x_1;
                    half_max_x_2 = eventFits.evntProfs.xProf.p.half_max_x_2;
                    half_max_y = eventFits.evntProfs.yProf.p.half_max_x;
                    half_max_y_1 = eventFits.evntProfs.yProf.p.half_max_x_1;
                    half_max_y_2 = eventFits.evntProfs.yProf.p.half_max_x_2;
                    FWHMx = eventFits.evntProfs.xProf.p.FWHM;
                    FWHMy = eventFits.evntProfs.yProf.p.FWHM;
            end
            % event support parameters for time profile
            bs_t = eventFits.evntProfs.tProf.p.bs;
            t0 = eventFits.evntProfs.tProf.p.t0;
            t_max = eventFits.evntProfs.tProf.p.t_max;
            v_max = eventFits.evntProfs.tProf.p.v_max;
            half_max_t = eventFits.evntProfs.tProf.p.half_max_t;
            half_max_t_1 = eventFits.evntProfs.tProf.p.half_max_t_1;
            half_max_t_2 = eventFits.evntProfs.tProf.p.half_max_t_2;
            % event parameters
            tauR = eventFits.evntProfs.tProf.fit.tauR;
            tauD = eventFits.evntProfs.tProf.fit.tauD;
            amplitude = eventFits.evntProfs.tProf.p.amplitude;
            TTP = eventFits.evntProfs.tProf.p.TTP;
            FDHM = eventFits.evntProfs.tProf.p.FDHM;
            % wave speed
            waveSpeed = eventFits.evntSP.waveSpeed_pls;

            % show profiles with fits
            % clear axes
            cla(app.UIAxes_eventTprof)
            cla(app.UIAxes_eventXYprof)
            % show t-profile with fit and params
            line(app.UIAxes_eventTprof, ...
                t, tProf, ...
                'Color', 'k', 'LineStyle', 'none', ...
                'Marker', 'o', 'Display','data')
            line(app.UIAxes_eventTprof, ...
                t_ups, tProfFit, ...
                'Color', 'r', 'LineStyle', '-', ...
                'Display','fit')
            try
            line(app.UIAxes_eventTprof, ...
                [half_max_t_1 half_max_t_2], ...
                [half_max_t half_max_t],...
                'Color','b', 'LineWidth',2,...
                'Display','FDHM')
            catch
            end
            line(app.UIAxes_eventTprof, ...
                [t_max t_max], ...
                [bs_t v_max], ...
                'Color','g', 'LineWidth',2, ...
                'Display','amplitude')
            line(app.UIAxes_eventTprof, ...
                [t0 t_max], ...
                [bs_t bs_t], ...
                'Color','m', 'LineWidth',2, ...
                'Display','TTP')
            % set up axis limits and labels
            hleg_t = legend( ...
                app.UIAxes_eventTprof,'show');
            hleg_t.Location = 'best';
            hleg_t.FontSize = 12;
            try
                yRange = abs(max(tProf(:))-min(tProf(:)));
                set(app.UIAxes_eventTprof, ...
                    'XLim', [min(t), max(t)], ...
                    'YLim', [min(tProf(:))-0.05*yRange, ...
                             max(tProf(:))+0.05*yRange]);
            catch
            end
            app.UIAxes_eventTprof.Title.String = ...
                sprintf('t-profile; %s; ID=%d', ...
                selectedEvent.eventType, ...
                selectedEvent.eventID);
            % show spatial profile
            if ~isempty(xProf)
                switch app.DropDown_spatialProfile.Value
                    case 'x-profile'
                        x_spProf = eventFits.evntProfs.xProf.fit.x;
                        spProf = eventFits.evntProfs.xProf.fit.y;
                        x_ups_spProf = eventFits.evntProfs.xProf.fit.x_ups;
                        spProfFit = eventFits.evntProfs.xProf.fit.yFit_ups;
                        hm_val = eventFits.evntProfs.xProf.p.half_max_x;
                        hm_x1 = eventFits.evntProfs.xProf.p.half_max_x_1;
                        hm_x2 = eventFits.evntProfs.xProf.p.half_max_x_2;
                    case 'y-profile'
                        x_spProf = eventFits.evntProfs.yProf.fit.x;
                        spProf = eventFits.evntProfs.yProf.fit.y;
                        x_ups_spProf = eventFits.evntProfs.yProf.fit.x_ups;
                        spProfFit = eventFits.evntProfs.yProf.fit.yFit_ups;
                        hm_val = eventFits.evntProfs.yProf.p.half_max_x;
                        hm_x1 = eventFits.evntProfs.yProf.p.half_max_x_1;
                        hm_x2 = eventFits.evntProfs.yProf.p.half_max_x_2;
                end
                % show spatial profile with fit and params
                line(app.UIAxes_eventXYprof, ...
                    x_spProf, spProf, ...
                    'Color', 'k', 'LineStyle', 'none', ...
                    'Marker', 'o', 'Display','data')
                line(app.UIAxes_eventXYprof, ...
                    x_ups_spProf, spProfFit, ...
                    'Color', 'r', 'LineStyle', '-', ...
                    'Display','fit')
                line(app.UIAxes_eventXYprof, ...
                    [hm_x1 hm_x2], ...
                    [hm_val hm_val],...
                    'Color','b', 'LineWidth',2,...
                    'Display','FWHM')
                % set up axis limits and labels
                hleg_x = legend(app.UIAxes_eventXYprof,'show');
                hleg_x.Location = 'best';
                hleg_x.FontSize = 12;
                try
                    set(app.UIAxes_eventXYprof, ...
                        'XLim', [min(x_spProf), max(x_spProf)], ...
                        'YLim', [min([spProf(:);spProfFit(:)])*0.95, ...
                        max([spProf(:);spProfFit(:)])*1.05]);
                catch
                end
                app.UIAxes_eventXYprof.Title.String = ...
                    sprintf('%s; %s; ID=%d', ...
                    app.DropDown_spatialProfile.Value, ...
                    selectedEvent.eventType, ...
                    selectedEvent.eventID);
            end
            
            % show parameters in table in gui
            app.UITable_eventParams.Data = ...
                [amplitude, ...
                TTP, FDHM, ...
                tauR, tauD, ...
                FWHMx, FWHMy, ...
                waveSpeed];          
        end
        
        
        function saveSelectedAxesToFiles(app, ...
                ~, ~, hf, h_cbx, h_dm, h_ax, dm_res, dm_contentType)
            % h_cbx = array of checkboxes
            % h_dm = array of dropdownmenus
            % h_ax = array of axes
            % dm_res = resolution of output
            % dm_contentType = content of axes

            % ask in which folder to save
            [imgPath,imgName,~] = fileparts(app.data.imgPath);
            dirPath = uigetdir(imgPath);

            % show progress
            progressBar_h = uiprogressdlg(hf, ...
                'Title', 'Please Wait',...
                'Message', 'Saving contents of axes in to files...', ...
                'Indeterminate', 'off');
            % save selected axes
            for i = 1:numel(h_ax)
                if h_cbx(i).Value
                    % update value of progress bar
                    progressBar_h.Value = i/numel(h_ax);
                    progressBar_h.Message = ...
                        sprintf('Saving contents of the axes #%d',i);

                    % export axes to file with selected format
                    fileToSave = fullfile( ...
                        dirPath, ...
                        [imgName, '_', h_ax(i).Tag, h_dm(i).Value]);
                    if exist(fileToSave, 'file')
                        delete(fileToSave)
                    end
                    % save
                    exportgraphics(h_ax(i), ...
                        fileToSave, ...
                        'Resolution',str2double(dm_res.Value), ...
                        'ContentType',dm_contentType.Value)
                end
            end
            % after saving delete figure with selection
            delete(hf)
        end
        
        
        function c = cellMaskPatchColor(app)
            % return RGB color for patch object, based on used colormap
            % find out which tab is selected and get colormap of image
            % series
            switch app.TabGroup.SelectedTab.Tag
                case 'detectionTab'
                    cmap = app.DropDown_lookuptable.Value;
                case  'analysisTab'
                    cmap = app.DropDown_lookuptable_events.Value;
            end
            
            switch cmap
                case {'jet', 'parula', 'turbo'}
                    c = [1 1 1];
                case 'hot'
                    c = [0 1 1];
                case 'gray'
                    c = [0 1 0];
            end
        end

        
        function positionAndSizeOfcellMaskROI(app, hO, event, ha)
            % ha = handle to axes with mask to update
            % get position of cursor in figure relatively to axes
            curPos = get(ha, 'CurrentPoint');
            % handle to roi
            switch hO.Tag
                case 'refitFig'
                    maskData = getappdata(hO, 'evntMaskCorr');
                otherwise
                    maskData = app.data.cellMaskCorr;
            end
            if isgraphics(maskData.maskCorrectionROI)
                maskROI = maskData.maskCorrectionROI;
            else
                return
            end
            % check if cursor is above the axes
            xCurPos = curPos(1,1);
            yCurPos = curPos(1,2);
            if (xCurPos<=ha.XLim(2)) && ...
                    (xCurPos>=ha.XLim(1)) && ...
                    (yCurPos<=ha.YLim(2)) && ...
                    (yCurPos>=ha.YLim(1))
                % update roi position or size
                switch event.EventName
                    case 'WindowMouseMotion'
                        maskROI.Position = [...
                            xCurPos-maskROI.Position(3)/2,...
                            yCurPos-maskROI.Position(4)/2,...
                            maskROI.Position(3),...
                            maskROI.Position(4)
                            ];
                    
                    case 'WindowScrollWheel'
                        szROI = maskROI.Position(3)+event.VerticalScrollCount;
                        if szROI<3
                            szROI=3;
                        elseif szROI>min(ha.YLim(2), ha.XLim(2))
                            szROI = min(ha.YLim(2), ha.XLim(2));
                        end
                        % set ROI size
                        maskROI.Position = [...
                            xCurPos-maskROI.Position(3)/2,...
                            yCurPos-maskROI.Position(4)/2,...
                            szROI,...
                            szROI
                            ];

                end
                % add pause, not to overload
                pause(0.001)
            end

        end

        
        function setMaskCorrectionMode(app, selectedItem, event, hf)
            % hf = handle to figure 
            % change color of mask corrections ROI
            % red color = remove mask
            switch hf.Tag
                case 'refitFig'
                    maskData = getappdata(hf, 'evntMaskCorr');
                otherwise
                    maskData = app.data.cellMaskCorr;
            end
            switch selectedItem.Text
                case 'add mask'
                    maskData.maskCorrectionROI.Color = ...
                        app.cellMaskPatchColor;
                    maskData.maskCorrectionROI.Tag = 'addMask';

                case 'remove mask'
                    maskData.maskCorrectionROI.Color = ...
                        [1 0 0];
                    maskData.maskCorrectionROI.Tag = 'removeMask';
            end
            % save data
            switch hf.Tag
                case 'refitFig'
                    setappdata(hf, 'evntMaskCorr', maskData);
                otherwise
                    app.data.cellMaskCorr = maskData;
            end

        end

        
        function analyseImgSeriesInBatchMode(app, hObj, event, ...
                fig_batch, tbl_imgSeries_batch)
            % get data from uitable_batch
            dataTbl = tbl_imgSeries_batch.Data;
            dataTbl(~dataTbl.selected,:) = [];
            % delete fig_batch uifigure
            delete(fig_batch)
            % set flag for batch analysis
            app.data.batchMode = true;
            % add progress bar for batch mode,
            % add some time estimation as before, with hours
            % minutes and seconds
            batchProgressBar = uiprogressdlg(app.UIFigure, ...
                'Title','Processing selected image series in batch mode.', ...
                'Message','remaining time: ...', 'Cancelable','on', ...
                'ShowPercentage','on');
            % do batch analysis
            numOfImgSeries = height(dataTbl);
            % durations of iterations
            tIter = nan(numOfImgSeries,1);
            for i=1:height(dataTbl)
                % start counting time per iteration
                tStartIter = tic;
                % Check for Cancel button press
                if batchProgressBar.CancelRequested
                    close(batchProgressBar)
                    % set flag for batch analysis
                    app.data.batchMode = false;
                    app.data = rmfield(app.data, 'imgMetaDataInBatchMode');
                    % clear app content
                    clearAppContent(app, 'all')
                    return
                end

                % load image series
                app.data.imgPath = dataTbl.("image series path"){i};
                % save metadata of image; might be changed by user, so not
                % to reload it again with app.openImg function
                t = (dataTbl.("pxSz_t (ms)")(i) : ...
                    dataTbl.("pxSz_t (ms)")(i) : ...
                    dataTbl.("# images")(i)*dataTbl.("pxSz_t (ms)")(i)) - ...
                    dataTbl.("pxSz_t (ms)")(i);
                app.data.imgMetaDataInBatchMode.t = t(:);
                app.data.imgMetaDataInBatchMode.n_ch = dataTbl.("# channels")(i);
                app.data.imgMetaDataInBatchMode.pxSzT = dataTbl.("pxSz_t (ms)")(i);
                app.data.imgMetaDataInBatchMode.pxSz = dataTbl.("pxSz (um)")(i);
                % simulate event structure after button click
                simButtonEvent = struct('Source', struct('Tag', 'menu_openImg'));
                app.openImg(simButtonEvent)

                % check if calculate cell mask and self ratio
                if app.CheckBox_findCellMaskInBatch.Value
                    % detect cell edge with checked parameters
                    app.findCellMask(simButtonEvent)
                end
                simButtonEvent = struct('Source', struct('Tag', 'detectEvents'));
                if app.CheckBox_calcSelfRatioInBatch.Value
                    app.calculate_selfRatio(simButtonEvent)
                end
                
                % detect calcium evens using selected method
                app.detectCalciumEvents(simButtonEvent)
                
                % save parameters of detected events
                app.saveAnalysis()
                
                % update progress bar
                batchProgressBar.Value = i/height(dataTbl);
                % get time per iteration, in seconds
                tIter(i) = toc(tStartIter);
                estimTimeOfFinish = mean(tIter,'omitnan')*(numOfImgSeries-i);
                % in hours
                estimTimeOfFinish_hour = [floor(estimTimeOfFinish/3600), ...
                    round(mod(estimTimeOfFinish,3600)/60)];
                % in minutes
                estimTimeOfFinish_min = [floor(estimTimeOfFinish/60), ...
                    round(mod(estimTimeOfFinish,60))];
                % in seconds
                estimTimeOfFinish_sec = round(estimTimeOfFinish);
                % update remaining time estimate
                if estimTimeOfFinish_hour(1) >= 1
                    batchProgressBar.Message = ...
                        sprintf('remaining time: %d hours and %d minutes', ...
                            estimTimeOfFinish_hour(1), ...
                            estimTimeOfFinish_hour(2));
                else
                    if estimTimeOfFinish_min(1) >= 1
                        batchProgressBar.Message = ...
                            sprintf('remaining time: %d minutes and %d seconds', ...
                            estimTimeOfFinish_min(1), ...
                            estimTimeOfFinish_min(2)); 
                    else
                        batchProgressBar.Message = ...
                            sprintf('remaining time: %d seconds', ...
                            estimTimeOfFinish_sec);
                    end
                end

            end
            % delete progress bar
            close(batchProgressBar)
            % set flag for batch analysis
            app.data.batchMode = false;
            app.data = rmfield(app.data, 'imgMetaDataInBatchMode');
            % clear app content
            clearAppContent(app, 'all')
        end
        

        function imgMetadata = getImgMetadataFromImgDescrStr(app, ...
                imgDescrStr, N_imgs)
            % try to get image series metadata
            try
                pixels_meta = regexp(imgDescrStr, ...
                    '(?<=Pixels).*?(?=</Pixels>)', 'match');
                pixels_meta = split(pixels_meta,{'<','>'});
                % remove lines without = sign
                pixels_meta = pixels_meta(~cellfun(@isempty,regexp(pixels_meta,'=')));
                % remove lines with TiffData and UUID
                pixels_meta = pixels_meta(cellfun(@isempty,regexp(pixels_meta,'TiffData|UUID')));

                % find line with PhysicalSizeX
                indx_pxSz = find(~cellfun(@isempty,regexp(pixels_meta,'PhysicalSizeX'))==1,1,'first');
                pixelSz_str = pixels_meta{indx_pxSz,1};
                % remove "
                pixelSz_str = erase(pixelSz_str,'"');
                pixelSz_str = split(pixelSz_str);
                % remove empty lines
                pixelSz_str = pixelSz_str(~cellfun(@isempty,pixelSz_str));
                % create structure
                pixelSz_struct = split(pixelSz_str, '=');
                pixelSz_struct = cell2struct(pixelSz_struct(:,2), pixelSz_struct(:,1));

                % number of channels
                imgMetadata.n_ch = str2double(pixelSz_struct.SizeC);

                % get time from images, delta T
                imgs_meta = pixels_meta(~cellfun(@isempty,regexp(pixels_meta,'Plane')));
                % remove " and /
                imgs_meta = erase(imgs_meta,'"');
                imgs_meta = erase(imgs_meta,'/');
                % split
                imgs_meta = split(imgs_meta);
                % remove empty lines
                imgs_meta = imgs_meta(all(~cellfun(@isempty,imgs_meta),2), :);
                % remove plane column
                imgs_meta(:,1) = [];

                % get time of images
                imgs_meta = split(imgs_meta, '=');
                idx_deltaT = find(strcmp(imgs_meta(1,:,1), 'DeltaT'));
                t = cellfun(@(x) str2double(x), imgs_meta(:,idx_deltaT,2));
                t = t - t(1);
                t = t(:);
                dt = mean(diff(t));
                if numel(t) ~= N_imgs
                    t = (dt:dt:N_imgs*dt) - dt;
                    t = t(:);
                end
                % in ms
                imgMetadata.t = t.*1000;
                imgMetadata.pxSzT = dt*1000;
                % dont do it in batch mode
                if ~app.data.batchMode
                    % check if dt is the same at least in range of miliseconds
                    % round on milisecond and use unique, if more than one throw
                    % a warning
                    timeSteps = unique( round(diff(t)*1000) );
                    if numel(timeSteps) > 1
                        uialert(app.UIFigure, ...
                            {'different duration of time steps (time rounded to nearest millisecond)', ...
                            sprintf( 'unique time steps: %s (ms)', ...
                            char(join( arrayfun(@(x) num2str(x), timeSteps, 'UniformOutput', 0), ', ' )))}, ...
                            'Warning', 'Icon', 'warning')
                    end
                end
                % get pixel size
                imgMetadata.pxSz = str2double(pixelSz_struct.PhysicalSizeX);
                % number of channels
                imgMetadata.n_ch = 1;
            catch
                try
                    % split image description string into lines
                    allLines = textscan(imgDescrStr,'%s','Delimiter','\n');
                    allLines = allLines{1};
                    % get xy pixel size
                    ind_imgPixelSz = find( ...
                        contains(allLines, 'Pixels', 'IgnoreCase',true), ...
                        1, "first");
                    ind_imgPhysicalSz = find( ...
                        contains(allLines, 'Microns', 'IgnoreCase',true), ...
                        1, "first");
                    if ~contains(allLines{ind_imgPhysicalSz,1}, ...
                            'not calibrated', 'IgnoreCase',true)
                        % image is calibrated, get spatial pixel size
                        imgSzPx = cell2mat( ...
                            textscan(allLines{ind_imgPixelSz,1}, '%*s %*c %d') );
                        imgSzPhys = cell2mat( ...
                            textscan(allLines{ind_imgPhysicalSz,1}, '%*s %*c %f') );
                        imgMetadata.pxSz = imgSzPhys/double(imgSzPx);
                    else
                        imgMetadata.pxSz = nan;
                    end
                    
                    % get t pixel size
                    % format data into table
                    ind = find(contains(allLines, 'frame', 'IgnoreCase',true), ...
                        1, "first");
                    descStrNums = allLines(ind+1:end,1);
                    descVarsNames = textscan(allLines{ind,1}, '%s', 'Delimiter','\t');
                    descVarsNames = descVarsNames{1};
                    descVarsNames = descVarsNames(:)';
                    descTbl = array2table(zeros(numel(descStrNums),numel(descVarsNames)), ...
                        'VariableNames',descVarsNames);
                    for l = 1:numel(descStrNums)
                        lineStr = textscan(descStrNums{l,1}, '%s', 'Delimiter','\t');
                        lineStr = lineStr{:}';
                        descTbl{l,:} = cellfun(@(x) str2double(x), lineStr);
                    end
                    imgMetadata.pxSzT = mean(diff(descTbl.("Time(ms)")))*1000; % in ms
                    t = (0:1:N_imgs-1).*imgMetadata.pxSzT;
                    imgMetadata.t = t(:);
                    % number of channels
                    imgMetadata.n_ch = 1;
                catch
                    imgMetadata.pxSz = nan;
                    imgMetadata.pxSzT = nan;
                    imgMetadata.t = nan;
                    imgMetadata.n_ch = 1;
                end
            end
            % check if any of pixel metadata are nans
            if isnan(imgMetadata.pxSz)
                imgMetadata.pxSz = app.data.pxSz;
            end
            if isnan(imgMetadata.pxSzT)
                imgMetadata.pxSzT = app.data.pxSzT;
                t = (0:1:N_imgs-1).*imgMetadata.pxSzT;
                imgMetadata.t = t(:);
            end
        end


        function changeValueOfMultipleSelectedCells(app, tbl, event)
            % change value of last selected cell to value of previous one
            % only in columns
            if size(event.Selection,1)>1 && numel(unique(event.Selection(:,2)))<2
                oldSelectionVal = tbl.Data{event.PreviousSelection(1,1), ...
                    event.PreviousSelection(1,2)};
                idxNewSelectedCell = ...
                    ~ismember(event.Selection, event.PreviousSelection, 'rows');
                tbl.Data{event.Selection(idxNewSelectedCell,1), ...
                    event.Selection(idxNewSelectedCell,2)} = oldSelectionVal;
            end
           
        end

        
        function clearAppContent(app, flag)
            % clear content in app
            if ~exist('flag', 'var')
                flag = '';
            end
            cla(app.UIAxes_img_events)
            cla(app.UIAxes_eventTprof)
            cla(app.UIAxes_eventXYprof)
            cla(app.UIAxes_avrgCellSignal)

            % remove previously highlighted row
            removeStyle(app.UITable_events)
            % set checkbox to show all detected events
            app.CheckBox_showSelectedEvent.Value = false;
            app.UITable_eventParams.Data = {};
            app.UITable_events.Data = {};

            if strcmp(flag, 'all')
                cla(app.UIAxes_img)
                app.UITable_imgMeta.Data = {};
            end
            % show state
            app.stateLampLabel.Text = 'ready';
            app.Lamp_state.Color = [0 1 0];
            drawnow
        end


        function [mask_sparks, mask_puffs, mask_waves] = ...
                detectEvents_threshold(app)
            % Miguel's approach
            % get data
            imgData = double(app.data.imgData);
            cellMask = app.data.cellMask;
            n_imgs = size(imgData,3);
            x = (1:n_imgs);
            x = x(:);
            % filter data
            H = fspecial('average',[3 3]);
            imgDataFilt = imgData;
            for i=1:size(imgData,3)
                imgDataFilt(:,:,i) = ...
                    imfilter(imgDataFilt(:,:,i), H, 'symmetric');
                imgDataFilt(:,:,i) = ...
                    imfilter(imgDataFilt(:,:,i), H, 'symmetric');
                imgDataFilt(:,:,i) = ...
                    imfilter(imgDataFilt(:,:,i), H, 'symmetric');
                imgDataFilt(:,:,i) = ...
                    imfilter(imgDataFilt(:,:,i), H, 'symmetric');
                imgDataFilt(:,:,i) = ...
                    imfilter(imgDataFilt(:,:,i), H, 'symmetric');
            end

            thr_1 = zeros(n_imgs,1);
            for i=1:n_imgs
                img = imgDataFilt(:,:,i);
                % first threshold for individual frames
                thr_1(i) = median(img(cellMask(:,:,i))) + ...
                    2.5*mad(img(cellMask(:,:,i)), 1);
            end

            % fit with polynomial model to avoid outliers
            thr_1_fit = feval( fit(x, thr_1, 'poly5', ...
                'Normalize','on', 'Robust','Bisquare'), (1:n_imgs));

            %                     figure
            %                     plot(thr_1, 'ok')
            %                     hold on
            %                     plot(thr_1_fit,'r')

            % exclude areas in cell higher than threshold, update
            % mask of cell
            cellMask_thr_1 = false(size(cellMask));
            for i=1:n_imgs
                cellMask_thr_1(:,:,i) = cellMask(:,:,i) & ...
                    (imgDataFilt(:,:,i)<thr_1_fit(i));
            end

            % repeat calculation of threshold from cell without
            % areas higher than threshold 1
            thr_2 = zeros(n_imgs,1);
            for i=1:n_imgs
                img = imgDataFilt(:,:,i);
                % first threshold for individual frames
                thr_2(i) = mean(img(cellMask_thr_1(:,:,i))) + ...
                    2.5*std(img(cellMask_thr_1(:,:,i)));
                if isnan(thr_2(i))
                    % take previous if possible
                    try
                        thr_2(i) = thr_2(i-1);
                        thr_2(i) = max(max(imgDataFilt(:,:,i)));
                    catch
                        % take maximum of image, should not detect
                        % anything
                        thr_2(i) = max(max(imgDataFilt(:,:,i)));
                    end

                end

            end

            % fit again with polynomial model to avoid outliers
            thr_2_fit = feval( fit(x, thr_2, 'poly5', ...
                'Normalize','on', 'Robust','Bisquare'), (1:n_imgs));

            %                     figure
            %                     plot(thr_2, 'ok')
            %                     hold on
            %                     plot(thr_2_fit,'r')

            % find masks of events, areas with signal higher than thr_2_fit
            % save mask as only mask sparks, this method does not
            % provide type of event
            mask_sparks = false(size(cellMask));
            for i=1:n_imgs
                mask_sparks(:,:,i) = cellMask(:,:,i) & ...
                    (imgDataFilt(:,:,i)>=thr_2_fit(i));
            end
            mask_puffs = false(size(imgDataFilt));
            mask_waves = false(size(imgDataFilt));
            %                     figure
            %                     subplot(3,1,1)
            %                     imagesc(imgData(:,:,30))
            %                     subplot(3,1,2)
            %                     imagesc(cellMask_thr_1(:,:,30))
            %                     subplot(3,1,3)
            %                     imagesc(mask_sparks(:,:,30))
        end


        function [mask_sparks, mask_puffs, mask_waves] = ...
                detectEvents_unet(app)
            % get data
            imgData = app.data.imgData;
            cellMask = app.data.cellMask;
            n_imgs = size(imgData,3);
            try
                % check system
                if ismac
                    % Code to run on Mac platform
                    % path to conda environment
                    pythonPath = fullfile(...
                        app.EditField_pathToConda.Value, ...
                        'bin','python');
                    % adding the folder with libraries to PATH
                    folder_with_lib = fullfile(...
                        app.EditField_pathToConda.Value, ...
                        'lib');
                elseif isunix
                    % Code to run on Linux platform
                    
                elseif ispc
                    % Code to run on Windows platform
                    % path to conda environment
                    pythonPath = fullfile(...
                        app.EditField_pathToConda.Value, ...
                        'pythonw.exe');
                    % adding the folder with the DLLs to PATH
                    folder_with_lib = fullfile(...
                        app.EditField_pathToConda.Value, ...
                        'Library', 'bin');
                else
                    uialert(app.UIFigure, ...
                        'Platform not supported!', ...
                        'error')
                    return
                end

                % username = getenv('username');
                setenv('PYTHONUNBUFFERED','1'); % doesn't change much, but just-in-case
                % Setting PYTHONUNBUFFERED to a non empty value ensures
                % that the python output is sent straight to terminal (e.g. your container log)
                % without being first buffered and that you can see the output of your application (e.g. django logs) in real time.
                % This also ensures that no partial output is held in a buffer somewhere and never written in case the python application crashes.
                setenv('path',[folder_with_lib, pathsep, getenv('path')]);
                % set python environment
                % check if selected conda environment is xytCaSignals
                [~, env_n, ~] = fileparts(app.EditField_pathToConda.Value);
                if ~strcmp(env_n,'xytCaSignals')
                    uialert(app.UIFigure, ...
                        'Wrong conda environmen!', ...
                        'Wrong Path')
                else
                    % might be due to difference between PC and mac
                    try
                        pe = pyenv('Version',pythonPath);
                    catch
                        uialert(app.UIFigure, ...
                            'Wrong conda environmen!', ...
                            'Wrong Path')
                    end
                end

                % get path to base folder with all files and folders
                appFile = mfilename('fullpath');
                [baseDir,~,~] = fileparts(appFile);

                % check in which mode app is running
                if isdeployed
                    % stand alone mode
                    % [d, result] = system('set PATH');
                    % currentDir = char(regexpi(result, 'Path=(.*?);', 'tokens', 'once'));
                    % look at it just in case there are some
                    % problems with paths to files
                    %                             uialert(app.UIFigure, ...
                    %                                 {ctfroot,getenv('path')}, ...
                    %                                 'path')
                else
                    % running in Matlab
                    % add path to folder with ML model
                    % does not work in standalone mode
                    addpath(genpath([baseDir, filesep, 'ML_model']))
                end

                % path to predict.py, python file which will detect
                % calcium events
                filePath = fullfile( ...
                    baseDir, 'ML_model', 'sparks', 'predict.py');

            catch ME
                uialert(app.UIFigure, ...
                    ME.message, ...
                    'error')
                return
            end

            % maybe save cropped file (add it to cropp function) and load that for AI
            % detection, discuss with Prisca & Miguel
            [status, output] = system( ...
                sprintf('%s %s "%s"', ...  % quotation in case there are some spaces in name or path
                pythonPath, filePath, app.data.imgPath), ...
                '-echo');
            % check status
            if status~=0
                uialert(app.UIFigure, ...
                    output, ...
                    'error')
                return
            end
  
            % load predicted masks of events
            [~,img_fn,~] = fileparts(app.data.imgPath);
            % paths to output files
            p_mask_sparks = fullfile(baseDir, 'ML_model', 'sparks', 'output', sprintf('%s_sparks.tif',img_fn));
            p_mask_puffs = fullfile(baseDir, 'ML_model', 'sparks', 'output', sprintf('%s_puffs.tif',img_fn));
            p_mask_waves = fullfile(baseDir, 'ML_model', 'sparks', 'output', sprintf('%s_waves.tif',img_fn));

            % load masks / read output imgs
            mask_sparks = false(size(imgData));
            mask_puffs = false(size(imgData));
            mask_waves = false(size(imgData));
            %mask_events_types = zeros(size(imgData), 'uint8');
            % make sure is inside cell
            for i=1:n_imgs
                mask_sparks(:,:,i) = ...
                    (imread(p_mask_sparks, i) > app.EditField_spark_thr.Value) & cellMask(:,:,i);
                mask_puffs(:,:,i) = ...
                    (imread(p_mask_puffs, i) > app.EditField_puff_thr.Value) & cellMask(:,:,i);
                mask_waves(:,:,i) = ...
                    (imread(p_mask_waves, i) > app.EditField_wave_thr.Value) & cellMask(:,:,i);

                % mask of all events together, also different types
                % BG = 0; spark = 1; puff = 2; wave = 3
                % mask_events_types(:,:,i) = mask_events_types(:,:,i) + uint8(mask_sparks(:,:,i));
                % mask_events_types(:,:,i) = mask_events_types(:,:,i) + uint8(mask_puffs(:,:,i)).*2;
                % mask_events_types(:,:,i) = mask_events_types(:,:,i) + uint8(mask_waves(:,:,i)).*3;
            end

            % delete image series created by U-Net
            allOutputFiles = dir(fileparts(p_mask_sparks));
            for i=1:numel(allOutputFiles)
                if contains(allOutputFiles(i).name, img_fn)
                    delete(fullfile( ...
                        allOutputFiles(i).folder, ...
                        allOutputFiles(i).name ))
                end
            end

        end


        function saveDataToTIFFfile(app, data, dataLabels, filePath)
            % check extension
            [path,fn,ext] = fileparts(filePath);
            % check in which format to save masks of events
            if ~contains(ext, 'tif')
                ext = '.tif';
            end
            % check size of data to save
            s = whos('data');
            if s.bytes > 2^32-1
                access_mode = 'w8';
            else
                access_mode = 'w';
            end
            % check if to save also some labels
            if isempty(dataLabels)
                samplesPerPixel = 1;
            else
                samplesPerPixel = 2;
            end
            % data dimensions
            [H, W, D] = size(data,(1:3));
            % create tiff object to access tiff file
            tif_obj = Tiff( ...
                fullfile(path, [fn, ext]), ...
                access_mode);
            % set tags values
            switch s.class
                case 'logical'
                    bitsPerSample = 1;
                    sampleFormat = Tiff.SampleFormat.UInt;
                    photometric = Tiff.Photometric.Mask;
                case 'int8'
                bitsPerSample = 8;
                    sampleFormat = Tiff.SampleFormat.Int;
                    photometric = Tiff.Photometric.Mask;
                case 'uint8'
                    bitsPerSample = 8;
                    sampleFormat = Tiff.SampleFormat.UInt;
                    photometric = Tiff.Photometric.Mask;
                case 'int16'
                    bitsPerSample = 16;
                    sampleFormat = Tiff.SampleFormat.Int;
                    photometric = Tiff.Photometric.Mask;
                case 'uint16'
                    bitsPerSample = 16;
                    sampleFormat = Tiff.SampleFormat.UInt;
                    photometric = Tiff.Photometric.MinIsBlack;
                case 'int32'
                    bitsPerSample = 32;
                    sampleFormat = Tiff.SampleFormat.Int;
                    photometric = Tiff.Photometric.Mask;
                case 'uint32'
                    bitsPerSample = 32;
                    sampleFormat = Tiff.SampleFormat.UInt;
                    photometric = Tiff.Photometric.Mask;
                case 'single'
                    bitsPerSample = 32;
                    sampleFormat = Tiff.SampleFormat.IEEEFP;
                    photometric = Tiff.Photometric.Mask;
                case 'double'
                    bitsPerSample = 64;
                    sampleFormat = Tiff.SampleFormat.IEEEFP;
                    photometric = Tiff.Photometric.Mask;
            end
            tagstruct.ImageLength = H;
            tagstruct.ImageWidth = W;
            tagstruct.Photometric = photometric;
            tagstruct.SampleFormat = sampleFormat;
            tagstruct.BitsPerSample = bitsPerSample;
            tagstruct.SamplesPerPixel = samplesPerPixel;
            tagstruct.RowsPerStrip = H;
            tagstruct.PlanarConfiguration = Tiff.PlanarConfiguration.Chunky;
            tagstruct.Software = 'MATLAB';
            % write data to tif file
            % imwrite(im2,fullfile(img_path, sprintf('%s_sparks.tif',img_fn)),'WriteMode','append')
            for f=1:D
                % write sparks mask
                setTag(tif_obj,tagstruct)
                write(tif_obj, data(:,:,f));
                % creates a new image file directory (IFD)
                % and makes it the current IFD
                if f ~= D
                    writeDirectory(tif_obj);
                end
            end
            % close tiff object
            close(tif_obj);
        end


        function filterDataWithButterworthFilter(app, hO, E,...
            uiFig, ax_prof, ax_spectrum)
                % clean axes 
            cla(ax_prof)
            cla(ax_spectrum)
            % get data
            butterOrder = ...
                findobj(uiFig,'Type','uinumericeditfield','Tag','butterOrder').Value;
            widthOfBandStop = ...
                findobj(uiFig,'Type','uinumericeditfield','Tag','widthOfBandStop').Value;
            RMS_multipl = ...
                findobj(uiFig,'Type','uinumericeditfield','Tag','peakPromin').Value;
            minFreq = ...
                findobj(uiFig,'Type','uinumericeditfield','Tag','minFreq').Value;
            % sampling period in s
            T = app.data.pxSzT/1000;
            % sampling frequency (Hz)
            Fs = 1/T;
            % length of signal
            L = size(app.data.imgData,3);
            % time vector
            t = (0:L-1)*T;
            % get average time profile of image series
            avrg_prof = zeros(1,size(app.data.imgData,3));
            for i=1:size(app.data.imgData,3)
                avrg_prof(i) = mean(app.data.imgData(:,:,i),'all');
            end
            % get frequencies of noise
            Y = fft(avrg_prof);
            P2 = abs(Y/L);
            P1 = P2(1:floor(L/2)+1);
            P1(2:end-1) = 2*P1(2:end-1);
            f = Fs*(0:(L/2))/L;
            
            % show average signal
            line(ax_prof, t, avrg_prof, 'Color','b', 'LineWidth',1, ...
                'DisplayName','profile')
            set(ax_prof,'FontSize',14, 'XLim',[min(t) max(t)])
            xlabel(ax_prof, 'time (ms)', 'FontSize',16, 'FontWeight','bold')
            ylabel(ax_prof, 'average signal', 'FontSize',16, 'FontWeight','bold')
            title(ax_prof, 'Average Time Profile of XYT Image Series', ...
                'FontSize',18, 'FontWeight','bold')
            % show single sided amplitude spectrum of signal
            hl_spctr = line(ax_spectrum, f, P1, 'Color','k', 'LineWidth',1, ...
                'DisplayName','amplitude spectrum of data');
            set(ax_spectrum,'FontSize',14, 'XLim',[min(f) max(f)], ...
                'YLim',[min(P1) max(P1(f>=minFreq))])
            xlabel(ax_spectrum, 'f (Hz)', 'FontSize',16, 'FontWeight','bold')
            ylabel(ax_spectrum, '|P1(f)|', 'FontSize',16, 'FontWeight','bold')
            title(ax_spectrum, ...
                'Single-Sided Amplitude Spectrum of Average Profile from Unfiltered Data(t)', ...
                'FontSize',18, 'FontWeight','bold')         
            % remove trends in signal
            [LT,ST,R] = trenddecomp(P1(:));
            % calculate rms of signal to estimate noise in signal
            peakPromin = RMS_multipl*rms(R(f>=minFreq)); % maybe check SD
            
            % find frequencies which will be filtered
            [pks,locs] = findpeaks(P1(f>=minFreq), f(f>=minFreq), ...
                'MinPeakProminence', peakPromin);
            % merge overlaping regions
            bands = [locs-widthOfBandStop/2 ;locs+widthOfBandStop/2]';
            try
                merged_bands = bands;
                % check which regions overlap
                m_overlaps = merged_bands(:,1)<circshift(merged_bands(:,2),1);
                m_overlaps(1) = false;
                intersectingRegions = any(m_overlaps);
                while intersectingRegions
                    idx_start = find(m_overlaps,1,'first')-1;
                    idx_end = find(m_overlaps(idx_start+1:end)==0,1,'first')+idx_start-1;
                    if isempty(idx_end), idx_end = numel(m_overlaps); end
                    % merge overlaping bands
                    merged_bands = [
                        merged_bands(1:idx_start-1,:); ...
                        [merged_bands(idx_start,1),merged_bands(idx_end,2)]; ...
                        merged_bands(idx_end+1:end,:)
                        ];
                    % check if there is still something overlapping
                    m_overlaps = merged_bands(:,1)<circshift(merged_bands(:,2),1);
                    m_overlaps(1) = false;
                    intersectingRegions = any(m_overlaps);
                end
                bands = merged_bands;
            catch
            end
            if ~isempty(bands)
                % make sure that nothing is ouiside of interval (0,max(f))
                bands(bands(:,1)<0,1) = 0;
                bands(bands(:,2)>max(f),2) = max(f)-mean(diff(f))/1000;
                % show found peaks, filtered bands and mergerd filtered bands
                lp = line(ax_spectrum, locs, pks, 'LineStyle','none', ...
                    'Marker','.', 'MarkerSize',25, 'Color','r',...
                    'DisplayName','cutoff frequencies');
                lbands = line(ax_spectrum, ...
                    [[locs-widthOfBandStop/2;locs-widthOfBandStop/2], ...
                    [locs+widthOfBandStop/2;locs+widthOfBandStop/2]], ...
                    [min(P1);max(P1)], 'LineStyle',':', ...
                    'LineWidth',1, 'Color','r',...
                    'DisplayName','filtered bands borders');
                for i=1:size(bands,1)
                    % rectangles
                    vert = [
                        bands(i,1) min(P1); ...
                        bands(i,2) min(P1); ...
                        bands(i,2) max(P1); ...
                        bands(i,1) max(P1)
                        ];
                    fcs = [1 2 3 4];
                    hp=patch(ax_spectrum, 'Faces',fcs, 'Vertices',vert, ...
                        'FaceColor','red', 'FaceAlpha',0.2, ...
                        'EdgeColor', 'none', 'DisplayName','merged filtered bands');
                end
                % show legend
                legend(ax_prof,'Location','best')
                try
                    legend(ax_spectrum,[hl_spctr,lp,lbands(1),hp],'Location','best')
                catch
                    legend(ax_spectrum,[hl_spctr,lp],'Location','best')
                end
            end
            % check if filter data or accept already filtered data
            if ~isempty(hO) && strcmp(hO.Type,'uibutton')
                switch hO.Text
                    case 'filter data'
                        % filter data with Butterworth filter
                        % filter type
                        ftype = 'stop';
                        % add progressbar
                        h_dlg = uiprogressdlg(uiFig, ...
                            'Indeterminate','on', ...
                            'Message', 'Filtering image series with Butterworth filter.', ...
                            'Title', 'Filtering data ...');
                        % filter data
                        imgDataFilt = shiftdim(double(app.data.imgData),2);
                        for i=1:size(bands,1)
                            Wn = bands(i,:) ./ max(f);
                            % Transfer Function design
                            % [b,a] = butter(butterOrder, Wn, ftype);
                            % Zero-Pole-Gain design, numericaly more stable
                            [z,p,k] = butter(butterOrder, Wn, ftype);
                            [sos,g] = zp2sos(z,p,k);
                            % Display and compare results
                            % hfvt = fvtool(b,a,sos,'FrequencyScale','log');
                            % legend(hfvt,'TF Design','ZPK Design')
                            % imgDataFilt = filtfilt(b,a,imgDataFilt);
                            imgDataFilt = filtfilt(sos,g,imgDataFilt);
                        end
                        imgDataFilt = shiftdim(imgDataFilt,4);
                        % calculate average from filtered data
                        avrg_prof_filt = zeros(size(avrg_prof));
                        for i=1:size(app.data.imgData,3)
                            avrg_prof_filt(i) = mean(imgDataFilt(:,:,i),"all");
                        end
                        % show filtered average profile in axes
                        line(ax_prof, t, avrg_prof_filt, 'Color','r', 'LineWidth',1, ...
                            'DisplayName','filtered profile')
                        % save filtered data
                        filteredData = struct('filterName','Butterworth', ...
                            'ftype', ftype, 'order', butterOrder, ...
                            'widthOfBandStop', widthOfBandStop, ...
                            'filteredFreq', locs, ...
                            'imgDataFilt', imgDataFilt);
                        setappdata(uiFig,'filteredData',filteredData)
                        % close progressbar
                        close(h_dlg)

                    case 'accept'
                        % check if there are data to save
                        if isfield(getappdata(uiFig),'filteredData')
                            filteredData = getappdata(uiFig,'filteredData');
                            % save parameters of butterworth filter to
                            % filters table
                            % check if there is a butterworth filter in
                            % table
                            if any(app.UITable_filters.Data.filter == ...
                                    "Butterworth filter")
                                % modify parameters of already added
                                % butterworth filter
                                ind_f = find(app.UITable_filters.Data.filter == ...
                                    "Butterworth filter");
                                app.UITable_filters.Data{ind_f,"p#1 value"} = ... 
                                    butterOrder;
                                app.UITable_filters.Data{ind_f,"p#2 value"} = ...
                                    widthOfBandStop;
                                app.UITable_filters.Data{ind_f,"p#3 value"} = ...
                                    RMS_multipl;
                                app.UITable_filters.Data{ind_f,"p#4 value"} = ...
                                    minFreq;

                            else
                                % add new filter
                                % remove all none rows
                                app.UITable_filters.Data( ...
                                    app.UITable_filters.Data.filter == "none", :) = [];
                                % add new butterworth filter and one empty row at the end
                                app.UITable_filters.Data = [ 
                                    app.UITable_filters.Data; ...
                                    table(categorical({'Butterworth filter';'none'}, app.filterNames), ...
                                        {'order of filter';''},[butterOrder;0], ...
                                        {'width of bandstop (Hz)';''},[widthOfBandStop;0], ...
                                        {'RMS noise multiplier';''},[RMS_multipl;0], ...
                                        {'freq filtered (Hz) >=';''},[minFreq;0], ...   
                                        'VariableNames',{'filter', ...
                                             'parameter #1','p#1 value', ...
                                             'parameter #2','p#2 value', ...
                                             'parameter #3','p#3 value', ...
                                             'parameter #4','p#4 value'}) 
                                             ];
                            end
                            % close Butterworth filter figure
                            close(uiFig)
                        else
                            uialert(uiFig,'First filter data!')
                            return
                        end
                end
            end

        end


        function setFilterAndDlmSettings(app, hO, E, uifig)
            % handle of filter table
            filters_uitbl = findobj(uifig, 'Type','uitable', ...
                'Tag','filtersSelectionTbl');
            % check inputs
            switch hO.Type
                case 'uitable'
                    switch hO.Tag
                        case 'filtersSelectionTbl'
                            % if filter is change, change parameters names and vals
                            if E.Indices(2)==1
                                switch E.NewData
                                    case 'none'
                                        hO.Data(E.Indices(1),"parameter #1") = {''};
                                        hO.Data{E.Indices(1),"p#1 value"} = 0;
                                        hO.Data(E.Indices(1),"parameter #2") = {''};
                                        hO.Data{E.Indices(1),"p#2 value"} = 0;
                                        hO.Data(E.Indices(1),"parameter #3") = {''};
                                        hO.Data{E.Indices(1),"p#3 value"} = 0;
                                        hO.Data(E.Indices(1),"parameter #4") = {''};
                                        hO.Data{E.Indices(1),"p#4 value"} = 0;
                                        hO.ColumnEditable = logical([1 0 1 0 1 0 0 0 0]);
                                    case 'gaussian'
                                        hO.Data(E.Indices(1),"parameter #1") = {'sigma xy'};
                                        hO.Data{E.Indices(1),"p#1 value"} = 1;
                                        hO.Data(E.Indices(1),"parameter #2") = {'sigma t'};
                                        hO.Data{E.Indices(1),"p#2 value"} = 0.5;
                                        hO.Data(E.Indices(1),"parameter #3") = {''};
                                        hO.Data{E.Indices(1),"p#3 value"} = 0;
                                        hO.Data(E.Indices(1),"parameter #4") = {''};
                                        hO.Data{E.Indices(1),"p#4 value"} = 0;
                                        hO.ColumnEditable = logical([1 0 1 0 1 0 0 0 0]);
                                    case 'anisotropic diffusion'
                                        hO.Data(E.Indices(1),"parameter #1") = {'# of iter'};
                                        hO.Data{E.Indices(1),"p#1 value"} = 5;
                                        hO.Data(E.Indices(1),"parameter #2") = {''};
                                        hO.Data{E.Indices(1),"p#2 value"} = 0;
                                        hO.Data(E.Indices(1),"parameter #3") = {''};
                                        hO.Data{E.Indices(1),"p#3 value"} = 0;
                                        hO.Data(E.Indices(1),"parameter #4") = {''};
                                        hO.Data{E.Indices(1),"p#4 value"} = 0;
                                        hO.ColumnEditable = logical([1 0 1 0 1 0 0 0 0]);
                                    case 'PURE-let'
                                        hO.Data(E.Indices(1),"parameter #1") = {'# of cycles'};
                                        hO.Data{E.Indices(1),"p#1 value"} = 5;
                                        hO.Data(E.Indices(1),"parameter #2") = {'# of frames'};
                                        hO.Data{E.Indices(1),"p#2 value"} = 5;
                                        hO.Data(E.Indices(1),"parameter #3") = {''};
                                        hO.Data{E.Indices(1),"p#3 value"} = 0;
                                        hO.Data(E.Indices(1),"parameter #4") = {''};
                                        hO.Data{E.Indices(1),"p#4 value"} = 0;
                                        hO.ColumnEditable = logical([1 0 1 0 1 0 0 0 0]);
                                    case 'Butterworth filter'
                                        hO.Data(E.Indices(1),"parameter #1") = {'order of filter'};
                                        hO.Data{E.Indices(1),"p#1 value"} = 4;
                                        hO.Data(E.Indices(1),"parameter #2") = {'width of bandstop (Hz)'};
                                        hO.Data{E.Indices(1),"p#2 value"} = 1;
                                        hO.Data(E.Indices(1),"parameter #3") = {'RMS noise multiplier'};
                                        hO.Data{E.Indices(1),"p#3 value"} = 3;
                                        hO.Data(E.Indices(1),"parameter #4") = {'freq filtered (Hz) >='};
                                        hO.Data{E.Indices(1),"p#4 value"} = 7;
                                        hO.ColumnEditable = logical([1 0 1 0 1 0 1 0 1]);
                                    otherwise
                                        hO.Data(E.Indices(1),"parameter #1") = {'kernel_sz xy'};
                                        hO.Data{E.Indices(1),"p#1 value"} = 3;
                                        hO.Data(E.Indices(1),"parameter #2") = {'kernel_sz t'};
                                        hO.Data{E.Indices(1),"p#2 value"} = 3;
                                        hO.Data(E.Indices(1),"parameter #3") = {''};
                                        hO.Data{E.Indices(1),"p#3 value"} = 0;
                                        hO.Data(E.Indices(1),"parameter #4") = {''};
                                        hO.Data{E.Indices(1),"p#4 value"} = 0;
                                        hO.ColumnEditable = logical([1 0 1 0 1 0 0 0 0]);
                                end
                                % remove all none rows
                                hO.Data(hO.Data.filter == "none", :) = [];
                                % add one empty row at the end
                                hO.Data = [ hO.Data; ...
                                    table(categorical({'none'}, app.filterNames), ...
                                    {''},0, {''},0, {''},0, {''},0, ...
                                    'VariableNames',{'filter', ...
                                    'parameter #1','p#1 value', ...
                                    'parameter #2','p#2 value', ...
                                    'parameter #3','p#3 value', ...
                                    'parameter #4','p#4 value'}) ];

                            else
                                % if parametres of filter are changed
                                % validate inputs
                                try
                                    % filters selecion table
                                    switch hO.Data{E.Indices(1),1}
                                        case 'anisotropic diffusion'
                                            % check kernel size, has to positive integer
                                            if E.Indices(2)==3
                                                % param 1
                                                validateattributes(...
                                                    hO.Data{E.Indices(1),E.Indices(2)}, ...
                                                    'numeric', {'integer','>',0})
                                            end
                                        case 'gaussian'
                                            validateattributes(...
                                                hO.Data{E.Indices(1),E.Indices(2)}, ...
                                                'numeric', {'>=',0})
                                        case 'PURE-let'
                                            if E.Indices(2)==3
                                                validateattributes(...
                                                    hO.Data{E.Indices(1),E.Indices(2)}, ...
                                                    'numeric', {'integer','>',0})
                                            else
                                                validateattributes(...
                                                    hO.Data{E.Indices(1),E.Indices(2)}, ...
                                                    'numeric', {'integer','odd','>',0})
                                            end
                                        case 'Butterworth filter'
                                            if E.Indices(2)==3
                                                validateattributes(...
                                                    hO.Data{E.Indices(1),E.Indices(2)}, ...
                                                    'numeric', {'integer','>',0})
                                            else
                                                validateattributes(...
                                                    hO.Data{E.Indices(1),E.Indices(2)}, ...
                                                    'numeric', {'>',0})
                                            end
                                        otherwise
                                            % check input, has to be odd and min 3
                                            validateattributes(...
                                                hO.Data{E.Indices(1),E.Indices(2)}, ...
                                                'numeric', {'integer','odd','>=',1})
                                    end
                                catch ME
                                    % set back to previous correct values
                                    hO.Data{E.Indices(1),E.Indices(2)} = E.PreviousData;
                                    errordlg(ME.message)
                                    return
                                end
                            end

                        case 'DLMclassesSelectionTbl'
                            if E.Indices(2)==1
                                % remove all none rows
                                hO.Data(hO.Data.("class name") == "none", :) = [];
                                % add one empty row at the end
                                hO.Data = [ hO.Data; ...
                                    table(categorical({'none'},app.classsesInDLM), nan, ...
                                    'VariableNames', {'class name', 'integer value'})
                                    ];
                            else
                                % if value of class is changed
                                % validate inputs
                                try
                                    % check input, has to be positive
                                    % integer, including 0
                                    validateattributes(...
                                        hO.Data{E.Indices(1),E.Indices(2)}, ...
                                        'numeric', {'integer','>=',0})
                                catch ME
                                    % set back to previous correct values
                                    hO.Data{E.Indices(1),E.Indices(2)} = E.PreviousData;
                                    errordlg(ME.message)
                                    return
                                end
                            end
                    end

                case 'uibutton'
                    % save state of setting
                    hObjs_settings = getappdata(uifig, 'hObjs');
                    % filters used during image series loading
                    app.data.defaultFiltersUsed = filters_uitbl.Data;
                    % copy filters to uitable in detection tab of app
                    % figure and call function to filter data if there are
                    % any image data
                    if ~isempty(app.data.imgData)
                        app.data.filtersUsed = filters_uitbl.Data;
                        % copy filters to uitable in detection tab of app
                        app.UITable_filters.Data = filters_uitbl.Data;
                        % call function to filter data
                        event.Source.Type = 'uibutton';
                        event.Source.Tag = 'filter_img';
                        app.setAndApplyFilters(event)
                    end
                    % also save path to conda environment
                    app.data.condaEnvPath = ...
                        hObjs_settings.uiEditCondaPath.Value;
                    % save DLM classes
                    app.data.DLMclasses = hObjs_settings.uiTblClasses.Data;
                    % save pixel size
                    app.data.pxSz = hObjs_settings.uiEditPxSz.Value;
                    app.data.pxSzT = hObjs_settings.uiEditPxSzT.Value;
                    % close settings window
                    close(uifig)
            end

        end


        function imgDataFilt = filterImgSeries(app, ...
                imgData, filterData, h_progressdlg)
            % filtered data
            imgDataFilt = zeros(size(imgData));
            filterName = filterData.filter;
            % apply filter
            switch filterName
                case 'none'
                    imgDataFilt = imgData;

                case 'anisotropic diffusion'
                    if ~isempty(h_progressdlg)
                        h_progressdlg.Message = ...
                            'Filtering image series using anisotropic diffusion.';
                    end
                    imgDataFilt = imdiffusefilt(imgData, ...
                        'NumberOfIterations', filterData.("p#1 value"));

                case 'gaussian'
                    if ~isempty(h_progressdlg)
                        h_progressdlg.Message = ...
                            'Filtering image series using gaussian filter.';
                    end
                    drawnow
                    % {'filter size is 2*ceil(2*sigma)+1, square', ...
                    %             'if sigma is 0 no filtering'};     
                    % first filter each frame in spatial dimension
                    if filterData.("p#1 value")>0
                        imgDataFilt = imgaussfilt(imgData, ...
                            filterData.("p#1 value"), ...
                            'Padding','symmetric');
                    else
                        imgDataFilt = imgData;
                    end
                    % then filter each pixel profile in time dimension
                    if filterData.("p#2 value")>0
                        imgDataFilt = imgaussfilt3(imgDataFilt, ...
                            filterData.("p#1 value"), ...
                            'FilterSize',[1, 1, 2*ceil(2*filterData.("p#1 value"))+1], ...
                            'Padding','symmetric');
                    end

                case 'PURE-let'
                    % http://bigwww.epfl.ch/algorithms/denoise/
                    % parameters of denoising
                    % * Constructor of the class Denoising.
                    % * @param input         input data to be denoised (3D ImageWare object)
                    % * @param Alpha         double array containing the estimated detector gain
                    % *                      for each frame/slice.
                    % * @param Delta         double array containing the estimated detector of-
                    % *                      fset for each frame/slice.
                    % * @param Sigma         double array containing the estimated AWGN standard
                    % *                      deviation for each frame/slice.
                    % * @param FRAMEWISE     true->Framewise noise parameters estimation
                    % *                      false->Global noise parameters estimation.
                    % * @param CYCLESPIN     number of cycle-spins (CS>0). A high value of CS
                    % *                      yields a high-quality denoising result, but the
                    % *                      computation time linearly increases with CS.
                    % * @param MULTIFRAME    number of adjacent frames/slices to be considered
                    % *                      for multi-frame/slices denoising. MF>0 must be odd.
                    % 
                    h_progressdlg.Message = ...
                        {'Filtering image series using PureDenoise.', ...
                        '(denoising of multidimensional images corrupted by mixed Poisson-Gaussian noise)', ...
                        'This might take several minutes.'}; 
                    % set up path to java packages
                    appFile = mfilename('fullpath');
                    [baseDir,~,~] = fileparts(appFile);
                    % check in which mode app is running
                    if isdeployed
                        % stand alone mode
                        % [d, result] = system('set PATH');
                        % currentDir = char(regexpi(result, 'Path=(.*?);', 'tokens', 'once'));
                        % look at it just in case there are some
                        % problems with paths to files
%                     uialert(app.UIFigure, ...
%                         {ctfroot,getenv('path')}, ...
%                         'path','Icon','warning')
%                         javaaddpath(fullfile(baseDir,'pure_let/PureDenoise_.jar'))
%                         javaaddpath(fullfile(baseDir,'pure_let/ij.jar'))
                    else
                        % running in Matlab
                        % add path to folder with ML model
                        % does not work in standalone mode
                        % add java packages to the path
                        javaaddpath(fullfile(baseDir,'pure_let/PureDenoise_.jar'))
                        javaaddpath(fullfile(baseDir,'pure_let/ij.jar'))
                    end
                
                    % create java image object
                    original_img = imageware.Builder.create(imgData);
                    % dimensions of image series
                    nx = original_img.getSizeX();
                    ny = original_img.getSizeY();
                    nz = original_img.getSizeZ();
                    % minimum size of image
                    Nmin = 16;
                    Ext = [0,0];
                    if nx<=Nmin || ny<=Nmin
                        errordlg('Minimum image height and width is 17!') 
                        return
                    end
                    nxe = ceil(nx / Nmin) * Nmin;
                    nye = ceil(ny / Nmin) * Nmin;
                    if (nxe ~= nx || nye ~= ny)
                        original_img = ...
                            denoise.Operations.symextend2D(original_img, nxe, nye, Ext);
                        Ext = [(nxe-nx)/2,(nye-ny)/2];
                    else
                        Ext = [0,0];
                    end
                    % init params
                    AlphaHat = zeros(nz,1);
                    DeltaHat = zeros(nz,1);
                    SigmaHat = zeros(nz,1);
                    CS = filterData.("p#1 value");
                    NBFRAME = filterData.("p#2 value");
                    FRAMEWISE = false;
                    % create denoising object
                    denoising = denoise.Denoising( ...
                        original_img, AlphaHat, DeltaHat, SigmaHat, ...
                        FRAMEWISE, CS, NBFRAME);
                    denoising.setLog(false);
                    % estimate parameters
                    denoising.estimateNoiseParameters();
                    % denoise
                    denoising.perform();
                    % get output
                    output = denoising.getOutput();
                    if (nxe ~= nx || nye ~= ny)
                        output = denoise.Operations.crop2D(output, nx, ny, Ext);
                    end
%                     % display
%                     methods(ij.IJ,"-full")
%                     ij.IJ.showStatus("Denoising in progress...");
%                     PureDenoise_.display()
                    % get parameters of denoising
                    alpha=denoising.getAlpha();
                    delta=denoising.getDelta();
                    sigma=denoising.getSigma();
                    % create denoised image
                    imgDataFilt = zeros(nx, ny, nz);
                    for i = 1:nz
                        imgDataFilt(:,:,i) = ...
                            reshape(output.getSliceDouble(i-1), nx, ny);
                    end

                case 'Butterworth filter'
                    h_progressdlg.Message = ...
                        'Filtering image series using Butterworth filter.'; 
                    drawnow
                    % get filter parameters
                    butterOrder = filterData.("p#1 value");
                    widthOfBandStop = filterData.("p#2 value");
                    RMS_multipl = filterData.("p#3 value");
                    minFreq = filterData.("p#4 value");
                    % sampling period in s
                    T = app.data.pxSzT/1000;
                    % sampling frequency (Hz)
                    Fs = 1/T;
                    if minFreq>Fs/2
                        minFreq = Fs/2;
                    end
                    % length of signal
                    L = size(imgData,3);
                    % time vector
                    t = (0:L-1)*T;
                    % get average time profile of image series
                    avrg_prof = zeros(1,size(imgData,3));
                    for i=1:size(imgData,3)
                        avrg_prof(i) = mean(imgData(:,:,i),'all');
                    end
                    % get frequencies of noise
                    Y = fft(avrg_prof);
                    P2 = abs(Y/L);
                    P1 = P2(1:floor(L/2)+1);
                    P1(2:end-1) = 2*P1(2:end-1);
                    f = Fs*(0:(L/2))/L;
                    % remove trends in signal
                    [LT,ST,R] = trenddecomp(P1(:));
                    % calculate rms of signal to estimate noise in signal
                    peakPromin = RMS_multipl*rms(R(f>=minFreq));
                    % find frequencies which will be filtered
                    [pks,locs] = findpeaks(P1(f>=minFreq), f(f>=minFreq), ...
                        'MinPeakProminence', peakPromin);
                    % merge overlaping regions
                    bands = [locs-widthOfBandStop/2 ;locs+widthOfBandStop/2]';
                    try
                        merged_bands = bands;
                        % check which regions overlap
                        m_overlaps = merged_bands(:,1)<circshift(merged_bands(:,2),1);
                        m_overlaps(1) = false;
                        intersectingRegions = any(m_overlaps);
                        while intersectingRegions
                            idx_start = find(m_overlaps,1,'first')-1;
                            idx_end = find(m_overlaps(idx_start+1:end)==0,1,'first')+idx_start-1;
                            if isempty(idx_end), idx_end = numel(m_overlaps); end
                            % merge overlaping bands
                            merged_bands = [
                                merged_bands(1:idx_start-1,:); ...
                                [merged_bands(idx_start,1),merged_bands(idx_end,2)]; ...
                                merged_bands(idx_end+1:end,:)
                                ];
                            % check if there is still something overlapping
                            m_overlaps = merged_bands(:,1)<circshift(merged_bands(:,2),1);
                            m_overlaps(1) = false;
                            intersectingRegions = any(m_overlaps);
                        end
                        bands = merged_bands;
                    catch
                    end
                    % make sure that nothing is ouiside of interval (0,max(f))
                    bands(bands(:,1)<0,1) = 0;
                    bands(bands(:,2)>max(f),2) = max(f)-mean(diff(f))/1000;
                    % filter data with Butterworth filter
                    % filter type
                    ftype = 'stop';
                    % filter data
                    imgDataFilt = shiftdim(double(imgData),2);
                    for i=1:size(bands,1)
                        Wn = bands(i,:) ./ max(f);
                        % Transfer Function design
                        % [b,a] = butter(butterOrder, Wn, ftype);
                        % Zero-Pole-Gain design, numericaly more stable
                        [z,p,k] = butter(butterOrder, Wn, ftype);
                        [sos,g] = zp2sos(z,p,k);
                        % Display and compare results
                        % hfvt = fvtool(b,a,sos,'FrequencyScale','log');
                        % legend(hfvt,'TF Design','ZPK Design')
                        % imgDataFilt = filtfilt(b,a,imgDataFilt);
                        imgDataFilt = filtfilt(sos,g,imgDataFilt);
                    end
                    imgDataFilt = shiftdim(imgDataFilt,4);

                otherwise
                    h_progressdlg.Message = sprintf(...
                        'Filtering image series using %s filter.',filterName); 
                    drawnow
                    % 'if kernel size is 1, no filtering';
                    switch filterName
                        case 'median'
                            % first filter spatial dimension
                            if filterData.("p#1 value") > 1
                                for i=1:size(imgData,3)
                                    imgDataFilt(:,:,i) = medfilt2( ...
                                        imgData(:,:,i), ...
                                        [filterData.("p#1 value") filterData.("p#1 value")], ...
                                        'symmetric');
                                end
                            else
                                imgDataFilt = imgData;
                            end
                            % then filter time dimension
                            if filterData.("p#2 value") > 1
                                imgDataFilt = medfilt1(double(imgDataFilt), ...
                                    filterData.("p#2 value"), [], 3, 'truncate');
                            end
                        
                        case 'average'
                            % first filter spatial dimension
                            if filterData.("p#1 value") > 1
                                imgDataFilt = imboxfilt(imgData, ...
                                    [filterData.("p#1 value") filterData.("p#1 value")], ...
                                    'Padding','symmetric');
                            else
                                imgDataFilt = imgData;
                            end
                            % then filter time dimension
                            if filterData.("p#2 value") > 1
                                imgDataFilt = imboxfilt3(imgDataFilt, ...
                                    [1,1,filterData.("p#2 value")], ...
                                    'Padding','symmetric');
                            end
                    end       
            end
            
        end
        

        function createCondaEnv(app, hO, E, hf)
            % ask if continue
            keyboard
            editField_h = findobj(hf, 'Type','uieditfield');
            createCondaEnv = uiconfirm(app.UIFigure, ...
               'Do you want to create conda evironment?', ...
               'new Anaconda environment', ...
               'Options', {'Continue', 'Cancel'}, ...
               'DefaultOption', 2);
            
            switch createCondaEnv
                case 'Continue'
                    % ask about path to conda instalation
                    condaInstalled = uiconfirm(app.UIFigure, ...
                        {'Is Anaconda installed?', ...
                        '(e.g. /Users/rjanicek/opt/anaconda3)'}, ...
                        'path to Anaconda folder', ...
                        'Options', {'Yes', 'No'}, ...
                        'DefaultOption', 1);
                    
                    switch condaInstalled
                        case 'Yes'
                            % select conda root directory
                            condaPath = uigetdir( ...
                                app.EditField_pathToConda.Value, ...
                                'Select directory where Anaconda was installed.');
                            drawnow;
                            figure(app.UIFigure);

                        case 'No'
%                             uialert(app.UIFigure, ...
%                                 'https://www.anaconda.com/', ...
%                                 'install Anaconda',...
%                                 'Icon', 'info');
                            w = 400;
                            h = 100;
                            posFig = [round(app.UIFigure.Position(1)+app.UIFigure.Position(3)/2-w/2), ...
                                round(app.UIFigure.Position(2)+app.UIFigure.Position(4)/2-h/2), ...
                                w, h];
                            uifig = uifigure( ...
                                'WindowStyle','modal',...
                                'Units', 'pixels', ...
                                'Position', posFig, ...
                                'Name', 'install Anaconda');
                            uiax = uiaxes(uifig, ...
                                'Position',[0 0 uifig.Position(3:4)]);
                            uiax.Toolbar.Visible = 'off';
                            axis(uiax,'off')
                            % add hyperlink text
                            th = text(uiax, 0.5, 0.75, ...
                                '$\underline{https://www.anaconda.com/}$', ...
                                'color',[0 0 1], 'FontSize',20, ...
                                'Interpreter','latex', ...
                                'HorizontalAlignment','center');
                            % open the website
                            th.ButtonDownFcn = @(~,~)web('https://www.anaconda.com/'); 
                            % Add OK button to close figure
                            uibutton(uifig, 'Text', 'OK', ...
                                'Position', [round(w/2-75/2), 10, 75, 30], ...
                                'ButtonPushedFcn', @(h,~)delete(h.Parent))
                            
                            return
                    end
                                        
                case 'Cancel'
                    return
            end
            
            % full path to conda executable file
            conda_path = fullfile(condaPath, ...
                filesep, 'condabin', filesep, 'conda');
            % check the list of environments already created
            [status,envs] = system(sprintf('%s env list', conda_path));
            if status~=0
                % conda has to be installed
                uialert(app.UIFigure, ...
                    {'Please provide correct path to directory where Anaconda is installed!', ...
                    '(e.g. /Users/rjanicek/opt/anaconda3)'}, ...
                    'Error',...
                    'Icon', 'error');
                return
            end
            envs = strsplit(envs,'\n');
            m_envs = true(1,numel(envs));
            envs_names = cell(size(envs));
            envs_paths = cell(size(envs));
            for i = 1:numel(envs)
                env = strrep(envs{i}, '*', ' ');
                if isempty(env) || contains(env, '#')
                    m_envs(i) = false;
                    continue
                end
                env = strsplit(env);
                envs_names(i) = env(1);
                envs_paths(i) = env(2);
            end
            envs_names = envs_names(m_envs);
            envs_paths = envs_paths(m_envs);
            
            new_env_name = 'xytCaSignals';

            % status = system(sprintf('%s init zsh', conda_path));
            
            % check if spark_puff environment is created
            if any(strcmp(envs_names, new_env_name))
                % conda env already created
                uialert(app.UIFigure, ...
                    sprintf('Conda environment with this name (%s) is already created.', ...
                    new_env_name), ...
                    'Environment with the same name already created.',...
                    'Icon', 'warning');
            else
                % create conda environment xytCaSignals from environment
                % file
                appFile = mfilename('fullpath');
                [baseDir,~,~] = fileparts(appFile);
                % check in which mode app is running
                if isdeployed
                    % stand alone mode
                    % [d, result] = system('set PATH');
                    % currentDir = char(regexpi(result, 'Path=(.*?);', 'tokens', 'once'));
                    % look at it just in case there are some
                    % problems with paths to files
%                     uialert(app.UIFigure, ...
%                         {ctfroot,getenv('path')}, ...
%                         'path','Icon','warning')
                else
                    % running in Matlab
                    % add path to folder with ML model
                    % does not work in standalone mode
                    addpath(genpath([baseDir, filesep, 'ML_model']))
                end
                
                % add progressbar
                h_dlg = uiprogressdlg(app.UIFigure, ...
                    'Indeterminate','on', ...
                    'Message', sprintf('creating conda environment with name: %s',new_env_name), ...
                    'Title', 'New conda environment.');
                env_file_path = fullfile( ...
                    baseDir, 'ML_model', 'xytCaSignals.yml');
                try
                    status = system( ...
                        sprintf('%s env create --file %s', ...
                        conda_path, env_file_path));
                    if status==0
                        % call function to set the path to conda
                        % environment
                        h_dlg.Message = sprintf('Select path to %s conda environment.',new_env_name);
                        app.getCondaEnvDirPath()
                        close(h_dlg)
                    else
                        uialert(app.UIFigure, ...
                            {'Could not create new conda environment', ...
                            'Try to install it manually in command line and provide path to it.'}, ...
                            'error')
                    end
                catch ME
                    uialert(app.UIFigure, ...
                        ME.message, ...
                        'error')
                    close(h_dlg)
                end
            end
        end
        

        function getCondaEnvDirPath(app, hO, E, hf)
            % get path to directory of required conda environment
            editField_h = findobj(hf, 'Type','uieditfield');
            selpath = uigetdir(editField_h.Value);
            drawnow;
            figure(app.UIFigure);
            
            if selpath == 0
                return
            else
                editField_h.Value = selpath;
            end
        end


        function [s_evt_bs, e_evt_bs, ... 
                sE_inProfWithAdjustedBs, eE_inProfWithAdjustedBs] = ...
                findMaskOfEventWithBaseline(app, ...
                x, y, dx, sE, eE, type, evntType)
            % find resonable start and end of event, with some baseline in
            % whole time or spatial profile 
            % y = whole profile
            % sE = start of event
            % eE = end of event
            % type = 't' (time) or 's' spatial
            % add some baseline before and after detected event
            switch type
                case 't'
                    % maxTimeDur before and 3*maxTimeDur after the end of event
                    switch evntType
                        case 'spark'
                            smoothSpan = 50; % ms
                            maxTimeDur = 100;
                        case {'wave', 'transient'}
                            smoothSpan = 100; % ms
                            maxTimeDur = 500;
                        case 'puff'
                            smoothSpan = 100; % ms
                            maxTimeDur = 150;
                        otherwise
                            smoothSpan = 100; % ms
                            maxTimeDur = 100;
                    end
                    maxDurOfBs_b = ceil(maxTimeDur/dx);
                    maxDurOfBs_a = maxDurOfBs_b * 3;

                case 's'
                    % maximum 2 µm before and 2 µm after the end of event, should be
                    % symetric
                    maxDurOfBs_b = ceil(2/dx);
                    maxDurOfBs_a = maxDurOfBs_b;
                    smoothSpan = 2; % µm
            end

            % add baseline to event
            s_evt_bs = double(sE - maxDurOfBs_b);
            durOfBs_beforeEvnt = maxDurOfBs_b;
            if s_evt_bs<1
                s_evt_bs = 1;
                durOfBs_beforeEvnt = sE-1;
            end
            e_evt_bs = double(eE + maxDurOfBs_a);
            if e_evt_bs>numel(x)
                e_evt_bs = numel(x);
            end
            % check if there is overlap with some other events
            % smooth event profile with added baseline
            % fit time profile
            % gprMdl = fitrgp(x(s_evt_bs:e_evt_bs)', ...
            %     y(s_evt_bs:e_evt_bs)', ...
            %     'Basis','linear',...
            %     'FitMethod','exact', ...
            %     'PredictMethod','exact');
            % t_prof_s = resubPredict(gprMdl);
            n_pts = ceil(smoothSpan/dx);
            t_prof_s = smooth(y(s_evt_bs:e_evt_bs), ...
                n_pts/double(e_evt_bs-s_evt_bs), 'loess');
            t_prof_s = t_prof_s(:);

            % remove baseline from extended event profile
            % only events bigger than 25th percentile of detected
            % event profile (calculated only from detected not extended profile)
            % start and end of event in extended profile
            sE_inProfWithBs = sE-s_evt_bs+1;
            if sE_inProfWithBs<1
                sE_inProfWithBs = 1;
            end
            eE_inProfWithBs = eE-s_evt_bs+1;
            if eE_inProfWithBs<=sE_inProfWithBs
                eE_inProfWithBs = sE_inProfWithBs+1;
            end
            if eE_inProfWithBs>numel(t_prof_s)
                eE_inProfWithBs = numel(t_prof_s);
            end
            perc = prctile( ...
                t_prof_s(sE_inProfWithBs:eE_inProfWithBs), ...
                [25 50 75]);

            t_prof_s(t_prof_s<perc(1)) = perc(1);
            % check if there is some event before
            % move from detected start of event towards begining of
            % extended event profile
            bs_before_eventStart = t_prof_s(1:durOfBs_beforeEvnt);
            % flip profile and calculate gradient
            g_bs_before_eventStart = gradient(flipud(bs_before_eventStart));

            [st_e,~] = find((g_bs_before_eventStart>0),1,'first');
            if ~isempty(st_e)
                s_evt_bs = s_evt_bs + ...
                    numel(g_bs_before_eventStart) - st_e + 1;
            end
            % move from detected end of event towards end of
            % profile
            bs_after_eventEnd = t_prof_s( ...
                numel(bs_before_eventStart)+eE-sE+1 : end);
            % calculate gradient
            g_bs_after_eventEnd = gradient(bs_after_eventEnd);
            if g_bs_after_eventEnd(1) > 0
                % maximum not masked
                idx_max = find(g_bs_after_eventEnd<0, 1, 'first');
            else
                idx_max = 1;
            end

            [end_e,~] = find((g_bs_after_eventEnd(idx_max:end)>0), 1, 'first');
            if ~isempty(end_e)
                e_evt_bs = e_evt_bs - ...
                    (numel(g_bs_after_eventEnd) - (end_e+idx_max));
            end

            % start and end of event in profile with adjusted
            % duration of baseline
            sE_inProfWithAdjustedBs = ...
                double(sE-s_evt_bs+1);
            if sE_inProfWithAdjustedBs<1
                sE_inProfWithAdjustedBs = 1;
            end
            eE_inProfWithAdjustedBs = ...
                double(eE-s_evt_bs+1);
            if eE_inProfWithAdjustedBs<=sE_inProfWithAdjustedBs
                eE_inProfWithAdjustedBs = sE_inProfWithAdjustedBs+1;
            end
            if eE_inProfWithAdjustedBs>numel(t_prof_s)
                eE_inProfWithAdjustedBs = numel(t_prof_s);
            end

        end
        

        function [FD, prcOfAmpl_val, pos_t_1, pos_t_2, pos_1, pos_2] = ...
                fullDurationCalc(app, ...
                    t, t_prof, sE, eE, peakVal, peakPos, bs, prcOfAmpl)
            % calculate full duration at specified percentage of amplitude of event
            % half maximum value
            % t = time
            % t_prof = profile
            % sE, eE = start and end of event
            % peakVal, peakPos, bs = values of peak and baseline
            % prcOfAmpl = percentage of amplitude of event where to calculate full
            % duration
            t_indx = (1:1:numel(t));
            t_indx = t_indx(:);

            % value of desired percentage of amplitude
            prcOfAmpl_val = (peakVal - bs)*(prcOfAmpl/100) + bs;

            % parts of profiles before and after maximum of event
            t_prof_beforePeak = t_prof(1:peakPos-1);
            t_prof_beforePeak = flipud(t_prof_beforePeak(:));

            % before peak
            sE = min(sE, ...
                numel(t_prof_beforePeak) - ...
                find((t_prof_beforePeak-prcOfAmpl_val) < 0, 1, 'first'));
            % after peak
            t_prof_afterPeak = t_prof(peakPos:end);
            p_prcOfAmpl_afterPeak = peakPos + ...
                find((t_prof_afterPeak - prcOfAmpl_val) < 0, 1, 'first');
            if (isempty(p_prcOfAmpl_afterPeak) || p_prcOfAmpl_afterPeak>numel(t_prof))
                p_prcOfAmpl_afterPeak = numel(t_prof);
            end

            eE = max(eE, p_prcOfAmpl_afterPeak);

            if sE>1
                y_t1 = [ zeros(sE-1,1); t_prof(sE:peakPos-1) ];
            else
                y_t1 = t_prof(1:peakPos-1);
            end
            y_t2 = [ zeros(peakPos-1,1); t_prof(peakPos:eE) ];

            % find position of first half max position
            y_t1_r = flipud(y_t1);
            d_y_t1 = gradient(y_t1_r);

            if all(d_y_t1==0)
                pos_1 = numel(d_y_t1);
            else
                indDer1 = find( d_y_t1<=0 & y_t1_r<prcOfAmpl_val, 1, 'first');
                if ~isempty(indDer1), y_t1_r(indDer1:end) = 0; end

                y_t1 = flipud(y_t1_r);
                pos_last_0_y_t1 = find(y_t1>0, 1, 'first')-1;
                % get the first position of desired percentage of amplitude
                [~,pos_1] = min(abs(y_t1(pos_last_0_y_t1:end) - prcOfAmpl_val));
                pos_1 = pos_1 + pos_last_0_y_t1 - 1;
            end

            % find the second position of desired percentage of amplitude
            d_y_t2 = gradient(y_t2);

            indDer2 = find( d_y_t2<0 & y_t2<prcOfAmpl_val & t_indx(1:eE)>peakPos, ...
                1, 'first');
            if ~isempty(indDer2), y_t2(indDer2:end) = 0; end

            pos_last_numNot0_y_t2 = find(y_t2(peakPos:end)>0, 1, 'last') + peakPos - 1;
            [~, pos_2] = ...
                min(abs(y_t2(peakPos:pos_last_numNot0_y_t2) - prcOfAmpl_val));
            pos_2 = pos_2 + peakPos - 1;

            % positions in time
            pos_t_1 = t(pos_1);
            pos_t_2 = t(pos_2);
            % get full duration in half maximum
            FD = pos_t_2 - pos_t_1;
            if FD < 0
                FD = 0;
            elseif isempty(FD)
                FD = 0;
            end

        end

        

        function out = analyzeWaveInPseudoLineScan(app, img, evnt_mask)
            % add some baseline to event
            evnt_props = regionprops('table', evnt_mask, img, ...
                {'Area', 'SubarrayIdx', 'PixelIdxList'});
            evnt_props = evnt_props(evnt_props.Area == max(evnt_props.Area), :);
            % take whole time dimension and crop spatial one, 
            % only to see analyzed event
            whole_img_evnt = img(evnt_props.SubarrayIdx{1}, :);
            whole_mask_evnt = false(size(evnt_mask));
            whole_mask_evnt(evnt_props.PixelIdxList{1}) = true;
            whole_mask_evnt = whole_mask_evnt(evnt_props.SubarrayIdx{1}, :);

            % find reasonable baseline for the detected event
            wh_img_t = (0:1:size(whole_img_evnt,2)-1).*app.data.pxSzT;
            wh_img_t = wh_img_t(:);
            s_evt_bs = zeros([size(whole_img_evnt, 1), 1]);
            e_evt_bs = zeros([size(whole_img_evnt, 1), 1]);
            sE_inProfWithAdjustedBs = zeros([size(whole_img_evnt, 1), 1]);
            eE_inProfWithAdjustedBs = zeros([size(whole_img_evnt, 1), 1]);
            for i = 1:size(whole_img_evnt, 1)
                [s_evt_bs(i), e_evt_bs(i), ...
                    sE_inProfWithAdjustedBs(i), eE_inProfWithAdjustedBs(i)] = ...
                    app.findMaskOfEventWithBaseline( ...
                    wh_img_t, whole_img_evnt(i,:), ...
                    app.data.pxSzT, find(whole_mask_evnt(i,:), 1, 'first'), ...
                    find(whole_mask_evnt(i,:), 1, 'last'), 't', 'wave');
            end
            % expand mask of event with estimated baseline
            img_evnt_mask_bs = whole_mask_evnt;
            % add baseline to mask of event
            for i = 1:size(img_evnt_mask_bs, 1)
                img_evnt_mask_bs(i, s_evt_bs(i):e_evt_bs(i)) = true;
            end
            % get image of wave with added baseline
            img_evnt_bs = whole_img_evnt(:, min(s_evt_bs(:)):max(e_evt_bs(:)));
            img_evnt_bs = whole_img_evnt;
            % img_evnt_mask_bs = whole_mask_evnt(:, min(s_evt_bs(:)):max(e_evt_bs(:)));
            % adjust position of mask of events in image with baseline
            % sE_inProfWithAdjustedBs = sE_inProfWithAdjustedBs + ...
            %     s_evt_bs - min(s_evt_bs(:));
            % eE_inProfWithAdjustedBs = eE_inProfWithAdjustedBs + ...
            %     s_evt_bs - min(s_evt_bs(:));
            % % add baseline to mask of event
            % for i = 1:size(img_evnt_mask_bs, 1)
            %     img_evnt_mask_bs(:, ...
            %         sE_inProfWithAdjustedBs(i):eE_inProfWithAdjustedBs(i)) = true;
            % end

            % deskew event
            [waveImgDeskewed, deskewedWave_mask, waveStartPx] = ...
                deskewCalciumWave(app, img_evnt_bs, img_evnt_mask_bs, 100);
            % time axis
            t = (0:1:size(img_evnt_bs,2)-1).*app.data.pxSzT;
            t = t(:);
            % get t-profile and mask of event from deskewed event
            deskewedWaveProf = mean(waveImgDeskewed, 1);
            deskewedWaveProf_m = sum(deskewedWave_mask, 1) > size(deskewedWave_mask,1)*0.5;
            if ~any(deskewedWaveProf_m(:))
                deskewedWaveProf_m = any(deskewedWave_mask, 1);
            end

            % normalize whole time profile by fitting it without
            % events
            % try also check if there are some events in baseline fit
            thrs_evnt = median(deskewedWaveProf(~deskewedWaveProf_m)) + ...
                mad(deskewedWaveProf(~deskewedWaveProf_m),1);
            maskOfEventsInBsCalcl = deskewedWaveProf>thrs_evnt;
            if sum(deskewedWaveProf_m | maskOfEventsInBsCalcl) / ...
                    numel(deskewedWaveProf_m) > 0.5
                deskewedWaveProf_bs = repmat( ...
                    prctile(deskewedWaveProf(~(deskewedWaveProf_m | maskOfEventsInBsCalcl)), 5), ...
                    [1,numel(deskewedWaveProf)] );
            else
                % check if there are baseline data at beggining and end of profile
                % if no then fit with line (means profile starts with event
                % (not necessary the one we are analyzing) directly)
                if deskewedWaveProf_m(1) || deskewedWaveProf_m(end)
                    fo_line = fit(t(:), deskewedWaveProf(:), 'poly1', ...
                        'Normalize','on', ...
                        'Exclude', deskewedWaveProf_m | maskOfEventsInBsCalcl, ...
                        'Robust','bisquare');
                    deskewedWaveProf_bs = feval(fo_line, t(:));
                else
                    deskewedWaveProf_bs = deskewedWaveProf;
                    deskewedWaveProf_bs( ...
                        deskewedWaveProf_m | maskOfEventsInBsCalcl) = nan;
                    deskewedWaveProf_bs = smooth(t, deskewedWaveProf_bs, ...
                        0.5, 'rloess');
                end
            end
            % normalization deltaF/F0
            deskewedWaveProf = (deskewedWaveProf(:) - deskewedWaveProf_bs(:)) ./ ...
                (deskewedWaveProf_bs(:) - app.data.img_blank);
            % check if mask of event is intact
            % means no holes, mainly due to aligning procedure
            roi_props_evnt_m = regionprops('table', ...
                deskewedWaveProf_m(:), deskewedWaveProf(:), ...
                {'Area', 'PixelIdxList', 'MaxIntensity'});
            if height(roi_props_evnt_m)>1
                % keep the roi with highes amplitude
                roi_props_evnt_m = sortrows(roi_props_evnt_m, ...
                    'MaxIntensity', 'descend');
                deskewedWaveProf_m = false(size(deskewedWaveProf_m));
                deskewedWaveProf_m(roi_props_evnt_m.PixelIdxList{1}) = true;
            end
            
            % get wave speed
            waveStartT = t(waveStartPx)./1000; % in s
            waveStartT = waveStartT(:);
            x = (0:1:size(img_evnt_bs,1)-1).*app.data.pxSz;
            x = x(:);
            % fit with spline with order 2 (piece-wise line)
            % first guess of spline coefficients
            spl = spap2(ceil(max(x)/5), 2, x, waveStartT);
            % newknt for a possibly better knot distribution
            knots = newknt(spl);
            spl  = spap2(knots, 2, x, waveStartT);
            splFit = fnval(spl,x);
            % 1. derivations of spline
            dx_ws = gradient(x);
            dt_ws = gradient(splFit);
            % wave speed in um/s
            waveSpeedPx = abs(dx_ws./dt_ws); 
            % check for outliers
            mOutlWsp = isoutlier(waveSpeedPx, 'gesd');
            % calculate wave speed without outliers
            waveSpeed = mean(waveSpeedPx(~mOutlWsp));

            % fit deskewed wave profile and calculate parameters
            deskewedWaveProfFit_t = app.fitEventProfile( ...
                t(deskewedWaveProf_m), ...
                deskewedWaveProf(deskewedWaveProf_m), ...
                1, numel(t(deskewedWaveProf_m)), ...
                'yes', 'spline', 50);
            waveParams = app.getParametersOfEventFromProfileFit( ...
                deskewedWaveProfFit_t.x_ups, ...
                deskewedWaveProfFit_t.yFit_ups,...
                deskewedWaveProfFit_t.bs, ...
                deskewedWaveProfFit_t.t0, [], ...
                deskewedWaveProfFit_t.y_m_ups, ...
                true, 't');

            out.deskewedWaveProfFit = deskewedWaveProfFit_t;
            out.waveParams = waveParams;
            out.waveSpeed.average = waveSpeed;
            out.waveSpeed.waveSpeedPx = waveSpeedPx;
            out.waveSpeed.outliers = mOutlWsp;
            out.waveStartT = waveStartT;
            out.waveStartFit = splFit;
            out.waveStartX = x;
            out.wholeImg = whole_img_evnt;
            out.wholeImg_m = img_evnt_mask_bs;
            out.imgE_bs = whole_img_evnt(:, min(s_evt_bs(:)):max(e_evt_bs(:)));
            out.imgE_bs_m = img_evnt_mask_bs(:, min(s_evt_bs(:)):max(e_evt_bs(:)));
            out.deskewed_imgE_bs = waveImgDeskewed(:, deskewedWaveProf_m);
            out.deskewed_imgE_bs_m = deskewedWave_mask(:, deskewedWaveProf_m);
        end
        

        function trainDLM(app,hO, E, hf)
            % train deep learning model
            keyboard
            
        end
        

        function refitSelectedEvent(app, hO, E, hf)
            % control behaviour of reanalyze window
            hObjs = getappdata(hf, 'hObjs');
            switch hO.Type
                case 'axes'
                    ha = hO;
                case 'uidropdown'
                    switch hO.Tag
                        case 'tProf'
                            ha = findobj(hf, 'Type','axes', ...
                                'Tag','tProf');
                        case 'xyProf'
                            if isempty(hObjs.lastAxes)
                                return
                            else
                                switch hObjs.lastAxes.Tag
                                    case 'xProf'
                                        ha = findobj(hf, 'Type','axes', ...
                                            'Tag','xProf');
                                    case 'yProf'
                                        ha = findobj(hf, 'Type','axes', ...
                                            'Tag','yProf');
                                end
                            end
                    end
                case 'uibutton'
                    switch hO.Tag
                        case 'tProf'
                            hO.Text = 'normalized';
                            hObjs.h_pb_selfRatio.Enable = 'off';
                            hObjs.h_drop_bsFit.Enable = 'off';
                            ha = findobj(hf, 'Type','axes', ...
                                'Tag','tProf');
                        case 'save'
                            % update parameters of events shown in table in
                            % app
                            eventFits = app.data.reanalyzedEvent.eventFits{1};
                            app.data.reanalyzedEvent.amplitude = ...
                                eventFits.evntProfs.tProf.p.amplitude;
                            app.data.reanalyzedEvent.TTP = ...
                                eventFits.evntProfs.tProf.p.TTP;
                            app.data.reanalyzedEvent.FDHM = ...
                                eventFits.evntProfs.tProf.p.FDHM;
                            app.data.reanalyzedEvent.tauRise = ...
                                eventFits.evntProfs.tProf.fit.tauR;
                            app.data.reanalyzedEvent.tauDecay = ...
                                eventFits.evntProfs.tProf.fit.tauD;
                            try
                                app.data.reanalyzedEvent.FWHMx = ...
                                    eventFits.evntProfs.xProf.p.FWHM;
                                app.data.reanalyzedEvent.FWHMy = ...
                                    eventFits.evntProfs.yProf.p.FWHM;
                            catch
                            end
                            try
                                app.data.reanalyzedEvent.waveSpeed = ...
                                    eventFits.evntSP.waveSpeed_pls;
                                app.data.reanalyzedEvent.waveSpeedCentroids = ...
                                    eventFits.evntSP.waveSpeed_centroids;
                            catch
                            end
                            % save re-analyzed event to
                            % all detected events table
                            idx = app.data.reanalyzedEvent.eventID;
                            app.data.detectedEvents(...
                                app.data.detectedEvents.eventID==idx,:) = ...
                                app.data.reanalyzedEvent;
                            % show fits of event in app window
                            app.showProfilesAndFitsOfSelectedEvent()
                            return
                    end
            end

            % update fit on axes, where the last mask correction was done
            % get old parameters of reanalyzed event
            evntProfs = app.data.reanalyzedEvent.eventFits{1}.evntProfs;
            evntSP = app.data.reanalyzedEvent.eventFits{1}.evntSP;
            switch ha.Tag
                case 'tProf'
                    % get data from axes
                    profLineObj = findobj(ha, 'Type','line', ...
                        'DisplayName','data');
                    x = profLineObj.XData;
                    y = profLineObj.YData;
                    x = x(:);
                    y = y(:);
                    % check if updating baseline or event mask
                    switch hObjs.h_pb_selfRatio.Text
                        case 'calculate self-ratio'
                            mBsLineObj = findobj(ha, 'Type','line', ...
                                'DisplayName','baselineMask');
                            % fit baseline
                            m = ismember(x, mBsLineObj.XData);
                            bsFit = app.fitBaseline(x, y, m, ...
                                hObjs.h_drop_bsFit.Value);
                            % show fit as line in axes
                            fitBsLineObj = findobj(ha, 'Type','line', ...
                                'DisplayName','baselineFit');
                            if isempty(fitBsLineObj)
                                % create new
                                line(ha, x, bsFit, ...
                                    'LineStyle','-', 'LineWidth',2, ...
                                    'Color','r', 'DisplayName','baselineFit')
                            else
                                % update
                                fitBsLineObj.YData = bsFit;
                            end
                            % show legend       
                            legend(ha, 'show', 'Location','best')
                            return
                        % already normalized profile, fit event
                        case 'normalized'
                            % check if profile is normalized
                            % get baseline fit data
                            fitBsLineObj = findobj(ha, 'Type','line', ...
                                'DisplayName','baselineFit');
                            % get mask of event
                            mEvntLineObj = findobj(ha, ...
                                'Type','line', ...
                                'DisplayName','eventMask');
                            mEvnt = ismember(x, mEvntLineObj.XData);
                            % get fit function
                            fitFun = hObjs.h_drop_tProfFit.Value;
                            switch fitFun
                                case {'spline', '1expR1expD'}
                                    piecewise = 'yes';
                                otherwise
                                    piecewise = 'no';
                            end
                            if isempty(fitBsLineObj)
                                % already normalized, update fit of event
                                % fit normalized profile
                                evntFit = app.fitEventProfile( ...
                                    x, y, ...
                                    find(mEvnt,1,'first'), ...
                                    find(mEvnt,1,'last'), ...
                                    piecewise, fitFun, ...
                                    hObjs.h_edit_smoothSpan.Value);
                            else
                                % normalize profile and do a first fit of
                                % data
                                bsFit = fitBsLineObj.YData;
                                y = (y-bsFit(:)) ./ ...
                                    (bsFit(:)-app.data.img_blank);
                                % delete baseline mask and fit and legend
                                delete(fitBsLineObj)
                                delete(findobj(ha, 'Type','line', ...
                                    'DisplayName','baselineMask'))
                                % disable correction of wave mask
                                hObjs.uipnl_wave.Enable = 'off';
                                % find resonable start and end of event, 
                                % with some baseline in whole profile of
                                % image series
                                [s_evt_bs, e_evt_bs, ...
                                    sE_inProfWithAdjustedBs, ...
                                    eE_inProfWithAdjustedBs] = ...
                                    app.findMaskOfEventWithBaseline( ...
                                    x, y, app.data.pxSzT, ...
                                    find(mEvnt,1,'first'), ...
                                    find(mEvnt,1,'last'), 't', ...
                                    app.data.reanalyzedEvent.eventType);
                                % fit normalized profile
                                evntFit = app.fitEventProfile( ...
                                    x(s_evt_bs:e_evt_bs), ...
                                    y(s_evt_bs:e_evt_bs), ...
                                    sE_inProfWithAdjustedBs, ...
                                    eE_inProfWithAdjustedBs, ...
                                    piecewise, fitFun, ...
                                    hObjs.h_edit_smoothSpan.Value);
                                % update mask of event in time
                                % profile of whole image series
                                evntSP.whole_tProf_evntMask = mEvnt;
                            end
                            % get parameters from profile fit
                            evntParams = app.getParametersOfEventFromProfileFit(...
                                evntFit.x_ups, evntFit.yFit_ups, ...
                                evntFit.bs, evntFit.t0, ...
                                [], evntFit.y_m_ups, true, 't');
                            % update fit and changed parameters of
                            % reanalyzed event
                            evntProfs.tProf.fit = evntFit;
                            evntProfs.tProf.p = evntParams;
                            % support and calculated parameters (SP)
                            switch app.data.reanalyzedEvent.eventType
                                case {'wave', 'transient'}
                                    evntSP.eventMass = nan;
                                otherwise
                                    evntSP.eventMass = ...
                                        evntParams.amplitude * 1.206 * ...
                                        mean([evntProfs.xProf.p.FWHM, ...
                                            evntProfs.yProf.p.FWHM])^3;
                            end
                            evntSP.SNR = evntParams.amplitude / ...
                                evntFit.sd_noise;
                            % plot/update data
                            profLineObj.XData = evntFit.x;
                            profLineObj.YData = evntFit.y;
                            % plot update fit line object
                            fitLineObj = findobj(ha, 'Type','line', ...
                                'DisplayName','eventFit');
                            if isempty(fitLineObj)
                                line(ha, evntFit.x_ups, evntFit.yFit_ups, ...
                                    'LineStyle','-', 'LineWidth',2, ...
                                    'Color','r', 'DisplayName','eventFit')
                            else
                                fitLineObj.XData = evntFit.x_ups;
                                fitLineObj.YData = evntFit.yFit_ups;
                            end
                            % update mask of event
                            mEvntLineObj.XData = evntFit.x(evntFit.y_m);
                            mEvntLineObj.YData = evntFit.y(evntFit.y_m);
                            % show parameters of event
                            delete(findobj(ha, {'Type','line'},'-and', ...
                                {'DisplayName','TTP','-or', ...
                                'DisplayName','FDMH','-or', ...
                                'DisplayName','amplitude'}));
                            line(ha, ...
                                [evntParams.t0 evntParams.t_max], ...
                                [evntParams.bs evntParams.bs], ...
                                'Color','b', 'Marker','none', ...
                                'LineStyle','-', 'LineWidth',2, ...
                                'DisplayName','TTP')
                            line(ha, ...
                                [evntParams.t_max evntParams.t_max], ...
                                [evntParams.bs evntParams.v_max], ...
                                'Color','g', 'Marker','none', ...
                                'LineStyle','-', 'LineWidth',2, ...
                                'DisplayName','amplitude')
                            line(ha, ...
                                [evntParams.half_max_t_1 evntParams.half_max_t_2], ...
                                [evntParams.half_max_t evntParams.half_max_t], ...
                                'Color','m', 'Marker','none', ...
                                'LineStyle','-', 'LineWidth',2, ...
                                'DisplayName','FDMH')
                            % update axis limits
                            set(ha, ...
                                'XLim',[min(x) max(x)], ...
                                'YLim',[min(y)*0.95 max(y)*1.05]);  
                            title(ha, ...
                                'normalized temporal profile', ...
                                'FontSize',18)
                            xlabel(ha, 'time (ms)', ...
                                'FontSize',16)
                            ylabel(ha, ...
                                'fluorescence (\DeltaF/F_0)', ...
                                'FontSize',16)
                            % show legend       
                            legend(ha, 'show', 'Location','best')
                    end

                case 'xProf'
                    % get data from axes
                    profLineObj = findobj(ha, 'Type','line', ...
                        'DisplayName','data');
                    mEvntLineObj = findobj(ha, 'Type','line', ...
                        'DisplayName','eventMask');
                    x = profLineObj.XData;
                    y = profLineObj.YData;
                    mEvnt = ismember(x, mEvntLineObj.XData);
                    % fit event
                    evntFit = app.fitEventProfile( ...
                        x, y, ...
                        find(mEvnt,1,'first'), ...
                        find(mEvnt,1,'last'), ...
                        'no', hObjs.h_drop_spatialProfFit.Value, ...
                        hObjs.h_edit_smoothSpan.Value);
                    % get parameters from profile fit
                    evntParams = app.getParametersOfEventFromProfileFit(...
                        evntFit.x_ups, evntFit.yFit_ups, ...
                        evntFit.bs, [], ...
                        [], evntFit.y_m_ups, false, 's');
                    % save fit and parameters
                    evntProfs.xProf.fit = evntFit;
                    evntProfs.xProf.p = evntParams;
                    evntSP.eventMass = ...
                        evntProfs.tProf.p.amplitude * 1.206 * ...
                        mean([evntParams.FWHM,evntProfs.yProf.p.FWHM])^3;
                    % plot/update data
                    profLineObj.XData = evntFit.x;
                    profLineObj.YData = evntFit.y;
                    % plot update fit line object
                    fitLineObj = findobj(ha, 'Type','line', ...
                        'DisplayName','eventFit');
                    if isempty(fitLineObj)
                        line(ha, evntFit.x_ups, evntFit.yFit_ups, ...
                            'LineStyle','-', 'LineWidth',2, ...
                            'Color','r', 'DisplayName','eventFit')
                    else
                        fitLineObj.XData = evntFit.x_ups;
                        fitLineObj.YData = evntFit.yFit_ups;
                    end
                    % update mask of event
                    mEvntLineObj.XData = evntFit.x(evntFit.y_m);
                    mEvntLineObj.YData = evntFit.y(evntFit.y_m);
                    % show parameters of event
                    delete(findobj(ha, {'Type','line'},'-and', ...
                        {'DisplayName','FWHMx'}));
                    line(ha, ...
                        [evntParams.half_max_x_1 evntParams.half_max_x_2], ...
                        [evntParams.half_max_x evntParams.half_max_x], ...
                        'Color','b', 'Marker','none', ...
                        'LineStyle','-', 'LineWidth',2, ...
                        'DisplayName','FWHMx')
                    set(ha, 'FontSize',14, ...
                        'XLim',[min(evntFit.x) max(evntFit.x)], ...
                        'YLim',[min(evntFit.y)*0.95 max(evntFit.y)*1.05]);
                    % show legend
                    legend(ha, 'show', 'Location','best')

                case 'yProf'
                    % get data from axes
                    profLineObj = findobj(ha, 'Type','line', ...
                        'DisplayName','data');
                    mEvntLineObj = findobj(ha, 'Type','line', ...
                        'DisplayName','eventMask');
                    x = profLineObj.YData;
                    y = profLineObj.XData;
                    mEvnt = ismember(x, mEvntLineObj.YData);
                    % fit event
                    evntFit = app.fitEventProfile( ...
                        x, y, ...
                        find(mEvnt,1,'first'), ...
                        find(mEvnt,1,'last'), ...
                        'no', hObjs.h_drop_spatialProfFit.Value, ...
                        hObjs.h_edit_smoothSpan.Value);
                    % get parameters from profile fit
                    evntParams = app.getParametersOfEventFromProfileFit(...
                        evntFit.x_ups, evntFit.yFit_ups, ...
                        evntFit.bs, [], ...
                        [], evntFit.y_m_ups, false, 's');
                    % save fit and parameters
                    evntProfs.yProf.fit = evntFit;
                    evntProfs.yProf.p = evntParams;
                    evntSP.eventMass = ...
                        evntProfs.tProf.p.amplitude * 1.206 * ...
                        mean([evntProfs.xProf.p.FWHM,evntParams.FWHM])^3;
                    % plot/update data
                    profLineObj.YData = evntFit.x;
                    profLineObj.XData = evntFit.y;
                    % plot update fit line object
                    fitLineObj = findobj(ha, 'Type','line', ...
                        'DisplayName','eventFit');
                    if isempty(fitLineObj)
                        line(ha, evntFit.yFit_ups, evntFit.x_ups,  ...
                            'LineStyle','-', 'LineWidth',2, ...
                            'Color','r', 'DisplayName','eventFit')
                    else
                        fitLineObj.YData = evntFit.x_ups;
                        fitLineObj.XData = evntFit.yFit_ups;
                    end
                    % update mask of event
                    mEvntLineObj.YData = evntFit.x(evntFit.y_m);
                    mEvntLineObj.XData = evntFit.y(evntFit.y_m);
                    % show parameters of event
                    delete(findobj(ha, {'Type','line'},'-and', ...
                        {'DisplayName','FWHMy'}));
                    line(ha, ...
                        [evntParams.half_max_x evntParams.half_max_x], ...
                        [evntParams.half_max_x_1 evntParams.half_max_x_2], ...
                        'Color','b', 'Marker','none', ...
                        'LineStyle','-', 'LineWidth',2, ...
                        'DisplayName','FWHMy')
                    set(ha, ...
                        'YLim',[min(evntFit.x) max(evntFit.x)], ...
                        'XLim',[min(evntFit.y)*0.95 max(evntFit.y)*1.05]);
                    % show legend
                    legend(ha, 'show', 'Location','best')
            end
            % save changed parameters
            app.data.reanalyzedEvent.eventFits{1}.evntProfs = evntProfs;
            app.data.reanalyzedEvent.eventFits{1}.evntSP = evntSP;
        end
        

        function showSelectedEventInReAnalyzeWindow(app, refitFig)
            % get data and fits
            hObjs = getappdata(refitFig, 'hObjs');
            if isempty(app.data.selectedEvent)
                answr = uiconfirm(refitFig,...
                    'Please first select an event from detected events table.', ...
                    'Warning Message', 'Options','OK', ...
                    'Icon','warning');
                close(refitFig)
                return
            end
            evntIdx = find(app.data.selectedEvent.eventID == ...
                app.data.detectedEvents.eventID);
            eventData = app.data.detectedEvents(evntIdx,:);
            % save selected event, which will be reanalyzed as 
            app.data.reanalyzedEvent = eventData;
            eventFits = eventData.eventFits{1}.evntProfs;
            eventSP = eventData.eventFits{1}.evntSP;
            if ~isfield(eventSP, 'mask_corrected')
                eventSP.mask_corrected = false;
            end
            cmapEvnt = app.DropDown_lookuptable_events.Value;
            % clear axes
            cla(hObjs.ax_wholeImg)
            cla(hObjs.ax_img)
            cla(hObjs.ax_tProf)
            cla(hObjs.ax_xProf)
            cla(hObjs.ax_yProf)
            % enable all control components
            for objN = fieldnames(hObjs)'
                if ~contains(objN, {'ax_','lastAxes'})
                    hObjs.(objN{1}).Enable = 'on';
                end
            end
            % set text of self ratio button back to calculate self ratio
            hObjs.h_pb_selfRatio.Text = 'calculate self-ratio';
            mrkSz = 15;
            % show event before self ratio
            switch eventData.eventType
                case 'transient'
                    keyboard
                    % calculate average from cell area, fit profile
                    % disable spatial profile refit control
                    hObjs.uipnl_spatialProf.Enable = 'off';
                    % finis REFIT function for waves and transients
                
                case 'wave'
                    % disable temporal and spatial profile refit control
                    hObjs.uipnl_spatialProf.Enable = 'off';
                    % first deskew wave and then normalize wave profile
                    % show whole time series, if too long > 5 s, 
                    % show wave +- 2 s around detected wave mask
                    % check duration of image series in s
                    if size(app.data.imgData,3)*app.data.pxSzT/1000 > 5
                        n_px_crop = ceil(2000/app.data.pxSzT);
                        imgSeries_show_s = eventData.imgNum_s - n_px_crop;
                        if imgSeries_show_s < 1
                            imgSeries_show_s = 1; 
                        end
                        imgSeries_show_e = eventData.imgNum_e + n_px_crop;
                        if imgSeries_show_e > size(app.data.imgData,3)
                            imgSeries_show_e = size(app.data.imgData,3); 
                        end
                    else
                        imgSeries_show_s = 1;
                        imgSeries_show_e = size(app.data.imgData,3);
                    end
                    % get data
                    % show image data from wave analysis
                    % whole cell image, try to pick those with minimim
                    % events and movement
                    evntsAreaPerFrame = zeros(1,imgSeries_show_e-imgSeries_show_s+1);
                    for f = imgSeries_show_s:imgSeries_show_e
                        evntsAreaPerFrame(f) = ...
                            sum(app.data.eventsIDs(:,:,f)>0, 'all');
                    end
                    wholeCellImg = mean( ...
                        app.data.imgData(:, :, ...
                            evntsAreaPerFrame==min(evntsAreaPerFrame)), 3);
                    % get wave images
                    wavePLSimg = ...
                            eventSP.wholeImg(:,imgSeries_show_s:imgSeries_show_e);
                    if eventSP.mask_corrected
                        mWavePLSimg = eventSP.pls_img_m;
                        eventSP.mask_corrected = false;
                    else
                        mWavePLSimg = ...
                            eventSP.wholeImg_m(:,imgSeries_show_s:imgSeries_show_e);
                    end
                    % deskew wave 
                    [deskewedImg, mDeskewedImg] = ...
                        app.deskewCalciumWave(wavePLSimg, mWavePLSimg, ...
                        hObjs.h_edit_deskewSpan.Value);
                    % get time profile from deskewed wave
                    tProf = mean(deskewedImg, 1);
                    tProf_m = any(mDeskewedImg, 1);
                    % baseline mask, make sure that there is one
                    if all(tProf_m)
                        bs_m = tProf<prctile(tProf(:),1);
                    else
                        bs_m = ~tProf_m;
                    end
                    t = app.data.t;
                    % deskewedWavePLSimg = eventSP.pls_imgDeskewed;
                    % mDeskewedWavePLSimg = eventSP.pls_imgDeskewed_m;
                    % pseudo line scan position in xy image of cell
                    ls_row_ind = eventSP.params.ls_row_ind;
                    ls_col_ind = eventSP.params.ls_col_ind;
                    % wavefront data
                    wf_line_x = eventSP.waveStartX;
                    wf_line_t = eventSP.waveStartT;
                    wf_line_fit = eventSP.waveStartFit;

                    % show whole cell with line depicting pseudo linescan
                    colormap(hObjs.ax_wholeImg, cmapEvnt)
                    imagesc(hObjs.ax_wholeImg, wholeCellImg)
                    set(hObjs.ax_wholeImg, 'FontSize',14, ...
                        'XLim',[1,size(wholeCellImg,2)], ...
                        'YLim',[1,size(wholeCellImg,1)], ...
                        'CLim',app.getAxisLimits(wholeCellImg, 1));
                    title(hObjs.ax_wholeImg, ...
                        ['cell with line representing pseudo linescan ' ...
                        '(position of centroids of event in individual frames)'], ...
                        'FontSize',18)
                    ylabel(hObjs.ax_wholeImg, 'y (pixels)', 'FontSize',16)
                    xlabel(hObjs.ax_wholeImg, 'x (pixels)', 'FontSize',16)
                    % add line representing pseudo linescan
                    drawpolyline(hObjs.ax_wholeImg, ...
                        'Position',[ls_col_ind(:) ls_row_ind(:)], ...
                        'InteractionsAllowed','none', ...
                        'LineWidth',2, 'Color','k');

                    % show wave with mask in whole image series with mask
                    % and line depicting detected wavefront
                    colormap(hObjs.ax_img, cmapEvnt)
                    imagesc(hObjs.ax_img, wavePLSimg, ...
                        'Tag', 'data')
                    hold(hObjs.ax_img, 'on')
                    img_m = double(mWavePLSimg).* ...
                        prctile(mWavePLSimg(:),50);
                    imagesc(hObjs.ax_img, img_m, ...
                        'AlphaData', mWavePLSimg.*0.25, ...
                        'Tag', 'mask')
                    set(hObjs.ax_img, 'FontSize',14, ...
                        'XLim',[1, size(wavePLSimg,2)], ...
                        'YLim',[1, size(wavePLSimg,1)], ...
                        'CLim',app.getAxisLimits(wavePLSimg, 5));
                    title(hObjs.ax_img, ...
                        'pseudo linescan of detected Ca^{2+} wave with mask', ...
                        'FontSize',18)
                    ylabel(hObjs.ax_img, 'x (pixels)', 'FontSize',16)
                    xlabel(hObjs.ax_img, 't (pixels)', 'FontSize',16)
                    % show detected wavefront
                    % convert wavefront line position in pixels
                    wf_line_fit_t_px = (wf_line_fit*1000)./app.data.pxSzT;
                    wf_line_x_px = wf_line_x./app.data.pxSz;
                    drawpolyline(hObjs.ax_img, ...
                        'Position',[wf_line_fit_t_px(:) wf_line_x_px(:)], ...
                        'InteractionsAllowed','none', ...
                        'LineWidth',2, 'Color','k', 'Tag', 'wavefront');
 
                    % show deskewed wave
                    colormap(hObjs.ax_yProf, cmapEvnt)
                    imagesc(hObjs.ax_yProf, deskewedImg, ...
                        'Tag','data')
                    set(hObjs.ax_yProf, 'FontSize',14, ...
                        'XLim',[1, size(deskewedImg,2)], ...
                        'YLim',[1, size(deskewedImg,1)], ...
                        'CLim',app.getAxisLimits(deskewedImg, 5));
                    title(hObjs.ax_yProf, ...
                        'deskewed Ca^{2+} wave', ...
                        'FontSize',18)
                    ylabel(hObjs.ax_yProf, 'x (pixels)', 'FontSize',16)
                    xlabel(hObjs.ax_yProf, 't (pixels)', 'FontSize',16)
                   
                    % show t-profile of wave in whole image series
                    line(hObjs.ax_tProf, ...
                        t, tProf, ...
                        'Color','k', 'Marker','none', ...
                        'LineStyle','-', 'LineWidth',1, ...
                        'Display', 'data')
                    line(hObjs.ax_tProf, ...
                        t(tProf_m), tProf(tProf_m), ...
                        'Color','b', 'Marker','.', 'MarkerSize',mrkSz, ...
                        'LineStyle','none', 'LineWidth',2, ...
                        'Display','eventMask')
                    line(hObjs.ax_tProf, ...
                        t(bs_m), tProf(bs_m), ...
                        'Color','g', 'Marker','.', 'MarkerSize',mrkSz, ...
                        'LineStyle','none', 'LineWidth',2, ...
                        'Display','baselineMask')
                    bsFit = app.fitBaseline(t, tProf, ...
                        bs_m, hObjs.h_drop_bsFit.Value);
                    line(hObjs.ax_tProf, t, bsFit, ...
                        'Color','r', 'Marker','none', 'MarkerSize',mrkSz, ...
                        'LineStyle','-', 'LineWidth',2, ...
                        'Display','baselineFit')
                    set(hObjs.ax_tProf, 'FontSize',14, ...
                        'XLim',[min(t) max(t)], ...
                        'YLim',app.getAxisLimits(tProf, 5));
                    hObjs.ax_tProf.InnerPosition(1) = hObjs.ax_yProf.InnerPosition(1);
                    hObjs.ax_tProf.InnerPosition(3) = hObjs.ax_yProf.InnerPosition(3);
                    title(hObjs.ax_tProf, ...
                        'temporal profile of Ca^{2+} wave', ...
                        'FontSize',18)
                    xlabel(hObjs.ax_tProf, 'time (ms)', 'FontSize',16)
                    ylabel(hObjs.ax_tProf, 'fluorescence (F)', ...
                        'FontSize',16)
                    legend(hObjs.ax_tProf,'Location','best')
                           
                otherwise
                    % local events
                    % disable wave refit control
                    hObjs.uipnl_wave.Enable = 'off';
                    % show whole cell with boundary of event
                    whImgAtMax = ...
                        app.data.imgData(:,:,eventSP.maxOfEventFrame);
                    whImgEvntMask = ...
                        app.data.eventsIDs(:,:,eventSP.maxOfEventFrame) == ...
                        eventData.eventID;
                    colormap(hObjs.ax_wholeImg, cmapEvnt)
                    imagesc(hObjs.ax_wholeImg, whImgAtMax)
                    hold(hObjs.ax_wholeImg, 'on')
                    % event boundary
                    whImgEvntBoundary = bwboundaries(whImgEvntMask);
                    patch( ...
                        'Faces',(1:numel(whImgEvntBoundary{1}(:,2))), ...
                        'Vertices',[whImgEvntBoundary{1}(:,2), ...
                                    whImgEvntBoundary{1}(:,1)], ...
                        'FaceColor','k', 'FaceAlpha',0, ... % 0.2
                        'EdgeColor','k', 'EdgeAlpha',1, ...
                        'Parent', hObjs.ax_wholeImg, ...
                        'LineWidth', 2, 'Tag', 'evnt');
                    set(hObjs.ax_wholeImg, 'FontSize',14, ...
                        'XLim',[0,size(whImgAtMax,2)]+0.5, ...
                        'YLim',[0,size(whImgAtMax,1)]+0.5, ...
                        'CLim',app.getAxisLimits(whImgAtMax, 5));
                    title(hObjs.ax_wholeImg, ...
                        'frame with maximum of detected Ca^{2+} event with boundary', ...
                        'FontSize',18)
                    ylabel(hObjs.ax_wholeImg, 'y (pixels)', 'FontSize',16)
                    xlabel(hObjs.ax_wholeImg, 'x (pixels)', 'FontSize',16)

                    % show event with mask
                    eventImg = whImgAtMax(...
                        eventSP.evntImg_row_s:eventSP.evntImg_row_e, ...
                        eventSP.evntImg_col_s:eventSP.evntImg_col_e);
                    eventImgMask = whImgEvntMask(...
                        eventSP.evntImg_row_s:eventSP.evntImg_row_e, ...
                        eventSP.evntImg_col_s:eventSP.evntImg_col_e);
                    colormap(hObjs.ax_img, cmapEvnt)
                    imagesc(hObjs.ax_img, eventImg)
                    hold(hObjs.ax_img, 'on')
                    % event mask
                    imagesc(hObjs.ax_img, eventImgMask, ...
                        'AlphaData', eventImgMask.*0.4)
                    set(hObjs.ax_img, 'FontSize',14, ...
                        'XLim',[0,size(eventImg,2)]+0.5, ...
                        'YLim',[0,size(eventImg,1)]+0.5, ...
                        'CLim',app.getAxisLimits(eventImg, 5));
                    title(hObjs.ax_img, ...
                        'image of detected Ca^{2+} event with mask', ...
                        'FontSize',18)
                    ylabel(hObjs.ax_img, 'y (pixels)', 'FontSize',16)
                    xlabel(hObjs.ax_img, 'x (pixels)', 'FontSize',16)
                    % show lines where profiles where calculated from
                    evnt_centr = eventSP.eventMaxInEvntImg;
                    drawline(hObjs.ax_img, ...
                        'Position',[evnt_centr(2) hObjs.ax_img.YLim(1); ...
                        evnt_centr(2) hObjs.ax_img.YLim(2)], ...
                        'InteractionsAllowed', ...
                        'none', 'LineWidth',2, 'Color','k');
                    drawline(hObjs.ax_img, ...
                        'Position',[hObjs.ax_img.XLim(1) evnt_centr(1); ...
                        hObjs.ax_img.XLim(2) evnt_centr(1) ], ...
                        'InteractionsAllowed','none', ...
                        'LineWidth',2, 'Color','k');

                    % show profiles without normalization
                    % get data
                    % calculate t-profile of event from whole series
                    evntProf_t_all = zeros(1, size(app.data.imgData, 3));
                    evntProf_t_all_mask = eventSP.whole_tProf_evntMask;
                    for p = 1:size(app.data.imgData, 3)
                        evntProf_t_all(p) = ...
                            mean(app.data.imgData( ...
                                    eventSP.tProf_row_s:eventSP.tProf_row_e, ...
                                    eventSP.tProf_col_s:eventSP.tProf_col_e, p), ...
                                    'all');
                    end
                    % baseline mask, make sure that there is one
                    if all(eventSP.whole_tProf_evntMask)
                        bs_t_all_mask = ...
                            evntProf_t_all<prctile(evntProf_t_all(:),1);
                    else
                        bs_t_all_mask = ~eventSP.whole_tProf_evntMask;
                    end
                    % time of whole profile
                    t_all = (0:numel(evntProf_t_all)-1).*app.data.pxSzT;
                    % show whole image series t-profile with mask 
                    line(hObjs.ax_tProf, t_all, evntProf_t_all, ...
                        'Color','k', 'Marker','none', 'LineStyle','-', ...
                        'Display','data')
                    line(hObjs.ax_tProf, t_all(evntProf_t_all_mask), ...
                        evntProf_t_all(evntProf_t_all_mask), ...
                        'Color','b', 'Marker','.', 'MarkerSize',mrkSz, ...
                        'LineStyle','none', 'LineWidth',2, ...
                        'Display','eventMask')
                    line(hObjs.ax_tProf, t_all(bs_t_all_mask), ...
                        evntProf_t_all(bs_t_all_mask), ...
                        'Color','g', 'Marker','.', 'MarkerSize',mrkSz, ...
                        'LineStyle','none', 'LineWidth',2, ...
                        'Display','baselineMask')
                    bsFit = app.fitBaseline(t_all, evntProf_t_all, ...
                        bs_t_all_mask, hObjs.h_drop_bsFit.Value);
                    line(hObjs.ax_tProf, t_all, bsFit, ...
                        'Color','r', 'Marker','none', 'MarkerSize',mrkSz, ...
                        'LineStyle','-', 'LineWidth',2, ...
                        'Display','baselineFit')
                    set(hObjs.ax_tProf, 'FontSize',14, ...
                        'XLim',[min(t_all) max(t_all)], ...
                        'YLim',app.getAxisLimits(evntProf_t_all, 5));
                    title(hObjs.ax_tProf, ...
                        'temporal profile from whole image series', ...
                        'FontSize',18)
                    xlabel(hObjs.ax_tProf, 'time (ms)', 'FontSize',16)
                    ylabel(hObjs.ax_tProf, 'fluorescence (F)', ...
                        'FontSize',16)
                    legend(hObjs.ax_tProf,'Location','best')

                    % show legend
                    % show spatial profiles from event image also with fits
                    % x  
                    evntX = eventFits.xProf.fit.x;
                    evntProfX = eventFits.xProf.fit.y;
                    evntProfX_m = eventFits.xProf.fit.y_m;
                    evntProfX_ups = eventFits.xProf.fit.x_ups;
                    evntProfXFit = eventFits.xProf.fit.yFit_ups;
                    line(hObjs.ax_xProf, evntX, evntProfX, ...
                        'Color','k', 'Marker','none', ...
                        'LineStyle','-', 'Display', 'data')
                    line(hObjs.ax_xProf, evntProfX_ups, evntProfXFit, ...
                        'Color','r', 'Marker','none', 'LineWidth',2, ...
                        'LineStyle','-', 'Display', 'eventFit')
                    line(hObjs.ax_xProf, evntX(evntProfX_m), ...
                        evntProfX(evntProfX_m), ...
                        'Color','b', 'Marker','.', 'MarkerSize',mrkSz, ...
                        'LineStyle','none', 'LineWidth',2, ...
                        'Display', 'eventMask')
                    line(hObjs.ax_xProf, ...
                        [eventFits.xProf.p.half_max_x_1 eventFits.xProf.p.half_max_x_2], ...
                        [eventFits.xProf.p.half_max_x eventFits.xProf.p.half_max_x], ...
                        'Color','m', 'LineStyle','-', ...
                        'LineWidth',2, 'Display', 'FWHMx')
                    set(hObjs.ax_xProf, 'FontSize',14, ...
                        'XLim',[min(evntX) max(evntX)], ...
                        'YLim',app.getAxisLimits(evntProfX, 5));
                    hObjs.ax_xProf.InnerPosition(1) = hObjs.ax_img.InnerPosition(1);
                    hObjs.ax_xProf.InnerPosition(3) = hObjs.ax_img.InnerPosition(3);
                    title(hObjs.ax_xProf, 'spatial x-profile', 'FontSize',18)
                    xlabel(hObjs.ax_xProf, 'x (\mum)', 'FontSize',16)
                    ylabel(hObjs.ax_xProf, 'fluorescence (F)', ...
                        'FontSize',16)
                    legend(hObjs.ax_xProf,'Location','best')
                    % y
                    evntY = eventFits.yProf.fit.x;
                    evntProfY = eventFits.yProf.fit.y;
                    evntProfY_m = eventFits.yProf.fit.y_m;
                    evntProfY_ups = eventFits.yProf.fit.x_ups;
                    evntProfYFit = eventFits.yProf.fit.yFit_ups;
                    line(hObjs.ax_yProf, evntProfY, evntY,  ...
                        'Color','k', 'Marker','none', ...
                        'LineStyle','-', 'Display', 'data')
                    line(hObjs.ax_yProf, evntProfYFit, evntProfY_ups, ...
                        'Color','r', 'Marker','none', 'LineWidth',2, ...
                        'LineStyle','-', 'Display', 'eventFit')
                    line(hObjs.ax_yProf, evntProfY(evntProfY_m), ...
                        evntY(evntProfY_m), ...
                        'Color','b', 'Marker','.', 'MarkerSize',mrkSz, ...
                        'LineStyle','none', 'LineWidth',2, ...
                        'Display', 'eventMask')
                    line(hObjs.ax_yProf, ...
                        [eventFits.yProf.p.half_max_x eventFits.yProf.p.half_max_x], ...
                        [eventFits.yProf.p.half_max_x_1 eventFits.yProf.p.half_max_x_2], ...
                        'Color','m', 'LineStyle','-', ...
                        'LineWidth',2, 'Display', 'FWHMy')
                    set(hObjs.ax_yProf, 'FontSize',14, ...
                        'XLim',app.getAxisLimits(evntProfY, 5), ...
                        'YLim',[min(evntY) max(evntY)]);
                    hObjs.ax_yProf.InnerPosition(2) = hObjs.ax_img.InnerPosition(2);
                    hObjs.ax_yProf.InnerPosition(4) = hObjs.ax_img.InnerPosition(4);
                    title(hObjs.ax_yProf, 'spatial y-profile', 'FontSize',18)
                    xlabel(hObjs.ax_yProf, 'fluorescence (F)', 'FontSize',16)
                    ylabel(hObjs.ax_yProf, 'y (\mum)', 'FontSize',16)
                    legend(hObjs.ax_yProf, 'Location','best')
            end
        end


        function setMaskOfProfileWithMouse(app, hO, E, refitFig)
            % check if are there any data in axes
            try
                % setMask of events
                % change pointer
                refitFig.Pointer = 'cross';
                % get current point in axes
                cp = hO.CurrentPoint;
                % start drawing line to select mask of event or baseline 
                % in profile 
                xinit = cp(1,1);
                yinit = cp(1,2);
                hl = line(hO, 'XData',xinit, 'YData',yinit,...
                    'color','g', 'LineWidth',5);
                % set callback functions in figure to control mouse
                % movement and mask selection process
                refitFig.WindowButtonMotionFcn = ...
                    {@app.mouseMotionFcn, hO, hl, xinit, yinit};
                refitFig.WindowButtonUpFcn = ...
                    {@app.mouseButtonUpFcn, hO, hl};
                % remove legend from axes
                delete(hO.Legend)
            catch
                return
            end
        end


        function mouseMotionFcn(app, refitFig, E, ha, hl, xinit, yinit)
            % update of line using to select mask of profile
            cp = ha.CurrentPoint;
            xdat = [xinit, cp(1,1)];
            ydat = [yinit, cp(1,2)];
            hl.XData = xdat;
            hl.YData = ydat;
            % set color of line, add = green, remove = red
            if xinit < cp(1,1)
                hl.Color = 'g';
            else
                hl.Color = 'r';
            end
            drawnow
        end


        function mouseButtonUpFcn(app, refitFig, E, ha, hl)
            % change back pointer and remove window button functions
            refitFig.Pointer = 'arrow';
            refitFig.WindowButtonMotionFcn = '';
            refitFig.WindowButtonUpFcn = '';
            hObjs_refitFig = getappdata(refitFig, 'hObjs');
            % get data
            % check in which axes the mask is updated
            switch ha.Tag
                case 'tProf'
                    % get new mask data from selection line
                    hl_x = hl.XData;
                    % get data from axes
                    profLineObj = findobj(ha, 'Type','line', ...
                        'DisplayName','data');
                    x = profLineObj.XData;
                    y = profLineObj.YData;
                    % check if updating baseline or event mask
                    switch hObjs_refitFig.h_pb_selfRatio.Text
                        case 'calculate self-ratio'
                            maskLineObj = findobj(ha, 'Type','line', ...
                                'DisplayName','baselineMask');
                        case 'normalized'
                            maskLineObj = findobj(ha, 'Type','line', ...
                                'DisplayName','eventMask');
                    end
                    m = ismember(x, maskLineObj.XData);    
                case 'xProf'
                    % get new mask data from selection line
                    hl_x = hl.XData;
                    % get data from axes
                    profLineObj = findobj(ha, 'Type','line', ...
                        'DisplayName','data');
                    maskLineObj = findobj(ha, 'Type','line', ...
                        'DisplayName','eventMask');
                    x = profLineObj.XData;
                    y = profLineObj.YData;
                    m = ismember(x, maskLineObj.XData);  
                case 'yProf'
                    % get new mask data from selection line
                    hl_x = hl.YData;
                    % get data from axes
                    profLineObj = findobj(ha, 'Type','line', ...
                        'DisplayName','data');
                    x = profLineObj.YData;
                    y = profLineObj.XData;
                    maskLineObj = findobj(ha, 'Type','line', ...
                        'DisplayName','eventMask');
                    m = ismember(x, maskLineObj.YData);
            end
            x = x(:);
            y = y(:);
            m = m(:);
            % stop if there is only point and no selection line
            if numel(hl_x)<2
                return
            end
            % position of start and end of selection line in points
            [~,s] = min(abs(x - hl_x(1)));
            [~,e] = min(abs(x - hl_x(2)));
            if s <= e
                % mark new area for event fitting
                m(s:e, 1) = true;
            else
                % remove marked area
                m(e:s, 1) = false;
            end
            % delete selection line
            delete(hl)
            % and update mask line
            switch ha.Tag
                case {'tProf', 'xProf'}
                    maskLineObj.XData = x(m);
                    maskLineObj.YData = y(m);
                case 'yProf'
                    maskLineObj.YData = x(m);
                    maskLineObj.XData = y(m);
            end
            % save clicked axes object
            hObjs_refitFig.lastAxes = ha;
            setappdata(refitFig, 'hObjs', hObjs_refitFig)
            % re-fit baseline or event
            app.refitSelectedEvent(ha, [], refitFig)
        end
        

        function bsFit = fitBaseline(app, x, y, m, fitFun)
            try
                switch fitFun
                    case 'rloess'
                        y(~m) = nan;
                        bsFit = smooth(x, y, 0.5, 'rloess');

                    case 'mean'
                        bsFit = ones(size(x)) .* mean(y(m));

                    otherwise
                        % fit
                        fo = fit(x(:), y(:), fitFun, ...
                            'Normalize','on', ...
                            'Exclude', ~m, ...
                            'Robust','bisquare');
                        bsFit = feval(fo, x(:));
                end
            catch ME
                try
                    bsFit = ones(size(x)) .* mean(y(m));
                catch
                    bsFit = ones(size(x)) .* mean(y(y<prctile(y,10,'all')));
                end
            end
        end
        

        function eventMaskManualCorrection(app, hO, E, refitFig)
            % update of mask of detected wave
            % get color of cell mask patch 
            maskColor = [0 1 0];
            hObjs = getappdata(refitFig, 'hObjs');
            % choose operation based on pushbutton text
            switch hO.Tag
                case 'start'
                    % get mask from axes 
                    mImgObj = findobj(hObjs.ax_img, 'Type', 'image', ...
                        'Tag', 'mask');
                    mImg = mImgObj.CData;
                    % delete previous cell mask
                    delete(mImgObj)
                    % delete previous detected wave front
                    delete(findobj(hObjs.ax_img, 'Type', 'images.roi.polyline', ...
                        'Tag', 'wavefront'))
                    % create temporal dummy axes 
                    % with the same dimensions as image axes
                    % only for selecting mask
                    ax_maskROI = copyobj(hObjs.ax_img, refitFig);
                    delete(ax_maskROI.Children)
                    colormap(ax_maskROI, [0 0 0; maskColor])
                    set(ax_maskROI, 'XTick',[], 'YTick',[], ...
                        'Color','none', 'Tag','tempROIaxes', ...
                        'XLabel', [], 'YLabel', [], 'CLim',[0 1], ...
                        'Title', [], ...
                        'InnerPosition',hObjs.ax_img.InnerPosition)
                    % disable zooming, moving by mouse in axes
                    disableDefaultInteractivity(ax_maskROI)
                    ax_maskROI.Toolbar.Visible = 'off';
                    % create temporal transparent mask, which will be
                    % updated/corrected
                    hImg_tempMaskCell = image(ax_maskROI, ...
                        'CData',mImg,...
                        'YData',[1 size(mImg,1)], ...
                        'XData',[1 size(mImg,2)],...
                        'CDataMapping','scaled', ...
                        'AlphaData',double(mImg).*0.3);

                    % start corrections
                    % create ROI used to correct cell mask
                    maskCorrectionROI = drawrectangle(ax_maskROI, ...
                        'Position',[1,1,5,5], ...
                        'InteractionsAllowed','translate', ...
                        'LineWidth', 2, 'Color',maskColor, ...
                        'Tag', 'addMask');
                    % create context menu
                    cm = uicontextmenu(refitFig);
                    m1 = uimenu(cm,'Text','add mask', ...
                        'MenuSelectedFcn', ...
                        {@app.setMaskCorrectionMode, refitFig});
                    m2 = uimenu(cm,'Text','remove mask', ...
                        'MenuSelectedFcn', ...
                        {@app.setMaskCorrectionMode, refitFig});
                    % assign it to mask correction ROI
                    maskCorrectionROI.ContextMenu = cm;

                    % add listener to save changes of boundary made by user
                    % 'MovingROI' ROIMoved
                    addlistener(maskCorrectionROI, ...
                        'MovingROI',@(src,evt)app.updateMask(src, evt, refitFig));
                    % save temp mask data
                    evntMaskCorr.ax_maskROI = ax_maskROI;
                    evntMaskCorr.correctedMask = mImg;
                    evntMaskCorr.hImg_tempMaskCell = hImg_tempMaskCell;
                    evntMaskCorr.maskCorrectionROI = maskCorrectionROI;
                    setappdata(refitFig, 'evntMaskCorr', evntMaskCorr)
                    % change pushbutton to: apply corrections
                    hO.Text  = 'apply corrections';
                    hO.FontColor = [1 0 0];
                    hO.Tag = 'apply';
                    % set up figure functions to control cell mask
                    % correction ROI
                    refitFig.WindowButtonMotionFcn = ...
                        {@app.positionAndSizeOfcellMaskROI, ax_maskROI};
                    refitFig.WindowScrollWheelFcn = ...
                        {@app.positionAndSizeOfcellMaskROI, ax_maskROI};
                    % check if there is a conflict between mouse control of
                    % cell mask selection and axes interactivity
                    % (some interactivity on some axes is turned on e.g. zooming, datatip...)
                    if isempty(refitFig.WindowScrollWheelFcn)
                        % throw an error dlg
                        uialert(refitFig, ...
                            {'Please turn off all interactivity in all axes!', ...
                            'e.g. zooming'}, ...
                            'TURN OFF AXES INREACTIVITY')
                        % put it in the start state, basically call the
                        % same callback again
                        app.eventMaskManualCorrection(hO, E, refitFig)
                    end

                case 'apply'
                    % extract only biggest object from corrected cell mask
                    evntMaskCorr = getappdata(refitFig, 'evntMaskCorr');
                    mImg = bwareafilt(evntMaskCorr.correctedMask, 1, 8);
                    % fill holes in mask
                    if hObjs.h_checkBox_mask_holes.Value
                        mImg = imfill(mImg, 'holes');
                    end
                    % change back settings, delete added things
                    hO.Text  = 'start event mask corrections';
                    hO.Tag = 'start';
                    hO.FontColor = [0 0 1];
                    % delete axes with cell mask correction roi and remove
                    % also data associated with it
                    delete(evntMaskCorr.ax_maskROI)
                    rmappdata(refitFig, 'evntMaskCorr');
                    % remove window finctions
                    refitFig.WindowButtonMotionFcn = [];
                    refitFig.WindowScrollWheelFcn = [];
                    % update mask of event
                    app.data.reanalyzedEvent.eventFits{1}.evntSP.pls_img_m = mImg;
                    % save flag that mask of event was manually corrected
                    app.data.reanalyzedEvent.eventFits{1}.evntSP.mask_corrected = true;
                    % save to table of detected events 
                    idx = app.data.reanalyzedEvent.eventID;
                    app.data.detectedEvents(...
                        app.data.detectedEvents.eventID==idx,:) = ...
                        app.data.reanalyzedEvent;
                    
                    % update event in re-analyze selected event window
                    app.showSelectedEventInReAnalyzeWindow(refitFig)
                    
            end
        end

        
        function [deskewedImg, mDeskewedImg, waveStartPx] = ...
                deskewCalciumWave(app, img, mImg, span)
            % span = span for spline knots % in ms
            t = (0:1:size(img,2)-1).*app.data.pxSzT;
            t = t(:);
            % get image based on selected mask
            waveImg = zeros(size(img));
            waveImg(mImg) = img(mImg);
            % find minimum distance of detected wave from beggining of image,
            % pixelwise, use 50% of peak of spline fit
            % rearrange image to deskew wave
            waveStartPx = zeros(size(waveImg,1),1);
            for i = 1:size(waveImg, 1)
                % get profiles
                % profile fit mask of detected event
                yE = waveImg(i, :);
                % event profile without mask
                y = img(i, :);
                % make sure y is positive, only for event start detection
                y = y + abs(min(y(:)));
                ind_y_noZero = yE~=0;
                %ind_y_noZero(find(ind_y_noZero, 1, 'last'):end) = true;
                % fit with spline
                % setup knots every k ms
                nK = ceil( (max(t)-min(t))/span );
                splOrd = 3; % spline order, poly3
                % first guess of spline coefficients
                spl0  = spap2(nK, splOrd, t, y);
                % newknt for a possibly better knot distribution
                knots = newknt(spl0);
                spl0  = spap2(knots, splOrd, t, y);
                yf = fnval(spmak(spl0.knots,spl0.coefs), t);
                yf(~ind_y_noZero) = 0;
                bs = mean( y(1:find(ind_y_noZero~=0, 1, 'first')) );
                % detect start of wave as 50 % of max amplitude
                [vMax,indMax] = max(yf);
                ampl = vMax - bs;
                [~,waveStartPx(i)] = min( abs( yf(1:indMax) - (0.5*ampl+bs) ) );
            end

            minDist = min(waveStartPx);
            deskewedImg = nan(size(waveImg));
            mDeskewedImg = nan(size(waveImg));
            for i = 1:size(waveImg, 1)
                d = waveStartPx(i) - minDist + 1;
                deskewedImg(i, :) = [img(i, d+1:end), img(i, 1:d)];
                mDeskewedImg(i, :) = ...
                    [mImg(i, d+1:end), mImg(i, 1:d)];
            end
            
        end
        
        function limits = getAxisLimits(app, data, offset)
            % data
            % offset in percentage
            data = data(:);
            dataRange = abs(max(data)-min(data)); 
            limits = [min(data)-dataRange*offset/100, ...
                max(data)+dataRange*offset/100];
        end
    end % end methods section


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function start_up_fcn(app)
            % load saved state            
            if exist(fullfile(fileparts(mfilename('fullpath')), ...
                        'previousAppState.mat'), 'file')
                app.data.previousAppState = load('previousAppState.mat');
                % set up path to last used conda environment
                app.data.condaEnvPath = ...
                    app.data.previousAppState.previousCondaEnvPath;
                app.data.defaultFiltersUsed = ...
                    app.data.previousAppState.filtersUsedDuringImgSeriesLoading;
                app.data.pxSz = app.data.previousAppState.previousPxSz;
                app.data.pxSzT = app.data.previousAppState.previousPxSzT;
                app.data.DLMclasses = app.data.previousAppState.DLM_dataClasses;
            else
                % set default parameters of filters during image series
                % loading
                % filters sequence
                filterNamesTbl = categorical({'median';'none';'none'}, ...
                    app.filterNames);
                param1_n = {'kernel_sz xy'; ''; '';};
                param1_val = [3; 0; 0];
                param2_n = {'kernel_sz t'; ''; ''};
                param2_val = [3; 0; 0];
                param3_n = {''; ''; ''};
                param3_val = [0; 0; 0];
                param4_n = {''; ''; ''};
                param4_val = [0; 0; 0];
                tdata_filters = table( ...
                    filterNamesTbl, ...
                    param1_n, param1_val, param2_n, param2_val, ...
                    param3_n, param3_val, param4_n, param4_val, ...
                    'VariableNames',{'filter', ...
                    'parameter #1','p#1 value', 'parameter #2','p#2 value', ...
                    'parameter #3','p#3 value', 'parameter #4','p#4 value'});
                % save default filters data
                app.data.defaultFiltersUsed = tdata_filters;
                % save default trained deep learnig model classes of events
                classesTbl = categorical( ...
                    {'background';'spark';'puff';'wave'; 'none'}, ...
                    app.classsesInDLM);
                intVals = [0; 1; 3; 2; nan];
                tdata_DLMclasses = table(classesTbl, intVals, ...
                    'VariableNames',{'class name', 'integer value'});
                app.data.DLMclasses = tdata_DLMclasses;
                % save default pixel sizes
                app.data.pxSz = 0.2; % µm
                app.data.pxSzT = 5; % ms
            end
            % set position of figure in the middle of the screen
            scSz = app.UIFigure.Parent.ScreenSize;
            x_pos_fig = round((scSz(3)-app.UIFigure.Position(3))/2);
            w_fig = app.UIFigure.Position(3);
            if x_pos_fig<1
                w_fig = app.UIFigure.Position(3)-(scSz(3)-app.UIFigure.Position(3));
                x_pos_fig = 1; 
            end
            y_pos_fig = round((scSz(4)-app.UIFigure.Position(4))/2);
            h_fig = app.UIFigure.Position(4);
            if y_pos_fig<1
                h_fig = app.UIFigure.Position(4)-(scSz(4)-app.UIFigure.Position(4));
                y_pos_fig = 1;
            end
            app.UIFigure.Position = [x_pos_fig y_pos_fig w_fig h_fig];
            
            % save original layout of components for image axes resizing
            % first tab
            app.data.orgCompPos.axImg = app.UIAxes_img.InnerPosition;
            % second tab
            app.data.orgCompPos.axImg_events = app.UIAxes_img_events.InnerPosition;
            
        end

        % Menu selected function: Menu_openImg
        function openImg(app, event)
            
            % check if there is path to previously loaded image
            if isfield(app.data.previousAppState, 'previousImgPath')
                prevImgPath = app.data.previousAppState.previousImgPath;     
            else
                prevImgPath = '';
            end
            % check if app is in batch mode
            if app.data.batchMode
                [p,n,e] = fileparts(app.data.imgPath);
                filePath = p;
                fileName = [n,e];
            else
                % open ui dialog to get a file to read
                % freeze uifigure
                dlg = uiprogressdlg(app.UIFigure, ...
                    'Title','Please load image series.', ...
                    'Indeterminate','on');
                % get path to image
                [fileName, filePath] = uigetfile( ...
                    {'*.tif'}, ...
                    'Load image', ...
                    prevImgPath, ...
                    'Multiselect','off');
                drawnow;
                figure(app.UIFigure);
                % unfreeze uifigure
                close(dlg)
            end
            % make sure that filename is cell type
            if ~iscell(fileName)
                fileName = {fileName};
            end
            % stop if no file was selected
            if fileName{1}==0
                return
            end
            
            % change tab
            app.TabGroup.SelectedTab = app.Tab_CaEventsDetection;
            % delete data from previous analysis
            app.data.detectedEvents = table();
            app.data.selectedEvent = [];
            % take pixel size values from settings or take default values, 
            % in case there is no way to read metadata
            app.data.t = [];
            app.data.imgDataAll = [];
            app.data.imgDataRaw = [];
            app.data.imgData = [];
            app.data.cellMask = [];
            app.data.crop_s_frame = [];
            app.data.crop_e_frame = [];
            app.data.crop_roi_xy_pos = [];
            % delete fields
            try
                fields = {'F0','img_blank', 'imgAnalysisData', ...
                    'eventsIDs','eventsInAllFrames', 'eventsIDs_types'};
                % try ismember and delete only those, which are there
                app.data = rmfield(app.data, ...
                    fields(ismember(fields, fieldnames(app.data))));
            catch
            end
            
            % show state
            app.stateLampLabel.Text = 'busy';
            app.Lamp_state.Color = [1 0 0];
            drawnow
            % save image path
            app.data.imgPath = fullfile(filePath, fileName{1});
            % read image data and meta data
            imgData = tiffreadVolume(app.data.imgPath);
            % size of image series
            img_height = size(imgData, 1);
            img_width = size(imgData, 2);
            N_imgs = size(imgData, 3);
            
            % get metadata, only if it is not in batch mode
            if app.data.batchMode
                imgMetadata = app.data.imgMetaDataInBatchMode;   
            elseif app.data.loadAnalyzedImgSeriesFlag
                % load previous analysis of selected image series
                % check if file exists
                [p,n,e] = fileparts(app.data.imgPath);
                if exist(fullfile(p, sprintf('%s_results.mat',n)), 'file')
                    imgSeriesAnalysis = ...
                        load(fullfile(p, sprintf('%s_results.mat',n)));
                    app.data.imgAnalysisData = imgSeriesAnalysis.imgAnalysisData;
                    imgMetadata.pxSzT = app.data.imgAnalysisData.pxSzT;
                    imgMetadata.pxSz = app.data.imgAnalysisData.pxSz;
                    imgMetadata.n_ch = app.data.imgAnalysisData.nCh;
                    % recalculate time in case image series was cropped 
                    if isempty(app.data.imgAnalysisData.crop_roi_pos)
                        imgMetadata.t = app.data.imgAnalysisData.t;
                    else
                        dt = imgMetadata.pxSzT;
                        t = (dt:dt:N_imgs*dt) - dt;
                        imgMetadata.t = t(:);
                    end
                else
                    uialert(app.UIFigure, ...
                        'Analysis file related to selected TIFF image series was not found.', ...
                        'Analysis file not found!', 'Icon','error')
                    app.data.loadAnalyzedImgSeriesFlag = false;
                    return
                end
            else
                % try to read metadata from selected/opened image
                allImgInfo = imfinfo(app.data.imgPath);
                if isfield(allImgInfo, 'ImageDescription')
                    % image description string, get the first not empty 
                    for i = 1:height(allImgInfo)
                        if ~isempty(allImgInfo(i).ImageDescription)
                            imgDescrStr = allImgInfo(i).ImageDescription;
                            break
                        end
                    end
                    imgMetadata = app.getImgMetadataFromImgDescrStr(...
                        imgDescrStr, N_imgs);
                end
            end
            % save metadata to app data
            app.data.t = imgMetadata.t;
            app.data.pxSzT = imgMetadata.pxSzT;
            app.data.pxSz = imgMetadata.pxSz;

            % fill metadata in table
            app.UITable_imgMeta.Data = {fullfile(filePath, fileName{1}), ...
                                imgMetadata.n_ch, ...
                                N_imgs, ...
                                img_width, ...
                                img_height, ...
                                app.data.pxSz, ...
                                app.data.pxSzT};
            
            % filter image data based on filters defined in settings
            % add progressbar
            h_dlg = uiprogressdlg(app.UIFigure, ...
                'Indeterminate','on', ...
                'Message', '', ...
                'Title', 'Filtering image series.');
            % get filters names and params
            filtersSeq = app.data.defaultFiltersUsed;
            filtersSeq(filtersSeq.filter=='none',:) = [];
            imgDataFilt = imgData;
            for idx = 1:height(filtersSeq)
                imgDataFilt = app.filterImgSeries(imgDataFilt, ...
                    filtersSeq(idx,:), h_dlg);
            end
            close(h_dlg)
            
            % show used filters in uitable filters applied to image series
            app.UITable_filters.Data = app.data.defaultFiltersUsed;
            app.data.filtersUsed = app.data.defaultFiltersUsed;

            % save data
            app.data.imgDataAll = imgData;
            app.data.imgData = imgDataFilt;
            app.data.imgDataRaw = imgData;
            % create cell mask, all true
            app.data.cellMask = true(size(imgData));

            % rescale axes and respective uicontrols 
            % based on size of image
            showImgAndRescaleAxes(app, event)
            
            % clear events tab content
            clearAppContent(app)

            showFilteredImgWithCellMask(app, event, true)
            
            % show state
            app.stateLampLabel.Text = 'ready';
            app.Lamp_state.Color = [0 1 0];
            drawnow
            
        end

        % Callback function: Slider_imgFrame, Slider_imgFrame, 
        % ...and 5 other components
        function showImgInSeriesSelectedWithSlider(app, event)
            % check if there are some data
            if isempty(app.data.imgData)
                return
            end
            
            % new current frame
            new_val = round(event.Source.Value);

            % check from which tab is it called
            if contains(event.Source.Tag, 'events')
                % set up time, slider and spinner
                app.EditField_imgTime_events.Value = app.data.t(new_val);
                app.Slider_imgFrame_events.Value = new_val;
                app.Spinner_imgFrame_events.Value = new_val;
                
                % show selected frame from image series with events
                showFilteredImgWithEventsBoundaries(app, event, false, false)

            else
                % set up time, slider and spinner
                app.EditField_imgTime.Value = app.data.t(new_val);
                app.Slider_imgFrame.Value = new_val;
                app.Spinner_imgFrame.Value = new_val;
            
                % show selected frame from image series 
                % with cell mask if calculated
                showFilteredImgWithCellMask(app, event, false)
            end
             
        end

        % Value changed function: DropDown_lookuptable, 
        % ...and 5 other components
        function changeColormap(app, event)
            % check if there are some data
            if isempty(app.data.imgData)
                return
            end
            % check from which tab is it called
            if contains(event.Source.Tag, 'events')
                edit_min_val = app.EditField_setglobalmin_events;
                edit_max_val = app.EditField_setglobalmax_events;
                ax = app.UIAxes_img_events;
                lookup_table = app.DropDown_lookuptable_events;

            else
                edit_min_val = app.EditField_setglobalmin;
                edit_max_val = app.EditField_setglobalmax;
                ax = app.UIAxes_img;
                lookup_table = app.DropDown_lookuptable;
            end

            % set caxis limits for image, if were changend
            if edit_min_val.Value >= edit_max_val.Value
                edit_min_val.Value = edit_max_val.Value - 1;
            end

            ax.CLim = [edit_min_val.Value edit_max_val.Value];

            % set new colormap
            colormap(ax, lookup_table.Value);

            % change color of patch representing cell mask
            cellMaskPatch_h = findobj(ax, ...
                'Type', 'Patch', '-and', 'Tag', 'cellMask');
            if ~isempty(cellMaskPatch_h)
                % select new color for patch
                patch_color = app.cellMaskPatchColor;
                cellMaskPatch_h.FaceColor = patch_color;
                cellMaskPatch_h.EdgeColor = patch_color;
            end
           
        end

        % Button pushed function: Button_detectevents
        function detectCalciumEvents(app, event)
            % show state
            app.stateLampLabel.Text = 'busy';
            app.Lamp_state.Color = [1 0 0];
            drawnow
            % get data
            imgData = double(app.data.imgData);
            n_imgs = size(imgData,3);
            % check if cell mask is calculated
            if ~isfield(app.data, 'cellMask')
                if app.data.batchMode
                    % set whole cellMask as true
                    app.data.cellMask = true(size(imgData));
                else
                    % % ask if continue without cell mask
                    choice = uiconfirm(app.UIFigure, ...
                        'Continue without cell mask?', ...
                        'No cell mask!', ...
                        'Options',{'Continue', 'Cancel'}, ...
                        'DefaultOption',2);
                    switch choice
                        case 'Continue'
                            app.data.cellMask = true(size(imgData));
                        case 'Cancel'
                            % show state
                            app.stateLampLabel.Text = 'ready';
                            app.Lamp_state.Color = [0 1 0];
                            drawnow
                            return
                    end
                end
            end
            % if in batch mode, save analysis data
            % save data of analysis
            if app.data.batchMode || app.CheckBox_saveDetection.Value
                [img_path_matFile,img_fn_matFile,~] = fileparts(app.data.imgPath);
                % data to save
                imgAnalysisData.imgAnalysisData_filePath = ...
                    fullfile(img_path_matFile, sprintf('%s_results.mat',img_fn_matFile));
                imgAnalysisData.imgSeriesPath = app.data.imgPath;
                imgAnalysisData.cellMask = app.data.cellMask;
                % blank
                imgAnalysisData.img_blank = app.data.img_blank;
                
                % add crop position
                try
                    imgAnalysisData.crop_s_frame = app.data.crop_s_frame;
                    imgAnalysisData.crop_e_frame = app.data.crop_e_frame;
                    imgAnalysisData.crop_roi_pos = app.data.crop_roi_xy_pos;
                catch
                    imgAnalysisData.crop_s_frame = [];
                    imgAnalysisData.crop_e_frame = [];
                    imgAnalysisData.crop_roi_pos = [];
                end
                % img meta data
                imgAnalysisData.pxSz = app.data.pxSz;
                imgAnalysisData.pxSzT = app.data.pxSzT;
                imgAnalysisData.t = app.data.t;
                imgAnalysisData.nCh = app.UITable_imgMeta.Data{2};
            end
            % detect calcium events with selected method
            progressBar_h = uiprogressdlg(app.UIFigure, ...
                'Title', 'Please Wait',...
                'Message', 'detecting calcium events (this might take several minutes)...', ...
                'Indeterminate', 'on');
            try
                switch app.DropDown_detectionMethod.Value
                    case 'U-Net'
                        [mask_sparks, mask_puffs, mask_waves] = ...
                            app.detectEvents_unet;
                        
                    case 'threshold'
                        [mask_sparks, mask_puffs, mask_waves] = ...
                            app.detectEvents_threshold;      
                end 
            catch
                % Close progressbar
                close(progressBar_h)
                return
            end
            % save events masks
            if app.data.batchMode || app.CheckBox_saveDetection.Value
                imgAnalysisData.maskOfDetected_sparks = mask_sparks;
                imgAnalysisData.maskOfDetected_puffs = mask_puffs;
                imgAnalysisData.maskOfDetected_waves = mask_waves;
            end
            % check if continue with analysis of detected events in batch
            % mode
            if app.data.batchMode && ...
                    strcmp(app.DropDown_typeOfAnalysisInBatch.Value, ...
                    'detect events')
                % save data of analysis
                imgAnalysisData.detectedEvents = [];
                % delete analysis file if exist
                if exist(imgAnalysisData.imgAnalysisData_filePath, 'file')
                    delete(imgAnalysisData.imgAnalysisData_filePath)
                end
                save(imgAnalysisData.imgAnalysisData_filePath, ...
                    'imgAnalysisData')
                % close progress bar
                close(progressBar_h)
                % show state
                app.stateLampLabel.Text = 'ready';
                app.Lamp_state.Color = [0 1 0];
                drawnow
                return
            end
            % filter detected events, do not take very small ones
            % min area of event 
            minEvntArea = 50;
            % get individual events in BW masks of frames
            detectedEvents = table('Size',[n_imgs,7], ...
                'VariableTypes', { 'uint32', 'cell', 'cell',...
                'cell', 'cell', 'cell', 'cell'}, ...
                'VariableNames', {'imgNum', ...
                'maskOfEvents_sparks', 'propsOfEvents_sparks', ...
                'maskOfEvents_puffs', 'propsOfEvents_puffs',...
                'maskOfEvents_waves', 'propsOfEvents_waves'});
            for i=1:n_imgs
                % process masks of sparks, puffs and waves
                % sparks
                CC = bwconncomp(imfill(mask_sparks(:,:,i), 8, 'holes'), 8);
                propsOfEvents = regionprops(CC, imgData(:,:,i), ...
                    'Area','MeanIntensity','WeightedCentroid','PixelIdxList');
                % filter out small events
                propsOfEvents = propsOfEvents([propsOfEvents.Area]>minEvntArea);
                detectedEvents.imgNum(i) = i;
                detectedEvents.maskOfEvents_sparks(i) = {mask_sparks(:,:,i)};
                detectedEvents.propsOfEvents_sparks(i) = {propsOfEvents};
                % puffs
                CC = bwconncomp(imfill(mask_puffs(:,:,i), 8, 'holes'), 8);
                propsOfEvents = regionprops(CC, imgData(:,:,i), ...
                    'Area','MeanIntensity','WeightedCentroid','PixelIdxList');
                % filter out small events
                propsOfEvents = propsOfEvents([propsOfEvents.Area]>minEvntArea);
                detectedEvents.imgNum(i) = i;
                detectedEvents.maskOfEvents_puffs(i) = {mask_puffs(:,:,i)};
                detectedEvents.propsOfEvents_puffs(i) = {propsOfEvents};
                % waves
                CC = bwconncomp(imfill(mask_waves(:,:,i), 8, 'holes'), 8);
                propsOfEvents = regionprops(CC, imgData(:,:,i), ...
                    'Area','MeanIntensity','WeightedCentroid','PixelIdxList');
                % filter out small events
                propsOfEvents = propsOfEvents([propsOfEvents.Area]>minEvntArea);
                detectedEvents.imgNum(i) = i;
                detectedEvents.maskOfEvents_waves(i) = {mask_waves(:,:,i)};
                detectedEvents.propsOfEvents_waves(i) = {propsOfEvents};
            end
            % allocate matrix for eventIDs
            % save mask of IDs of events
            app.data.eventsIDs = zeros(size(imgData) ,'uint32');
            % cell array with eventsIds and respective detected type of
            % events
            app.data.eventsIDs_types = {};
            % go through all image masks
            % set up progress bar
            progressBar_h.Message = ...
                {'Combining detected events in time dimension.', ...
                'Processing image #1'};
            progressBar_h.Indeterminate = 'off';
            for i = 1:n_imgs
                % update value of progress bar
                progressBar_h.Value = i/n_imgs;
                progressBar_h.Message = ...
                    {'Combining detected events in time dimension.', ...
                    sprintf('Processing image #%d',i)};
                % process detected sparks in frame,
                % update eventsIDs matrix and next used eventID number
                combineDetectedEventsInTime(app, ...
                    detectedEvents.propsOfEvents_sparks, ...
                    i, 'spark');
                % process detected puffs in frame,
                % update eventsIDs matrix and next used eventID number
                combineDetectedEventsInTime(app, ...
                    detectedEvents.propsOfEvents_puffs, ...
                    i, 'puff');
                % process detected waves in frame,
                % update eventsIDs matrix and next used eventID number
                combineDetectedEventsInTime(app, ...
                    detectedEvents.propsOfEvents_waves, ...
                    i, 'wave');
            end
            % update progressbar message
            progressBar_h.Message = ...
                {'Creating final table of detected events.', ...
                'Processing event #1'};
            progressBar_h.Indeterminate = 'off';
            
            % total number of events, take it from matrix, just in case
            % some events were merged
            eIDs = unique(app.data.eventsIDs(:));
            eIDs(eIDs==0) = [];
            % classes in analysis (background and none not included)
            % set in settings,
            evntDLMclasses = cellstr(app.data.DLMclasses{ ...
                ~ismember(app.data.DLMclasses.("class name"), ...
                categorical({'background'; 'none'})), 1});
            % if threshold method was used add class "undefined"
            if strcmp(app.DropDown_detectionMethod.Value, 'threshold')
                 evntDLMclasses = [evntDLMclasses; {'undefined'}];
            end
            evntDLMclasses = categorical(evntDLMclasses);
            % final table of events groupped
            finalTableOfEvents = table( ...
                zeros(numel(eIDs),1), zeros(numel(eIDs),1), zeros(numel(eIDs),1),...
                cell(numel(eIDs),1), zeros(numel(eIDs),1), ...
                repmat(evntDLMclasses(1), numel(eIDs), 1), ...
                zeros(numel(eIDs),1), zeros(numel(eIDs),1), zeros(numel(eIDs),1),...
                zeros(numel(eIDs),1), zeros(numel(eIDs),1), zeros(numel(eIDs),1), zeros(numel(eIDs),1),...
                zeros(numel(eIDs),1), zeros(numel(eIDs),1),...
                false(numel(eIDs),1), cell(numel(eIDs),1), ...
                'VariableNames', ...
                {'eventID','imgNum_s', 'imgNum_e', ...
                'boundary', 'color', 'eventType', ...
                'amplitude', 'TTP', 'FDHM', ...
                'tauRise', 'tauDecay', 'FWHMx', 'FWHMy', ...
                'waveSpeed', 'waveSpeedCentroids',...
                'accepted', 'eventFits'});
            % finalTableOfEvents = table( ...
            %     'Size', [numel(eIDs), 17], ...
            %     'VariableTypes', ...
            %     {'uint32', 'uint32', 'uint32', ...
            %     'cell', 'double', 'categorical', ...
            %     'double', 'double', 'double', ...
            %     'double', 'double', 'double', 'double', ...
            %     'double', 'double',...
            %     'logical', 'cell'}, ...
            %     'VariableNames', ...
            %     {'eventID','imgNum_s', 'imgNum_e', ...
            %     'boundary', 'color', 'eventType', ...
            %     'amplitude', 'TTP', 'FDHM', ...
            %     'tauRise', 'tauDecay', 'FWHMx', 'FWHMy', ...
            %     'waveSpeed', 'waveSpeedCentroids',...
            %     'accepted', 'eventFits'});
            for e = 1:numel(eIDs)
                % update value of progress bar
                progressBar_h.Value = e/numel(eIDs);
                progressBar_h.Message = ...
                    {'Creating final table of detected events.', ...
                    sprintf('Processing event #%d',e)};
         
                % get position of detected and merged events
                [I1,I2,I3] = ind2sub(size(app.data.eventsIDs), ...
                    find(app.data.eventsIDs == eIDs(e)));
                % event boundaries, one for each frame
                eventB = cell(max(I3)-min(I3)+1, 1);
                for f = min(I3):max(I3)
                    eventB(f-min(I3)+1) = bwboundaries( ...
                        app.data.eventsIDs(:,:,f)==eIDs(e), 8, 'noholes');
                end
                % mark as accepted only events with duration more than 2
                % frames
                if (max(I3)-min(I3)+1)>2
                    finalTableOfEvents.accepted(e) = true;
                else
                    % skip event 
                    continue
                    %finalTableOfEvents.accepted(e) = false;
                end
                % save data to table
                finalTableOfEvents.eventID(e) = eIDs(e);
                finalTableOfEvents.imgNum_s(e) = min(I3);
                finalTableOfEvents.imgNum_e(e) = max(I3);
                finalTableOfEvents.boundary(e) = {eventB};
                % find type of event
                idx_type_evnt = find([app.data.eventsIDs_types{:,1}] == eIDs(e));
                % add posibility in table to change class/type of event
                finalTableOfEvents.eventType(e) = evntDLMclasses(...
                    evntDLMclasses == ...
                    categorical(app.data.eventsIDs_types(idx_type_evnt,2)) );
                % fit event and get its parameters
                eventFits = app.getProfileAndParamsOfSelectedEvent(...
                    {eventB}, min(I3), max(I3), ...
                    app.data.eventsIDs_types(idx_type_evnt,2), ...
                    [], [], eIDs(e));
                % save fits and parameters of event in final table of
                % events, also supporting parameters of event, used to draw parameters
                % in profile
                if isempty(eventFits.evntSP)
                    finalTableOfEvents{e,[7:15]} = nan;
                else
                    finalTableOfEvents.eventFits(e) = {eventFits};
                    % parameters of event
                    finalTableOfEvents.amplitude(e) = ...
                        eventFits.evntProfs.tProf.p.amplitude;
                    finalTableOfEvents.TTP(e) = ...
                        eventFits.evntProfs.tProf.p.TTP;
                    finalTableOfEvents.FDHM(e) = ...
                        eventFits.evntProfs.tProf.p.FDHM;
                    finalTableOfEvents.tauRise(e) = ...
                        eventFits.evntProfs.tProf.fit.tauR;
                    finalTableOfEvents.tauDecay(e) = ...
                        eventFits.evntProfs.tProf.fit.tauD;
                    switch finalTableOfEvents.eventType(e)
                        case {'wave', 'transient'}
                            finalTableOfEvents.FWHMx(e) = nan;
                            finalTableOfEvents.FWHMy(e) = nan;
                        otherwise
                            finalTableOfEvents.FWHMx(e) = ...
                                eventFits.evntProfs.xProf.p.FWHM;
                            finalTableOfEvents.FWHMy(e) = ...
                                eventFits.evntProfs.yProf.p.FWHM;
                    end
                    finalTableOfEvents.waveSpeed(e) = ...
                        eventFits.evntSP.waveSpeed_pls;
                    finalTableOfEvents.waveSpeedCentroids(e) = ...
                        eventFits.evntSP.waveSpeed_centroids;
                end
            end
            
            % setup variable units in table
            finalTableOfEvents.Properties.VariableUnits = ...
                {'','','','','','', ...
                'deltaF/F0', 'ms', 'ms', 'ms', 'ms', ...
                'µm', 'µm', 'µm/s', 'µm/s', '', ''};
            
            % remove events with ID = zero
            finalTableOfEvents(...
                finalTableOfEvents.eventID==0,:)=[];
            % remove events with no fits
            finalTableOfEvents(...
                cellfun(@(x) isempty(x), finalTableOfEvents.eventFits),:)=[];
            
            % add color for individual ROIs
            finalTableOfEvents.color = hsv(height(finalTableOfEvents));
            
            % save table
            app.data.detectedEvents = finalTableOfEvents;
       
            % Close progressbar
            close(progressBar_h)
            
            % save anlysis data to app.data
            app.data.imgAnalysisData = imgAnalysisData;

            % if in batch mode do not show detected events, just save table
            % of detected events
            if app.data.batchMode
                % save analysis data
                save(imgAnalysisData.imgAnalysisData_filePath, ...
                        'imgAnalysisData')
                return
            end
            
            % rescale axes and respective uicontrols
            % based on size of image
            showImgAndRescaleAxes(app, event)
            % show detected events with image
            showFilteredImgWithEventsBoundaries(app, event, false, true)
            
            % show state
            app.stateLampLabel.Text = 'ready';
            app.Lamp_state.Color = [0 1 0];
            drawnow

        end

        % Button pushed function: Button_play, Button_play_events
        function playWholeImgSeries(app, event)
            % data
            imgData = app.data.imgData;
            % check if there are some data
            if isempty(imgData)
                return
            end
            
            % profile on
            % check from which tab it is called and select appropriate
            % elements
            switch event.Source.Tag
                case 'play_imgSeries'
                    ax = app.UIAxes_img;
                    edit_frameRate = app.EditField_framesPerSec;
                    spinner = app.Spinner_imgFrame;
                    stopButton = app.Button_stop;
                    playInLoop = app.CheckBox_playInLoop;
                    edit_imgTime = app.EditField_imgTime;
                    slider_imgFrame = app.Slider_imgFrame;
                    whereToShow = 'imgSeries';
                    
                case 'play_imgSeries_events'
                    ax = app.UIAxes_img_events;
                    edit_frameRate = app.EditField_framesPerSec_events;
                    spinner = app.Spinner_imgFrame_events;
                    stopButton = app.Button_stop_events;
                    playInLoop = app.CheckBox_playInLoop_events;
                    edit_imgTime = app.EditField_imgTime_events;
                    slider_imgFrame = app.Slider_imgFrame_events;
                    whereToShow = 'imgSeries_events';
            end
                  
            % get handle to image shown in axes
            img_series_h = findobj(ax, 'Type', 'Image');
            % get speed of playing
            frames_s = edit_frameRate.Value;
            if frames_s > 50
                frames_s = 50;
                edit_frameRate.Value = frames_s;
            end
            
            % get current position of slider or spinner
            curr_frame = spinner.Value;
            if curr_frame==size(imgData,3)
                curr_frame = 1;
            end
            % set stop button
            stopButton.UserData.cliked = false;
            % remove previous cell mask patch object
            delete( findobj(ax, 'Type','Patch', 'Tag','cellMask') )
         
            % patch color
            patchColor = app.cellMaskPatchColor;
            % calculate cell boundary for each frame
            cellBoundary = cell(size(app.data.cellMask,3),1);
            for f=1:size(app.data.cellMask, 3)
                cellBoundary(f) = ...
                    bwboundaries(app.data.cellMask(:,:,f), 8, 'noholes');
            end

            % choose what to show
            switch whereToShow
                case 'imgSeries'
                    % check if to show cell mask
                    if app.CheckBox_cellMask_show.Value
                        
                        % show cell mask
                        showCellMask = true;
                        cell_boundary_h = patch( ...
                            'Faces',(1:numel(cellBoundary{curr_frame}(:,2))), ...
                            'Vertices',[cellBoundary{curr_frame}(:,2), ...
                            cellBoundary{curr_frame}(:,1)], ...
                            'FaceColor',patchColor, 'FaceAlpha',0.2, ...
                            'EdgeColor',patchColor, 'EdgeAlpha',1, ...
                            'Parent', ax, ...
                            'LineWidth',1, 'Tag', 'cellMask');
                    else
                        showCellMask = false;
                    end

                case 'imgSeries_events'
                    % check if to show cell mask in image with detected
                    % events
                    if app.CheckBox_showCellMask_events.Value
                        showCellMask = true;
                        cell_boundary_h = patch( ...
                            'Faces',(1:numel(cellBoundary{curr_frame}(:,2))), ...
                            'Vertices',[cellBoundary{curr_frame}(:,2), ...
                            cellBoundary{curr_frame}(:,1)], ...
                            'FaceColor',patchColor, 'FaceAlpha',0, ...
                            'EdgeColor',patchColor, 'EdgeAlpha',1, ...
                            'Parent', ax, ...
                            'LineWidth',1, 'Tag', 'cellMask');
                    else
                        showCellMask = false;
                    end
                    
                    % check if to show detected events
                    if ~isempty(app.data.detectedEvents) && ...
                            app.CheckBox_showevents_events.Value
                        % keyboard
                        % get boundaries and colors of evens in current frame
                        showEvents = true;
                        eventsInAllFrames = app.data.eventsInAllFrames;
                       
                        % check if to show mask
                        if app.CheckBox_showmask_events.Value
                            showMaskOfEvents = true;
                        else
                            showMaskOfEvents = false;
                        end
                        % and boundary
                        if app.CheckBox_showBB_events.Value
                            showBBofEvents = true;
                        else
                            showBBofEvents = false;
                        end     
                    else
                        showEvents = false;
                    end
            end
            
            switch event.Source.Text
                case 'play'
                    if playInLoop.Value
                        i=curr_frame;
                        while ~stopButton.UserData.cliked
                            % once finish circle, set i=1
                            if i > size(imgData,3)
                                i = 1;
                            end
                            edit_imgTime.Value = app.data.t(i);
                            slider_imgFrame.Value = i;
                            spinner.Value = i;

                            % image
                            img_series_h.CData = imgData(:,:,i);
                            % check if to show cell mask and events
                            switch whereToShow
                                case 'imgSeries'
                                    % cell mask
                                    if showCellMask
                                        cell_boundary_h.Faces = ...
                                            (1:numel(cellBoundary{i}(:,2)));
                                        cell_boundary_h.Vertices = ...
                                            [cellBoundary{i}(:,2), ...
                                            cellBoundary{i}(:,1)];
                                    end
                                
                                case 'imgSeries_events'
                                    % cell mask
                                    if showCellMask
                                        cell_boundary_h.Faces = ...
                                            (1:numel(cellBoundary{i}(:,2)));
                                        cell_boundary_h.Vertices = ...
                                            [cellBoundary{i}(:,2), ...
                                            cellBoundary{i}(:,1)];
                                    end
                                    % events visualisation
                                    % set events from previous frame to invisible
                                 
                                    if i>1
                                        set(eventsInAllFrames{i-1}, 'Visible', 'off')
                                        set(eventsInAllFrames{i-1}, 'FaceAlpha', 0)
                                        set(eventsInAllFrames{i-1}, 'EdgeAlpha', 0)
                                    else
                                        set(eventsInAllFrames{end}, 'Visible', 'off')
                                        set(eventsInAllFrames{end}, 'FaceAlpha', 0)
                                        set(eventsInAllFrames{end}, 'EdgeAlpha', 0)
                                    end

                                    % show only the ones in frame of interest
                                    % check if to show all or only selected event
                                    if app.CheckBox_showSelectedEvent.Value
                                        m_selectedEvents = arrayfun( @(x) ...
                                            strcmp(x.Tag, num2str(app.data.selectedEvent.eventID)), ...
                                            eventsInAllFrames{i} );
                                    else
                                        m_selectedEvents = true(numel(eventsInAllFrames{i}),1);
                                    end
                                    % show only accepted events
                                    m_acceptedEvents = true(numel(eventsInAllFrames{i}),1);
                                    for e = 1:numel(eventsInAllFrames{i})
                                        m_acceptedEvents(e) = ...
                                            app.UITable_events.Data.accepted( ...
                                            str2double(eventsInAllFrames{i}(e).Tag)==...
                                            app.UITable_events.Data.eventID );
                                    end
                                    m_selectedEvents = m_selectedEvents & m_acceptedEvents;

                                    % show only the ones in frame of
                                    % interest
                                    if showEvents
                                        set(eventsInAllFrames{i}(m_selectedEvents), 'Visible','on')
                                    else
                                        set(eventsInAllFrames{i}(m_selectedEvents), 'Visible','off')
                                    end
                                    
                                    if showMaskOfEvents
                                        set(eventsInAllFrames{i}(m_selectedEvents), 'FaceAlpha',0.2)
                                    else
                                        set(eventsInAllFrames{i}(m_selectedEvents), 'FaceAlpha',0)
                                    end
                                    
                                    if showBBofEvents
                                        set(eventsInAllFrames{i}(m_selectedEvents), 'EdgeAlpha',1)
                                    else
                                        set(eventsInAllFrames{i}(m_selectedEvents), 'EdgeAlpha',0)
                                    end
                                    
                            end
                            
                            i = i+1;
                            %drawnow
                            pause(1/frames_s)
                        end
                        
                    else
                        for i=curr_frame:size(imgData,3)
                            %tic
                            edit_imgTime.Value = app.data.t(i);
                            slider_imgFrame.Value = i;
                            spinner.Value = i;
                            
                            % show image
                            img_series_h.CData = imgData(:,:,i);
                            % check if to show cell mask and events
                            switch whereToShow
                                case 'imgSeries'
                                    % cell mask
                                    if showCellMask
                                        cell_boundary_h.Faces = ...
                                            (1:numel(cellBoundary{i}(:,2)));
                                        cell_boundary_h.Vertices = ...
                                            [cellBoundary{i}(:,2), ...
                                            cellBoundary{i}(:,1)];
                                    end
                                case 'imgSeries_events'
                                    % cell mask
                                    if showCellMask
                                        cell_boundary_h.Faces = ...
                                            (1:numel(cellBoundary{i}(:,2)));
                                        cell_boundary_h.Vertices = ...
                                            [cellBoundary{i}(:,2), ...
                                            cellBoundary{i}(:,1)];
                                    end
                                    % events visualisation
                                    % set events from previous frame to invisible
                                    if i>1
                                        set(eventsInAllFrames{i-1}, 'Visible', 'off')
                                        set(eventsInAllFrames{i-1}, 'FaceAlpha', 0)
                                        set(eventsInAllFrames{i-1}, 'EdgeAlpha', 0)
                                    else
                                        % set events in last frame as invisible, in
                                        % case there are still visible from previous
                                        % runs
                                        set(eventsInAllFrames{end}, 'Visible', 'off')
                                        set(eventsInAllFrames{end}, 'FaceAlpha', 0)
                                        set(eventsInAllFrames{end}, 'EdgeAlpha', 0)
                                    end
                              
                                    % show only the ones in frame of interest
                                    % check if to show all or only selected event
                                    if app.CheckBox_showSelectedEvent.Value
                                        m_selectedEvents = arrayfun( @(x) ...
                                            strcmp(x.Tag, num2str(app.data.selectedEvent.eventID)), ...
                                            eventsInAllFrames{i} );
                                    else
                                        m_selectedEvents = true(numel(eventsInAllFrames{i}),1);
                                    end
                                    % show only accepted events
                                    m_acceptedEvents = true(numel(eventsInAllFrames{i}),1);
                                    for e = 1:numel(eventsInAllFrames{i})
                                        m_acceptedEvents(e) = ...
                                            app.UITable_events.Data.accepted( ...
                                            str2double(eventsInAllFrames{i}(e).Tag)==...
                                            app.UITable_events.Data.eventID );
                                    end
                                    m_selectedEvents = m_selectedEvents & m_acceptedEvents;
                                    
                                    
                                    % show only the ones in frame of
                                    % interest
                                    if showEvents
                                        set(eventsInAllFrames{i}(m_selectedEvents), 'Visible', 'on')
                                    else
                                        set(eventsInAllFrames{i}(m_selectedEvents), 'Visible', 'off')
                                    end
                                    
                                    if showMaskOfEvents
                                        set(eventsInAllFrames{i}(m_selectedEvents), 'FaceAlpha', 0.25)
                                    else
                                        set(eventsInAllFrames{i}(m_selectedEvents), 'FaceAlpha', 0)
                                    end
                                    
                                    if showBBofEvents
                                        set(eventsInAllFrames{i}(m_selectedEvents), 'EdgeAlpha', 1)
                                    else
                                        set(eventsInAllFrames{i}(m_selectedEvents), 'EdgeAlpha', 0)
                                    end
                                    
                            end

                            % drawnow
                             pause(1/frames_s)
                            %T(i)= toc;
                            % stop playing
                            if stopButton.UserData.cliked
                                break
                            end
                        end
                    end
            end
            
            
 %            profile viewer  % view the timing report
%             profile off     % to stop the timer 

        end

        % Button pushed function: Button_stop, Button_stop_events
        function stopPlayingImgSeries(app, event)
            event.Source.UserData.cliked = true;
        end

        % Button pushed function: Button_crop
        function cropImgSeries(app, event)
            % get data
            imgData = app.data.imgData;
            
            % check if there are some data
            if isempty(imgData)
                return
            end
          
            switch event.Source.Text

                case 'select xy area to crop'
                    % delete previous crop roi                   
                    delete( findobj(app.UIAxes_img, ...
                        'Type', 'images.roi.rectangle', ...
                        'Tag', 'cropROIxy') )
                    
                    % suggest size of cropping rectangle
                    % get 25th percentile from max xy image - estimation of background
                    if ~isempty(imgData)
                        imgData_max = mean(imgData,3);
                  
                        bg_est = double(prctile(imgData_max(:), 25));
                        mImg = imgData_max - bg_est*1.05;
                        mImg(mImg>0) = 1;
                        mImg(mImg<=0) = 0;
                        try
                            % find biggest rectangle, which is in image area
                            img_CC_event = bwconncomp(logical(mImg),8);

                            %calculate properties of all CC (regions)
                            statEvents = regionprops(img_CC_event,imgData_max, ...
                                'SubarrayIdx', 'Area','BoundingBox');
                            [~,indMaxArea] = max([statEvents.Area]);
                            suggested_pos = statEvents(indMaxArea).BoundingBox;

                            % create crop ROI
                            crop_roi_xy = drawrectangle( ...
                                app.UIAxes_img, 'Color',[1 0 0],...
                                'Position', suggested_pos, ...
                                'Tag', 'cropROIxy');

                        catch
                            % create crop ROI
                            crop_roi_xy = drawrectangle( ...
                                app.UIAxes_img, 'Color',[1 0 0],...
                                'Position', [10, 10, size(imgData,2)-20, size(imgData,1)-20], ...
                                'Tag', 'cropROIxy');
                        end
                    else
                            % create crop ROI
                            crop_roi_xy = drawrectangle( ...
                                app.UIAxes_img, 'Color',[1 0 0],...
                                'Position', [10, 10, size(imgData,2)-20, size(imgData,1)-20], ...
                                'Tag', 'cropROIxy');
                    end
                    
                    % set up name of pushbutton
                    app.Button_crop.Text = 'crop';
                    app.Button_crop.FontWeight = 'bold';
                    
                    
                case 'crop'
                    % get start and end frames
                    if app.data.loadAnalyzedImgSeriesFlag
                        % get position from saved image analysis data
                        s_frame = app.data.imgAnalysisData.crop_s_frame;
                        e_frame = app.data.imgAnalysisData.crop_e_frame;
                        ROI_pos = app.data.imgAnalysisData.crop_roi_pos;
                    else
                        % get position from ROI
                        s_frame = round(app.EditField_cropStartFrame.Value);
                        e_frame = round(app.EditField_cropEndFrame.Value);
                        if s_frame < 1
                            s_frame = 1;
                        end
                        if e_frame > size(app.data.imgData,3)
                            e_frame = size(app.data.imgData,3);
                        end

                        % get position if xy roi
                        crop_roi_xy = findobj(app.UIAxes_img, ...
                            'Type', 'images.roi.rectangle', ...
                            'Tag', 'cropROIxy');
                        ROI_pos = round(crop_roi_xy.Position);
                        ROI_pos(ROI_pos<1) = 1;
                        if ROI_pos(1)+ROI_pos(3) > size(app.data.imgData,2)
                            if ROI_pos(1)==1
                                ROI_pos(3) = size(app.data.imgData,2)-1;
                            else
                                ROI_pos(3) = ROI_pos(3) - abs(size(app.data.imgData,2)-ROI_pos(3));
                            end
                        end
                        if ROI_pos(2)+ROI_pos(4) > size(app.data.imgData,1)
                            if ROI_pos(2)==1
                                ROI_pos(4) = size(app.data.imgData,1)-1;
                            else
                                ROI_pos(4) = ROI_pos(4) - abs(size(app.data.imgData,1)-ROI_pos(4));
                            end
                        end
                        % delete crop ROI
                        delete(crop_roi_xy)
                    end

                    % crop data
                    app.data.imgData = app.data.imgData( ...
                        ROI_pos(2):ROI_pos(2)+ROI_pos(4), ...
                        ROI_pos(1):ROI_pos(1)+ROI_pos(3), ...
                        s_frame:e_frame);
                    app.data.imgDataRaw = app.data.imgDataRaw( ...
                        ROI_pos(2):ROI_pos(2)+ROI_pos(4), ...
                        ROI_pos(1):ROI_pos(1)+ROI_pos(3), ...
                        s_frame:e_frame);
                    app.data.cellMask = app.data.cellMask( ...
                        ROI_pos(2):ROI_pos(2)+ROI_pos(4), ...
                        ROI_pos(1):ROI_pos(1)+ROI_pos(3), ...
                        s_frame:e_frame);
                    
                    % recalculate new t axis
                    app.data.t = app.data.t(s_frame:e_frame);
                    % save where it was cropped
                    if ~isempty(app.data.crop_s_frame)
                        app.data.crop_s_frame = app.data.crop_s_frame + s_frame;
                        app.data.crop_e_frame = app.data.crop_s_frame + (e_frame-s_frame);
                        app.data.crop_roi_xy_pos = [...
                            app.data.crop_roi_xy_pos(1)+ROI_pos(1), ...
                            app.data.crop_roi_xy_pos(2)+ROI_pos(2), ...
                            ROI_pos(3), ROI_pos(4) ];
                    else
                        app.data.crop_s_frame = s_frame;
                        app.data.crop_e_frame = e_frame;
                        app.data.crop_roi_xy_pos = ROI_pos;
                    end

                    % show cropped image series
                    showImgAndRescaleAxes(app, event)
                    showFilteredImgWithCellMask(app, event, true)

                    % set up pushbutton for cropping
                    event.Source.Text = 'select xy area to crop';
                    event.Source.FontWeight = 'normal';

            end         
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            %             % ask if to close
            %             selection = uiconfirm(app.UIFigure, ...
            %                 'Close app?', ...
            %                 'Confirm app close...',...
            %                 'Options',{'Yes','No'},...
            %                 'DefaultOption',2);
            %             switch selection
            %                 case 'Yes'
            % save state
            % get path of app
            appFile = mfilename('fullpath');
            [baseDir,~,~] = fileparts(appFile);
            % prepare sturucture to save

            previousAppState = struct( ...
                'previousImgPath', app.data.imgPath, ...
                'previousCondaEnvPath', app.data.condaEnvPath, ...
                'previousPxSz', app.data.pxSz, ...
                'previousPxSzT', app.data.pxSzT, ...
                'filtersUsedDuringImgSeriesLoading', ...
                app.data.defaultFiltersUsed, ...
                'DLM_dataClasses', app.data.DLMclasses);
            % save file
            save(fullfile(baseDir,'previousAppState.mat'), ...
                '-struct', 'previousAppState')


            %                     % close app
            %                     if isdeployed
            %                         quit
            %                     else
            %                         delete(app.UI)
            %                     end
            %                 case 'no'
            %                     return
            %             end
            delete(app.UIFigure)

        end

        % Button pushed function: Button_cellMask
        function findCellMask(app, event)

            % check if there are any data
            if isempty(app.data.imgData)
                return
            end
            % show state
            app.stateLampLabel.Text = 'busy';
            app.Lamp_state.Color = [1 0 0];
            drawnow
            % get kernel size used in filtering of image series during finding cell
            % mask procedure
            kernel_sz = app.EditField_kernelSz_cellMask.Value;
            % check kernel size, has to be odd and min 3
            try
                validateattributes(kernel_sz,'numeric',{"integer",'odd','>=',3})
            catch ME
                uialert(app.UIFigure, ME.message, ...
                    'Wrong kernel size input!', 'Icon','error')
                % show state
                app.stateLampLabel.Text = 'ready';
                app.Lamp_state.Color = [0 1 0];
                drawnow
                return
            end

            % estimate call mask on filtered data          
            % pre-allocate
            cellMask = false(size(app.data.imgData));
            % check if calculate one cell mask for all frames in image series
            % or for each image frame individualy
            if app.CheckBox_cellMaskForEachFrame.Value
                N_edges = size(app.data.imgData, 3);
            else
                N_edges = 1;
            end
            % find cell mask in whole series
            for i=1:N_edges
                % get single image frame
                if app.CheckBox_cellMaskForEachFrame.Value
                    img = app.data.imgData(:,:,i);
                else
                    img = prctile(app.data.imgData, 99, 3);
                end

                % filter image
                imgFilt = medfilt2(img, [kernel_sz kernel_sz], 'symmetric');
                imgFilt = wiener2(imgFilt, [kernel_sz kernel_sz]);
                H = fspecial('average',[kernel_sz kernel_sz]);
                imgFilt = imfilter(imgFilt, H, 'symmetric');
                imgFilt = imfilter(imgFilt, H, 'symmetric');
                imgFilt = imfilter(imgFilt, H, 'symmetric');

                imgFilt = imcomplement(imgFilt);
                imgFilt = ordfilt2(imgFilt, ...
                    kernel_sz^2, true(kernel_sz), 'symmetric');
                imgFilt = imcomplement(imgFilt);
                % Otsu%s method threshold
                % first normalize to range [0, 1]
                imgFilt = im2double(imgFilt);

                mCell = imbinarize(imgFilt ./ max(imgFilt, [], 'all'), ...
                    'global');
                % mCell = imbinarize(imgFilt, 'adaptive', 'Sensitivity',0.5);

                temp_filt_img = imgaussfilt(imgFilt,3);
                imgFilt(mCell) = min(temp_filt_img(mCell));

                % Otsu%s method threshold
                imgFilt = imgFilt ./ max(imgFilt(:));
                mCell = imbinarize(imgFilt,'global');
                % fill holes if any
                mCell = imfill(mCell,'holes');

                % try also to look for edges and use threshold
                cell_thrs = graythresh( ...
                    imgFilt(imgFilt > prctile(imgFilt, 1, 'all') & ...
                    imgFilt < prctile(imgFilt, 99, 'all') ) );
                cellImg_bw_thrs = imfill( ...
                    imbinarize(imgFilt, cell_thrs), 'holes');
                %cellFrame_bw_thrs = bwareafilt(cellFrame_bw, 1);
                % check for edges to get cell mask
                [~,thrs] = edge(imgFilt, 'sobel');
                BWs1 = edge(imgFilt, 'sobel', thrs * 0.5);
                BWs2 = edge(imgFilt, 'canny');
                se90 = strel('line', 3, 90);
                se0 = strel('line', 3, 0);
                BWsdil1 = imdilate(BWs1, [se90 se0]);
                BWsdil2 = imdilate(BWs2, [se90 se0]);
                mCell_e = BWsdil1 | BWsdil2 | cellImg_bw_thrs;
                %mCell_e = imfill(mCell_e, 'holes');
                mCell_e = imerode(mCell_e, strel('disk', 3));
                mCell_e = imerode(mCell_e, strel('line', 3, 0));
                mCell_e = imfill(mCell_e, 'holes');
                % take only the biggest object
                cellMask(:,:,i) = bwareafilt(mCell | mCell_e, 1, 8);
            end

            % save data
            % replicate cell masks and boundaries to have it as many as
            % number of frames
            if ~app.CheckBox_cellMaskForEachFrame.Value
                cellMask = repmat(cellMask(:,:,1), 1, 1, ...
                    size(app.data.imgData,3));
            end
            app.data.cellMask = cellMask;
            
            % calculate blank
            app.data.img_blank = mean(app.data.imgData(~cellMask), 'all');

            % show selected frame from image series with cell mask if calculated
            showFilteredImgWithCellMask(app, event, false)
            
            % show state
            app.stateLampLabel.Text = 'ready';
            app.Lamp_state.Color = [0 1 0];
            drawnow
            
        end

        % Button pushed function: Button_cellMask_manualCorrection
        function cellMask_manualCorrection(app, event)
            % cell mask has to be calculated
            if ~isfield(app.data, 'cellMask')
                uialert(app.UIFigure, ...
                    'Please first use an automatic procedure to find cell mask!', ...
                    'cell mask detection')
                return
            else
                if isempty(app.data.cellMask)
                    uialert(app.UIFigure, ...
                        'Please first use an automatic procedure to find cell mask!', ...
                        'cell mask detection')
                    return
                end
            end
            % get color of cell mask patch 
            cellMaskColor = app.cellMaskPatchColor;
            % choose operation based on pushbutton text
            pb = event.Source;
            switch pb.Tag
                case 'start corrections'
                    % fix value of checkbox, whether to aplly corrections 
                    % to all cell masks in all frames or only in selected one
                    app.CheckBox_cellMask_manualCorrection_toAll.Enable = 'off';
                    % check if mask correction applies to all frames/mask or only
                    % to selected one
                    if app.CheckBox_cellMask_manualCorrection_toAll.Value
                        % correct cell mask in maximum or average intensity projection image
                        % imgData_proj = max(imgData, [], 3);
                        % imgData_proj = mean(imgData, 3);
                        imgData_proj = prctile(app.data.imgData, 99, 3);
                        % show max intensity projection
                        img_series_h = findobj(app.UIAxes_img, 'Type', 'Image');
                        img_series_h.CData = imgData_proj;

                        % set up caxis as 1-99 percentiles
                        img_caxis_lims = [min(imgData_proj(:)), max(imgData_proj(:))];
                        app.UIAxes_img.CLim = [img_caxis_lims(1) img_caxis_lims(2)];
                        app.UIAxes_img.Title.String = 'average intensity projection';

                        app.EditField_setglobalmin.Value = double(img_caxis_lims(1));
                        app.EditField_setglobalmax.Value = double(img_caxis_lims(2));
                    end

                    % delete previous cell mask
                    delete(findobj(app.UIAxes_img, 'Type', 'patch', ...
                        'Tag', 'cellMask'))
                    % create temporal dummy axes with the same dimensions as image axes
                    % only for selecting mask
                    ax_maskROI = copyobj(app.UIAxes_img, app.UIAxes_img.Parent);
                    delete(ax_maskROI.Children)
                    colormap(ax_maskROI, [0 0 0; cellMaskColor])
                    set(ax_maskROI, 'XTick',[], 'YTick',[], ...
                        'Color','none', 'Tag','tempROIaxes', ...
                        'XLabel', [], 'YLabel', [], 'CLim',[0 1], ...
                        'Title', [])
                    % disable zooming, moving by mouse in axes
                    disableDefaultInteractivity(ax_maskROI)
                    ax_maskROI.Toolbar.Visible = 'off';
                    % create temporal transparent cell mask, which will be
                    % updated/corrected
                    correctedMask = ...
                        app.data.cellMask(:,:,app.Spinner_imgFrame.Value);
                    correctedMask_alpha = double(correctedMask).*0.25;

                    hImg_tempMaskCell = image(ax_maskROI, ...
                        'CData',correctedMask,...
                        'YData',[1 size(correctedMask,1)], ...
                        'XData',[1 size(correctedMask,2)],...
                        'CDataMapping','scaled', ...
                        'AlphaData',correctedMask_alpha);

                    % start corrections
                    % create ROI used to correct cell mask
                    maskCorrectionROI = drawrectangle(ax_maskROI, ...
                        'Position',[1,1,5,5], ...
                        'InteractionsAllowed','translate', ...
                        'LineWidth', 2, 'Color',cellMaskColor, ...
                        'Tag', 'addMask');

                    % create context menu
                    cm = uicontextmenu(app.UIFigure);
                    m1 = uimenu(cm,'Text','add mask', ...
                        'MenuSelectedFcn', ...
                        {@app.setMaskCorrectionMode, app.UIFigure});
                    m2 = uimenu(cm,'Text','remove mask', ...
                        'MenuSelectedFcn', ...
                        {@app.setMaskCorrectionMode, app.UIFigure});
                    % assign it to mask correction ROI
                    maskCorrectionROI.ContextMenu = cm;

                    % add listener to save changes of boundary made by user
                    % 'MovingROI' ROIMoved
                    addlistener(maskCorrectionROI, ...
                        'MovingROI',@(src,evt)app.updateMask(src, evt, app.UIFigure));
                    % save temp mask data
                    cellMaskCorr.ax_maskROI = ax_maskROI;
                    cellMaskCorr.correctedMask = correctedMask;
                    cellMaskCorr.hImg_tempMaskCell = hImg_tempMaskCell;
                    cellMaskCorr.maskCorrectionROI = maskCorrectionROI;
                    app.data.cellMaskCorr = cellMaskCorr;
                    % change pushbutton to: apply corrections
                    pb.Text  = 'apply corrections';
                    pb.FontColor = [1 0 0];
                    pb.Tag = 'apply corrections';
                    % set up figure functions to control cell mask
                    % correction ROI
                    app.UIFigure.WindowButtonMotionFcn = ...
                        {@app.positionAndSizeOfcellMaskROI, app.UIAxes_img};
                    app.UIFigure.WindowScrollWheelFcn = ...
                        {@app.positionAndSizeOfcellMaskROI, app.UIAxes_img};
                    % check if there is a conflict between mouse control of
                    % cell mask selection and axes interactivity
                    % (some interactivity on some axes is turned on e.g. zooming, datatip...)
                    if isempty(app.UIFigure.WindowScrollWheelFcn)
                        % throw an error dlg
                        uialert(app.UIFigure, ...
                            {'Please turn off all interactivity in all axes!', ...
                            'e.g. zooming'}, ...
                            'TURN OFF AXES INREACTIVITY')
                        % put it in the start state, basically call the
                        % same callback again
                        app.cellMask_manualCorrection(event)
                    end

                case 'apply corrections'
                    % extract only biggest object from corrected cell mask
                    correctedMask = bwareafilt( ...
                        app.data.cellMaskCorr.correctedMask, 1, 8);
                    % fill holes in mask
                    if app.CheckBox_cellMask_manulalCorrection_fillHoles.Value
                        correctedMask = imfill(correctedMask,'holes');
                    end
                    % check if mask correction applies to all frames/mask or only
                    % to selected one
                    if app.CheckBox_cellMask_manualCorrection_toAll.Value
                        % apply to all, change cell masks in all frames
                        app.data.cellMask = repmat(correctedMask, ...
                            1, 1, size(app.data.imgData,3));
                    else 
                        % only change mask of cell in selected frame
                        app.data.cellMask(:,:,app.Spinner_imgFrame.Value) = ...
                            correctedMask;
                    end

                    % change back settings, delete added things
                    pb.Text  = 'start corrections';
                    pb.Tag = 'start corrections';
                    pb.FontColor = [0 0 1];
                    app.CheckBox_cellMask_manualCorrection_toAll.Enable = 'on';
                    % delete axes with cell mask correction roi and remove
                    % also data associated with it
                    delete(app.data.cellMaskCorr.ax_maskROI)
                    app.data = rmfield(app.data, 'cellMaskCorr');
                    % remove window finctions
                    app.UIFigure.WindowButtonMotionFcn = [];
                    app.UIFigure.WindowScrollWheelFcn = [];

                    % update blank blank value
                    app.data.img_blank = mean( ...
                        app.data.imgData(~app.data.cellMask), 'all');

                    % update
                    showFilteredImgWithCellMask(app, event, false)
            end

        end

        % Callback function
        function calculate_selfRatio(app, event)
            
            
            keyboard
            % calculate average of cell of each frame
             % check if there are some data and cell edge
            if isempty(app.data.imgData) || ~isfield(app.data, 'cellMask')
                return
            end

            % show state
            app.stateLampLabel.Text = 'busy';
            app.Lamp_state.Color = [1 0 0];
            drawnow
            % filter imgData before normalization, not so heavily filtered
            % filterImgForAnalysis(app, 'calculation_deltaF_div_F0')
            % get data
            imgData = double(app.data.imgData);
            % get maximum cell mask
            cellMask = app.data.cellMask;
            cellMask_proj = any(cellMask, 3);
            
            % get minimum (1st percentile) and average projection of imgData
            F0_imgSeries = prctile(imgData, 1, 3); % F0
            % F0_imgSeries = min(imgData,[],3); % F0
            img_min_proj = min(imgData,3);
                      
            % check whether calculate single F0 for whole image series or
            % for each frame
            if app.CheckBox_F0ForEachFrame.Value
                % for each frame
                F0 = zeros(size(imgData,3),1);
                img_blank = zeros(size(imgData,3),1);
                for i = 1:size(imgData,3)
                    % single frame, only data inside cell mask
                    img = imgData(:,:,i);
                    img_cell = img(cellMask(:,:,i));
                    % get blank from area which is not cell
                    img_blank(i) = median(img(~cellMask(:,:,i)));
                    if isnan(img_blank(i))
                        % means that cell mask is all true, no area ouside
                        % of cell
                        img_blank(i) = 0;
                    end
                    % get mean and SD fluorescence of cell in image
                    % avrg_img = mean(img_cell);
                    % take it from min image projection
                    avrg_img = mean(F0_imgSeries(cellMask(:,:,i)));
                    sd_img = std(img_cell);

                    % calculate F0 as mean from pixels which are less than mean + 1SD
                    F0(i) =  mean( img_cell(img_cell < (avrg_img+1*sd_img)) );

                    % normalize image as (F-F0)/(F0-blank)
                    imgData(:,:,i) = ( imgData(:,:,i) - F0(i) ) ./ ( F0(i) - img_blank(i) );
                end

            else
                % one for whole image series
                blank = median(img_min_proj(~cellMask_proj));
                % subtract blank
                imgData = imgData - blank;
                % normalize
                imgData = imgData ./ F0_imgSeries - 1;
                % filter after to remove outliers due to normalization
                for i=1:size(imgData,3)
                    imgData(:,:,i) = medfilt2(imgData(:,:,i), [3 3]);
                end
                
                % save F0
                F0 = repmat(mean(F0_imgSeries(cellMask_proj)),size(imgData,3),1);
                img_blank = repmat(blank,size(imgData,3),1);
            end
            
            % save normalized data
            app.data.imgData = imgData;
            app.data.F0 = F0;
            app.data.img_blank = img_blank;
            
            % show selected frame from image series with cell mask if calculated
            showFilteredImgWithCellMask(app, event, true)
            
            % show state
            app.stateLampLabel.Text = 'ready';
            app.Lamp_state.Color = [0 1 0];
            drawnow
            
        end

        % Value changed function: CheckBox_cellMask_show
        function showCellMask(app, event)
            % find handle to cell mask
            h_cellMask = findobj(app.UIAxes_img, 'Type', 'patch', ...
                'Tag', 'cellMask');
            % check if there is mask ROI
            if ~isempty(h_cellMask)
                if app.CheckBox_cellMask_show.Value
                    h_cellMask.Visible = 'on';
                else
                    h_cellMask.Visible = 'off';
                end
            end           
        end

        % Cell edit callback: UITable_imgMeta
        function imgMetadataTableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
            switch event.Source.ColumnName{indices(2)}
                case 'pixel size (um)'
                    app.data.pxSz = newData;
                     
                case 'time step (ms)'
                    app.data.pxSzT = newData;
                    app.data.t = ...
                        (newData:newData:newData*size(app.data.imgData,3)) - newData;
            end
                     
        end

        % Value changed function: CheckBox_showBB_events, 
        % ...and 3 other components
        function showDetectedEvents_checkBoxes(app, event)
            
            % take only events present in current frame
            curr_frame = app.Spinner_imgFrame_events.Value;
            
            % handles of events in current frame
            h_events = app.data.eventsInAllFrames{curr_frame};
            
            switch event.Source.Tag
                case 'check_showBBofEvents'
                    if event.Value
                        arrayfun(@(x) set(x, 'EdgeAlpha', 1), h_events)
                    else
                        arrayfun(@(x) set(x, 'EdgeAlpha', 0), h_events)
                    end


                case 'check_showEvents'
                    if event.Value
                        arrayfun(@(x) set(x, 'EdgeAlpha', 1, ...
                            'FaceAlpha', 0.2, 'Visible', 1), ...
                            h_events)
                        app.CheckBox_showBB_events.Value = 1;
                        app.CheckBox_showmask_events.Value = 1;
                    else
                        arrayfun(@(x) set(x, 'Visible', 0), h_events)
                        app.CheckBox_showBB_events.Value = 0;
                        app.CheckBox_showmask_events.Value = 0;
                    end
                    

                case 'check_showMaskOfEvents'
                    if event.Value
                        arrayfun(@(x) set(x, 'FaceAlpha', 0.25), h_events)
                    else
                        arrayfun(@(x) set(x, 'FaceAlpha', 0), h_events)
                    end
                    

                case 'check_showCellMask_events'
                    h_cellMask = findobj(app.UIAxes_img_events, ...
                        'Type', 'Patch', 'Tag', 'cellMask');
                    if event.Value
                        arrayfun(@(x) set(x, 'EdgeAlpha', 1), h_cellMask)
                    else
                        arrayfun(@(x) set(x, 'EdgeAlpha', 0), h_cellMask)
                    end

            end


        end

        % Callback function: DropDown_spatialProfile, UITable_events, 
        % ...and 1 other component
        function showSelectedEvent(app, event)
            % check if it is uitable or dropdown menu
            if isequal(event.Source.Type, 'uitable')
                % indices of selected event in table
                indices = event.Indices;
                % get frame, in which selected event starts
                if isempty(indices)
                    return
                end
                frame_val = double(app.UITable_events.Data.imgNum_s(indices(1)));

                % remove previously highlighted row
                removeStyle(app.UITable_events)

                % highlight row with selected event
                s = uistyle('BackgroundColor','yellow');
                addStyle(app.UITable_events, s, 'row', indices(1))

                % set up time, slider and spinner
                app.EditField_imgTime_events.Value = app.data.t(frame_val);
                app.Slider_imgFrame_events.Value = frame_val;
                app.Spinner_imgFrame_events.Value = frame_val;

                % check show selected event only
                app.CheckBox_showSelectedEvent.Value = 1;

                % save selected event
                app.data.selectedEvent = app.UITable_events.Data(indices(1),:);

                % in case there was change of type of event using dropdownmenu
                % or change of accept state, save it in all events data table 
                idx = find(app.data.selectedEvent.eventID == ...
                app.data.detectedEvents.eventID);
                app.data.detectedEvents.eventType(idx) = ...
                    app.data.selectedEvent.eventType;
                app.data.detectedEvents.accepted(idx) = ...
                    app.data.selectedEvent.accepted;

                % show selected frame from image series with events
                % set previous to invisible
                cellfun(@(x) set(x, ...
                    'Visible','off', 'FaceAlpha',0, 'EdgeAlpha',0), ...
                    app.data.eventsInAllFrames)

                app.showFilteredImgWithEventsBoundaries(event, false, false)

                % if re-analyze window is opened show selected event also
                % there
                refitFig = findall(groot,'Type','figure', 'Tag','refitFig');
                if ~isempty(refitFig)
                    app.showSelectedEventInReAnalyzeWindow(refitFig)
                end
            end
            % show profile of selected event
            app.showProfilesAndFitsOfSelectedEvent()
            
        end

        % Window key press function: UIFigure
        function scrollEventsWithKeyArrows(app, event)
            % check if detected evetns tab is in focus
            if ~strcmp(app.TabGroup.SelectedTab.Tag, 'analysisTab')
                return
            end
            if ~isfield(app.data, 'selectedEvent')
                return
            end
            if isempty(app.data.selectedEvent)
                return
            end
       
            previousEvent = app.data.selectedEvent;
            idx_row_prev = find(previousEvent.eventID == ...
                app.UITable_events.Data.eventID);
            % move in table of events
            if app.CheckBox_showSelectedEvent.Value && ...
                ~isempty(previousEvent)
                    switch event.Key
                        case 'downarrow'
                            idx_row_new = idx_row_prev + 1;
                            if idx_row_new>height(app.UITable_events.Data)
                                idx_row_new = height(app.UITable_events.Data);
                            end
                        
                        case 'uparrow'
                            idx_row_new = idx_row_prev - 1;
                            if idx_row_new<1
                                idx_row_new = 1;
                            end
                        otherwise
                            return
                    end
                    
                    % remove highlight in table
                    removeStyle(app.UITable_events)
                    
                    % highlight row with new selected event
                    s = uistyle('BackgroundColor','yellow');
                    addStyle(app.UITable_events, s, 'row', idx_row_new)

                    % save selected event
                    app.data.selectedEvent = app.UITable_events.Data(idx_row_new,:);

                    % show profile of selected event
                    app.showProfilesAndFitsOfSelectedEvent()
                    
            end
        end

        % Button pushed function: Button_save
        function saveAnalysis(app, event)
            % save analysis results in to xlsx or csv file
            % check if there is something to save
            if isempty(app.data.detectedEvents)
                return
            end
            % get path to file where to save
            [imgPath, imgName, imgExt] = ...
                fileparts(char(app.data.imgPath));
            % save as xlsx file
            saveFileFormat = '.xlsx';
            resultsFilePath = fullfile( ...
                char(imgPath), ...
                char([imgName,'_results',saveFileFormat]));
            if ~app.data.batchMode
                % dialog window to choose where to save results
                [file, path] = uiputfile(...
                    {'*.xlsx','Excel files (*.xlsx)'}, ...
                    'save analysis results', ...
                    resultsFilePath);
                if path==0
                    return
                end
                % set up path for results file
                resultsFilePath = fullfile(path, file);
            end

            % prepare data to save to file
            % info 
            info = {'image name:', [imgName, imgExt]};
            info = [info;
                {'image path:', imgPath}];
            info = [info;
                {'pixel size x (µm):', app.data.pxSz}];
            info = [info;
                {'pixel size t (ms):', app.data.pxSzT}];
            try
                info = [info; ...
                    {'blank:', mean(app.data.img_blank)}];
            catch
                info = [info; ...
                    {'blank:', nan}];
            end
            % save crop position
            if isempty(app.data.crop_roi_xy_pos)
                info = [info; ... 
                    {'crop start frame:', []; ...
                     'crop end frame:', []; ...
                     'crop ROI position (x,y,w,h):', []}];
            else
                info = [info; ...
                    {'crop start frame:', app.data.crop_s_frame; ...
                     'crop end frame:', app.data.crop_e_frame; ...
                     'crop ROI position (x):', app.data.crop_roi_xy_pos(1); ...
                     'crop ROI position (y):', app.data.crop_roi_xy_pos(2); ...
                     'crop ROI position (w):', app.data.crop_roi_xy_pos(3); ...
                     'crop ROI position (h):', app.data.crop_roi_xy_pos(4)}];
            end

            % parameters
            eventsParams = app.data.detectedEvents(...
                app.data.detectedEvents.accepted, [[1,2,3],(6:13)]);
            eventsParamsCellArr = [ 
                cellfun(@(x,y) [x, ' (', y, ')'], ...
                eventsParams.Properties.VariableNames, ...
                eventsParams.Properties.VariableUnits, ...
                'UniformOutput', false); ...  
                table2cell(eventsParams) 
                ];
            % create one array from parameters and info
            % find dimensions
            numOfrows = max(size(info,1),size(eventsParamsCellArr,1));
            numOfCols = size(info,2) + 2 + size(eventsParamsCellArr,2);
            outputToSave = cell([numOfrows, numOfCols]);
            % add info
            outputToSave(1:size(info,1), 1:size(info,2)) = info;
            % add parameters
            outputToSave(1:size(eventsParamsCellArr,1), size(info,2)+3:end) = ...
                eventsParamsCellArr;
            % save to file
            % delete xls file if exist
            if exist(resultsFilePath, 'file')
                delete(resultsFilePath)
            end
            % save new file
            writecell(outputToSave, resultsFilePath, ...
                'Sheet','results', 'Range', 'A1')

            % save analysis data if selected
            if app.CheckBox_saveDetection.Value
                app.data.imgAnalysisData.detectedEvents = app.data.detectedEvents;
                imgAnalysisData = app.data.imgAnalysisData;
                % delete analysis file if exist
                if exist(imgAnalysisData.imgAnalysisData_filePath, 'file')
                    delete(imgAnalysisData.imgAnalysisData_filePath)
                end
                % save analysis data
                save(imgAnalysisData.imgAnalysisData_filePath, 'imgAnalysisData')
            end

            % clear app content
            clearAppContent(app, 'all')


        end

        % Button pushed function: Button_refitEvent
        function reanalyzeSelectedEventWindow(app, event)
            % used for all components in refit event panel
            % get selected event, highlighted in uitable
            if ~isfield(app.data, 'selectedEvent')
                return
            end
            % create window 
            % in pixels
            w_fig = 1300;
            h_fig = app.UIFigure.OuterPosition(4);
            df_x = 0.01;
            df_y = 0.01;
            h_p = 0.15;
            w_p = 1-2*df_x;
            h_a = (1-5*df_y-h_p)/3;
            w_ha = (1-3*df_x)/2;
            posFig = round( [...
                app.UIFigure.Position(1)+app.UIFigure.Position(3)/2-w_fig/2, ...
                app.UIFigure.Position(2)+app.UIFigure.Position(4)/2-h_fig/2, ...
                w_fig, ...
                h_fig ]);
            refitFig = uifigure('Name','Re-analyze selected event', ...
                'WindowStyle','normal',... % modal normal
                'Units','pixels', 'Position', posFig, 'Tag','refitFig');
            % add axes to show event
            % check ratio of image and adjust width axes according to that
            % in pixels
            imgRatio = size(app.data.imgData,2)/size(app.data.imgData,1);
            w_wholeImg_a = h_a*refitFig.Position(4)*imgRatio;
            if w_wholeImg_a>(1-2*df_x)*refitFig.Position(3)
                w_wholeImg_a = (1-2*df_x)*refitFig.Position(3);
            end
            ax_wholeImg = uiaxes(refitFig, ...
                'OuterPosition',[(refitFig.Position(3)-w_wholeImg_a)/2 ...
                                 (1-df_y-h_a)*refitFig.Position(4) ...
                                 w_wholeImg_a ...
                                 h_a*refitFig.Position(4)], ...
                'Tag','wholeImg');
            ax_img = uiaxes(refitFig, ...
                'OuterPosition',[df_x*refitFig.Position(3) ...
                                 (1-2*df_y-2*h_a)*refitFig.Position(4) ...
                                 w_ha*refitFig.Position(3) ...
                                 h_a*refitFig.Position(4)], ...
                'Tag','eventImg');
            ax_xProf = uiaxes(refitFig, ...
                'OuterPosition',[df_x*refitFig.Position(3) ...
                                 (1-3*df_y-3*h_a)*refitFig.Position(4) ...
                                 w_ha*refitFig.Position(3) ...
                                 h_a*refitFig.Position(4)], ...
                'Tag','xProf', ...
                'ButtonDownFcn',{@app.setMaskOfProfileWithMouse, refitFig});
            ax_yProf = uiaxes(refitFig, ...
                'OuterPosition',[(1-w_ha)*refitFig.Position(3) ...
                                 (1-2*df_y-2*h_a)*refitFig.Position(4) ...
                                 w_ha*refitFig.Position(3) ...
                                 h_a*refitFig.Position(4)], ...
                'Tag','yProf', ...
                'ButtonDownFcn',{@app.setMaskOfProfileWithMouse, refitFig});
            ax_yProf.YAxis.Direction = 'reverse';
            ax_tProf = uiaxes(refitFig, ...
                'OuterPosition',[(1-w_ha)*refitFig.Position(3) ...
                                 (1-3*df_y-3*h_a)*refitFig.Position(4) ...
                                 w_ha*refitFig.Position(3) ...
                                 h_a*refitFig.Position(4)], ...
                'Tag','tProf', ...
                'ButtonDownFcn',{@app.setMaskOfProfileWithMouse, refitFig});

            % create panel with fit control with filters selection
            uipnl_fitControl = uipanel(refitFig, ...
                'Title',[], ...
                'FontSize',16, 'FontWeight','bold', ...
                'Position',[df_x*refitFig.Position(3) ...
                df_y*refitFig.Position(4) ...
                w_p*refitFig.Position(3), ...
                h_p*refitFig.Position(4)]);
            % wave detection and deskew
            uipnl_wave = uipanel(uipnl_fitControl, ...
                'Title','detect & deskew wave', ...
                'FontSize',16, 'FontWeight','bold', ...
                'FontAngle','italic', ...
                'Position',[0*uipnl_fitControl.InnerPosition(3) ...
                            0*uipnl_fitControl.InnerPosition(4) ...
                            0.4*uipnl_fitControl.InnerPosition(3) ...
                            1*uipnl_fitControl.InnerPosition(4)]);
            % span of spline knots
            uilabel('Parent',uipnl_wave, ...
                'Text',{'span of spline knots';'(ms)'}, ...
                'FontSize',14, ...
                'HorizontalAlignment','center',...
                'VerticalAlignment','center', ...
                'Position',[0.01*uipnl_wave.InnerPosition(3) ...
                            0.1*uipnl_wave.InnerPosition(4) ...
                            0.39*uipnl_wave.InnerPosition(3) ...
                            0.8*uipnl_wave.InnerPosition(4)]);
            h_edit_deskewSpan = uieditfield('numeric', ...
                'Value',100, ...
                'Parent',uipnl_wave, 'FontSize',14, ...
                'HorizontalAlignment','center',...
                'Position',[0.4*uipnl_wave.InnerPosition(3) ...
                            (uipnl_wave.InnerPosition(4)-30)/2 ...
                            0.2*uipnl_wave.InnerPosition(3) ...
                            30], ...
                'ValueChangedFcn','');
            % add pushbutton to correct mask of detected wave
            hb_wave_mask = uibutton(uipnl_wave, ...
                'Text','start event mask correction',...
                'Tag','start',...
                'WordWrap','on', ...
                'FontSize',16, ...
                'FontWeight','bold', 'Tag','start', ...
                'Position',[0.65*uipnl_wave.InnerPosition(3) ...
                            0.3*uipnl_wave.InnerPosition(4) ...
                            0.3*uipnl_wave.InnerPosition(3) ...
                            0.6*uipnl_wave.InnerPosition(4)], ...
                'ButtonPushedFcn',{@app.eventMaskManualCorrection,refitFig});
            h_checkBox_mask_holes = uicheckbox(uipnl_wave, ...
                'Text','fill holes in event mask', ...
                'FontSize',14, 'Value',1, ...
                'Position',[0.65*uipnl_wave.InnerPosition(3) ...
                            0.05*uipnl_wave.InnerPosition(4) ...
                            0.3*uipnl_wave.InnerPosition(3) ...
                            0.2*uipnl_wave.InnerPosition(4)]);

            % time profile
            uipnl_tProf = uipanel(uipnl_fitControl, ...
                'Title','time profile', ...
                'FontSize',14, 'FontWeight','bold', ...
                'FontAngle','italic', ...
                'Position',[0.4*uipnl_fitControl.InnerPosition(3) ...
                            0*uipnl_fitControl.InnerPosition(4) ...
                            0.3*uipnl_fitControl.InnerPosition(3) ...
                            0.94*uipnl_fitControl.InnerPosition(4)]);
            % normalization of time profile
            uipnl_tProfNorm = uipanel(uipnl_tProf, ...
                'Title','self-ratio', ...
                'FontSize',14, 'FontWeight','normal', ...
                'Position',[0*uipnl_tProf.InnerPosition(3) ...
                            0*uipnl_tProf.InnerPosition(4) ...
                            0.5*uipnl_tProf.InnerPosition(3) ...
                            0.94*uipnl_tProf.InnerPosition(4)]);
            h_drop_bsFit = uidropdown('Parent',uipnl_tProfNorm, ...
                'Items',{'rloess','mean','poly1','poly2','poly3','poly4', ...
                'exp1','exp2','spline'}, ...
                'Position',[0.05*uipnl_tProfNorm.InnerPosition(3) ...
                            0.6*uipnl_tProfNorm.InnerPosition(4) ...
                            0.9*uipnl_tProfNorm.InnerPosition(3) ...
                            0.3*uipnl_tProfNorm.InnerPosition(4)], ...
                'FontSize',16, 'FontWeight','bold', 'Tag','tProf', ...
                'ValueChangedFcn',{@app.refitSelectedEvent,refitFig});
            h_pb_selfRatio = uibutton(uipnl_tProfNorm, ...
                'Text','calculate self-ratio',...
                'WordWrap','on', ...
                'FontSize',16, ...
                'FontWeight','bold', 'Tag','tProf', ...
                'Position',[0.05*uipnl_tProfNorm.InnerPosition(3) ...
                            0.01*uipnl_tProfNorm.InnerPosition(4) ...
                            0.9*uipnl_tProfNorm.InnerPosition(3) ...
                            0.58*uipnl_tProfNorm.InnerPosition(4)], ...
                'ButtonPushedFcn',{@app.refitSelectedEvent,refitFig});
            % fitting of time profile
            uilabel('Parent',uipnl_tProf, ...
                'Text','fit function', ...
                'FontSize',16, ...
                'HorizontalAlignment','center',...
                'VerticalAlignment','bottom', ...
                'Position',[0.55*uipnl_tProf.InnerPosition(3) ...
                            0.75*uipnl_tProf.InnerPosition(4) ...
                            0.4*uipnl_tProf.InnerPosition(3) ...
                            0.25*uipnl_tProf.InnerPosition(4)]);
            h_drop_tProfFit = uidropdown('Parent',uipnl_tProf, ...
                'Items',{'spline','EMG','1expR1expD'}, ...
                'Position',[0.55*uipnl_tProf.InnerPosition(3) ...
                            0.5*uipnl_tProf.InnerPosition(4) ...
                            0.4*uipnl_tProf.InnerPosition(3) ...
                            0.25*uipnl_tProf.InnerPosition(4)], ...
                'FontSize',16, 'FontWeight','bold', 'Tag','tProf', ...
                'ValueChangedFcn',{@app.refitSelectedEvent,refitFig});
            % smooth window
            uilabel('Parent',uipnl_tProf, ...
                'Text',{'smooth';'span';'(ms)'}, ...
                'FontSize',14, ...
                'HorizontalAlignment','center',...
                'VerticalAlignment','bottom', ...
                'Position',[0.55*uipnl_tProf.InnerPosition(3) ...
                            0.01*uipnl_tProf.InnerPosition(4) ...
                            0.2*uipnl_tProf.InnerPosition(3) ...
                            0.45*uipnl_tProf.InnerPosition(4)]);
            h_edit_smoothSpan = uieditfield('numeric', ...
                'Value',50, ...
                'Parent',uipnl_tProf, 'FontSize',16, ...
                'HorizontalAlignment','center',...
                'Position',[0.75*uipnl_tProf.InnerPosition(3) ...
                            0.08*uipnl_tProf.InnerPosition(4) ...
                            0.2*uipnl_tProf.InnerPosition(3) ...
                            0.3*uipnl_tProf.InnerPosition(4)], ...
                'ValueChangedFcn','');

            % spatial profiles
            uipnl_spatialProf = uipanel(uipnl_fitControl, ...
                'Title','spatial profile', ...
                'FontSize',14, 'FontWeight','bold', ...
                'FontAngle','italic', ...
                'Position',[0.755*uipnl_fitControl.InnerPosition(3) ...
                            0*uipnl_fitControl.InnerPosition(4) ...
                            0.125*uipnl_fitControl.InnerPosition(3) ...
                            0.94*uipnl_fitControl.InnerPosition(4)]);
            uilabel('Parent',uipnl_spatialProf, ...
                'Text','fit function', ...
                'FontSize',16, ...
                'HorizontalAlignment','center',...
                'VerticalAlignment','bottom', ...
                'Position',[0.05*uipnl_spatialProf.InnerPosition(3) ...
                            0.50*uipnl_spatialProf.InnerPosition(4) ...
                            0.9*uipnl_spatialProf.InnerPosition(3) ...
                            0.25*uipnl_spatialProf.InnerPosition(4)]);
            h_drop_spatialProfFit = uidropdown( ...
                'Parent',uipnl_spatialProf, ...
                'Items',{'Gauss','EMG'}, ...
                'Position',[0.05*uipnl_spatialProf.InnerPosition(3) ...
                            0.25*uipnl_spatialProf.InnerPosition(4) ...
                            0.9*uipnl_spatialProf.InnerPosition(3) ...
                            0.25*uipnl_spatialProf.InnerPosition(4)], ...
                'FontSize',16, 'FontWeight','bold', 'Tag','xyProf',...
                'ValueChangedFcn',{@app.refitSelectedEvent,refitFig});
            % save analysis and close window
            uibutton('Text','save',...
                'WordWrap','on', ...
                'FontSize',18, ...
                'FontWeight','bold', ...
                'Parent',uipnl_fitControl, 'Tag','save',...
                'Position',[0.89*uipnl_fitControl.InnerPosition(3) ...
                            0.2*uipnl_fitControl.InnerPosition(4) ...
                            0.1*uipnl_fitControl.InnerPosition(3) ...
                            0.6*uipnl_fitControl.InnerPosition(4)], ...
                'ButtonPushedFcn',{@app.refitSelectedEvent,refitFig});
            
            % save handle
            hObjs.ax_wholeImg = ax_wholeImg;
            hObjs.ax_img = ax_img;
            hObjs.ax_tProf = ax_tProf;
            hObjs.ax_xProf = ax_xProf;
            hObjs.ax_yProf = ax_yProf;
            hObjs.uipnl_fitControl = uipnl_fitControl;
            hObjs.uipnl_wave = uipnl_wave;
            hObjs.hb_wave_mask = hb_wave_mask;
            hObjs.h_checkBox_mask_holes = h_checkBox_mask_holes;
            hObjs.uipnl_tProf = uipnl_tProf;
            hObjs.h_drop_bsFit = h_drop_bsFit;
            hObjs.h_pb_selfRatio = h_pb_selfRatio;
            hObjs.h_drop_tProfFit = h_drop_tProfFit;
            hObjs.uipnl_spatialProf = uipnl_spatialProf;
            hObjs.h_drop_spatialProfFit = h_drop_spatialProfFit;
            hObjs.h_edit_smoothSpan = h_edit_smoothSpan;
            hObjs.h_edit_deskewSpan = h_edit_deskewSpan;
            hObjs.uipnl_fitControl = uipnl_fitControl;
            hObjs.lastAxes = [];

            % save handles of objects
            setappdata(refitFig, 'hObjs', hObjs);
            % show selected event
            app.showSelectedEventInReAnalyzeWindow(refitFig)


        end

        % Menu selected function: Menu_saveAxesAsImgs
        function saveAxesContentToFile(app, event)
            % curObj = gco(app.UIFigure);
            % find axes to save
            % ax = ancestor(curObj,{'axes'});
            
            % create dialog window
            % in pixels
            w_fig = 450;
            h_fig = 400;
            d = 10;
            w_cbx = 250;
            h_cbx = 50;
            w_dm = 100;
            h_dm = 30;
            posFig = round([round(app.UIFigure.Position(1)+app.UIFigure.Position(3)/2-w_fig/2), ...
                round(app.UIFigure.Position(2)+app.UIFigure.Position(4)/2-h_fig/2), ...
                w_fig, h_fig]);
            uifig = uifigure( ...
                'WindowStyle','modal',...
                'Units', 'pixels', ...
                'Position', posFig, ...
                'Name', 'Select which axes to save to files with selected format.');
            % checkboxes and dropdown menus
            cbx = gobjects(4,1);
            dm = gobjects(4,1);
            % axes to save
            h_ax = [app.UIAxes_img, app.UIAxes_img_events, ...
                app.UIAxes_eventTprof, app.UIAxes_eventXYprof];
            text_cbx = { ...
                {'image axes', 'Ca events detection tab'}, ...
                {'image axes', 'analysed events browser tab'}, ...
                {'t-profile of event', 'analysed events browser tab'}, ...
                {'2D Gauss fit of event', 'analysed events browser tab'}, ...
                };
            uipnl_ax = uipanel(uifig, ...
                'Position', [1 ...
                h_fig-h_cbx*numel(h_ax)-(numel(h_ax)-1)*d ...
                w_fig ...
                h_cbx*numel(h_ax)+(numel(h_ax)-1)*d]);
            for i=1:numel(h_ax)
                % checkboxes
                cbx(i) = uicheckbox(uipnl_ax, ...
                    'Text',text_cbx{i},...
                    'Value',1, 'FontSize',16, ...
                    'Position', round([ ...
                    (uipnl_ax.Position(3)-w_cbx-w_dm)/2 ...
                    uipnl_ax.Position(4)-i*h_cbx-(i-1)*d ...
                    w_cbx ...
                    h_cbx]));
                % dropdownmenus
                dm(i) = uidropdown(uipnl_ax, ...
                    'Items',{'.png','.pdf','.eps'},...
                    'Value','.pdf', 'FontSize',16, ...
                    'Position',round([ ...
                    (uipnl_ax.Position(3)-w_cbx-w_dm)/2+w_cbx ...
                    uipnl_ax.Position(4)-i*h_cbx-(i-1)*d+(h_cbx-h_dm)/2 ...
                    w_dm ...
                    h_dm]));
            end
            % panels for parameters
            uipnl_params = uipanel(uifig, ...
                'Position',round([1 1 w_fig/2 uipnl_ax.Position(2)]), ...
                'Title','Parameters of output', ...
                'FontSize',16, 'FontWeight','bold');
            % add selection for resolution of saved image
            uilabel(uifig, ...
                'Text','Resolution (pixels):', ...
                'FontSize',16, ...
                'HorizontalAlignment','center', ...
                'Position',round([d ...
                uipnl_params.Position(4)-h_dm-2*d ...
                uipnl_params.Position(3)-2*d ...
                h_dm]));
            dm_res = uidropdown(uifig, ...
                'Items',{'150','300','600','900','1200'},...
                'Value','300', 'FontSize',16, ...
                'Position',round([...
                (uipnl_params.Position(3)-w_dm)/2 ...
                uipnl_params.Position(4)-2*h_dm-2*d ...
                w_dm ...
                h_dm]));
            % and for content
            uilabel(uifig, ...
                'Text','Type of content of axes:', ...
                'FontSize',16, ...
                'HorizontalAlignment','center', ...
                'Position',round([d ...
                uipnl_params.Position(4)-3*h_dm-3*d ...
                uipnl_params.Position(3)-2*d ...
                h_dm]));
            dm_contentType = uidropdown(uifig, ...
                'Items',{'auto','vector','image'},...
                'Value','auto', 'FontSize',16, ...
                'Position',round([...
                (uipnl_params.Position(3)-w_dm)/2 ...
                uipnl_params.Position(4)-4*h_dm-3*d ...
                w_dm ...
                h_dm]));
            
            % push buttons
            h_pb = round((h_fig-uipnl_ax.Position(4)-6*d)/2);
            w_pb = (w_fig-(uipnl_params.Position(1)+uipnl_params.Position(3))-6*d);
            pb_Save = uibutton(uifig, ...
                'Text', 'Save', ...
                'Position', [ ...
                uipnl_params.Position(1)+uipnl_params.Position(3)+3*d ...
                uipnl_ax.Position(2)-h_pb-2*d ...
                w_pb ...
                h_pb], ...
                'FontSize',18, 'FontWeight','bold', ...
                'ButtonPushedFcn', ...
                {@app.saveSelectedAxesToFiles, uifig, cbx, dm, h_ax, dm_res, dm_contentType});
            pb_Cancel = uibutton(uifig, ...
                'Text', 'Cancel', ...
                'Position', [ ...
                uipnl_params.Position(1)+uipnl_params.Position(3)+3*d ...
                uipnl_ax.Position(2)-2*h_pb-4*d ...
                w_pb ...
                h_pb], ...
                'FontSize',18, 'FontWeight','normal', ...
                'ButtonPushedFcn', @(h,~)delete(h.Parent));
            
        end

        % Button pushed function: Button_restart
        function restartAnalysis(app, event)
            
            % delete all analysis progress and data, keep only raw image data
            app.data.imgData = app.data.imgDataAll;
            app.data.imgDataRaw = app.data.imgDataAll;
            % recalculate time in case image series was cropped
            dt = app.data.pxSzT;
            t = (dt:dt:size(app.data.imgDataAll,3)*dt) - dt;
            app.data.t = t(:);
            app.data.detectedEvents = table();
            app.data.imgDataCaxisLimits = [];
            app.data.cellMask = true(size(app.data.imgData));
            app.data.F0 = [];
            app.data.img_blank = [];
            app.data.eventsIDs = [];
            app.data.eventsIDs_types = [];
            app.data.eventsInAllFrames = [];
            app.data.selectedEvent = [];
            app.data.crop_s_frame = [];
            app.data.crop_e_frame = [];
            app.data.crop_roi_xy_pos = [];

            % set up pushbutton for cropping
            app.Button_crop.Text = 'select xy area to crop';
            app.Button_crop.FontWeight = 'normal';

            % show image
            % rescale axes and respective uicontrols
            % based on size of image
            showImgAndRescaleAxes(app, event)

            % clear events tab content
            clearAppContent(app)

            % show state
            app.stateLampLabel.Text = 'ready';
            app.Lamp_state.Color = [0 1 0];
            drawnow 
             
        end

        % Value changed function: CheckBox_cellMaskForEachFrame
        function checkBoxes_cellEdgePanel(app, event)
            if app.CheckBox_cellMaskForEachFrame.Value
                app.CheckBox_cellMask_manualCorrection_toAll.Value = 0;
            else
                app.CheckBox_cellMask_manualCorrection_toAll.Value = 1;
            end
            
        end

        % Button pushed function: Button_batchAnalysis
        function batchModeAnalysis(app, event)
            % select folder with movies
            dir_path = uigetdir();
            if dir_path==0
                return
            end
%             % use datastore, did not really worked for this app
%             imgSeriesDatastore = imageDatastore(dir_path, ...
%                 'FileExtensions','.tif', ...
%                 'IncludeSubfolders',app.CheckBox_includeSubfolders.Value, ...
%                 'LabelSource','foldernames');
            % get only TIFF files from selected folder
            if app.CheckBox_includeSubfolders.Value
                listing_tif = dir( fullfile(dir_path, '**', '*.tif*') );
                listing_all = dir( fullfile(dir_path, '**', '*.*') );
            else
                listing_tif = dir( fullfile(dir_path, '*.tif*') );
                listing_all = dir( fullfile(dir_path, '*.*') );
            end
            % exclude invalid entries if any
            listing_tif = listing_tif(~cellfun('isempty', {listing_tif.date}));
            listing_all = listing_all(~cellfun('isempty', {listing_all.date}));
            % check and throw error if listing is empty
            if isempty(listing_tif)
               uialert(app.UIFigure, ...
                   {'No TIFF files found in selected directory!', ...
                   'Check if subfolders are included.'}, ...
                   'Error', 'Icon','error')
               return 
            end
            % check if there are some results .mat files
            m_resultsFiles = ...
                contains({listing_all.name}, {'_sparks', '_puffs', '_waves'});
            if any(m_resultsFiles)
                selection = uiconfirm(app.UIFigure, ...
                    {'Results TIFF files found!', 'If continue, old results files will be deleted.'}, ...
                    'Delete results TIFF files?', ...
                    'Options', {'Continue', 'Cancel'}, ...
                    'DefaultOption', 2);
                switch selection 
                    case 'Continue'
                        % delete results files
                        cellfun(@(x) delete(x), ...
                            fullfile({listing_all(m_resultsFiles).folder}, ...
                            {listing_all(m_resultsFiles).name}));
                    case 'Cancel'
                        return
                end
            end
            % create image series paths
            imgsPaths = fullfile({listing_tif.folder}, {listing_tif.name});
            imgsPaths = imgsPaths(:);
            % show selected image series files in uitable
            % figure size in pixels
            w_fig = 1200;
            h_fig = 900;
            d = 10;
            h_pb = 75;
            w_pb = 100;
            posFig = round([round(app.UIFigure.Position(1)+app.UIFigure.Position(3)/2-w_fig/2), ...
                round(app.UIFigure.Position(2)+app.UIFigure.Position(4)/2-h_fig/2), ...
                w_fig, h_fig]);
            UIfig_batch = uifigure( ...
                'WindowStyle','normal',...
                'Units', 'pixels', ...
                'Position', posFig, ...
                'Name', 'Image series selected for batch analysis.');
            % get metadata for each img series, create table to show
            infoProgressBar = uiprogressdlg(UIfig_batch, ...
                'Title','Please wait.', ...
                'Message','getting info about selected image series...', ...
                'ShowPercentage','on');
            drawnow
            imgSeriesData_tbl = table( imgsPaths, ...
                zeros(size(imgsPaths)), ...
                zeros(size(imgsPaths)), ...
                zeros(size(imgsPaths)), ...
                zeros(size(imgsPaths)), ...
                true(size(imgsPaths)), ...
                'VariableNames',{'image series path', '# images', '# channels', ...
                'pxSz (um)', 'pxSz_t (ms)', 'selected'});
            for i=1:height(imgSeriesData_tbl)
                img_info = imfinfo(imgSeriesData_tbl.("image series path"){i});
                imgDescrStr = img_info(1).ImageDescription;
                N_imgs = size(img_info, 1);
                imgMetadata = app.getImgMetadataFromImgDescrStr(...
                    imgDescrStr, N_imgs);
                imgSeriesData_tbl.("# images")(i) = N_imgs;
                imgSeriesData_tbl.("# channels")(i) = imgMetadata.n_ch;
                imgSeriesData_tbl.("pxSz (um)")(i) = imgMetadata.pxSz;
                imgSeriesData_tbl.("pxSz_t (ms)")(i) = imgMetadata.pxSzT;
                % update progress bar
                infoProgressBar.Value = i/height(imgSeriesData_tbl);
            end
            % delete progress bar
            close(infoProgressBar)
            % uitable with selected image series
            UItbl_imgSeries = uitable(UIfig_batch, ...
                'Data',imgSeriesData_tbl, ...
                'Position', [d h_pb+2*d w_fig-2*d h_fig-h_pb-3*d], ...
                'ColumnEditable',[false true true true true true], ...
                'Multiselect','on', ...
                'SelectionChangedFcn', @app.changeValueOfMultipleSelectedCells);

            % push buttons
            pb_continue = uibutton(UIfig_batch, ...
                'Text', 'Continue', ...
                'Position', [round((w_fig-2*w_pb-4*d)/2) d w_pb h_pb], ...
                'FontSize',18, 'FontWeight','bold', ...
                'FontColor', [1 0 0], ...
                'ButtonPushedFcn', ...
                {@app.analyseImgSeriesInBatchMode, UIfig_batch, UItbl_imgSeries});
            pb_cancel = uibutton(UIfig_batch, ...
                'Text', 'Cancel', ...
                'Position', [round((w_fig-2*w_pb-4*d)/2)+w_pb+4*d d w_pb h_pb], ...
                'FontSize',18, 'FontWeight','normal', ...
                'Tag', 'menu_openImg', ...
                'ButtonPushedFcn', @(h,~)delete(h.Parent));
        end

        % Menu selected function: Menu_loadAnalyzedImgSeries
        function loadAnalyzedImgSeries(app, event)
            % set flag for loading analyzed image series to true
            app.data.loadAnalyzedImgSeriesFlag = true;
            % clear app content in gui
            clearAppContent(app, 'all')
            
            % load analyzed image series
            % select (.tif) image series and load respective analysis (.mat file)
            % simulate event structure after button click
            simButtonEvent = struct('Source', struct('Tag', 'menu_openImg'));
            app.openImg(simButtonEvent)
            
            % crop image series
            if ~isempty(app.data.imgAnalysisData.crop_roi_pos)
                % crop image series
                simButtonEvent = struct( ...
                    'Source', struct('Tag', 'cropImage', ...
                                     'Text', 'crop', ...
                                     'Type', 'uibutton'));
                app.cropImgSeries(simButtonEvent)
            end

            % load cell mask
            if ~isempty(app.data.imgAnalysisData.cellMask)
                app.data.cellMask = app.data.imgAnalysisData.cellMask;
                % show cell mask
                app.showFilteredImgWithCellMask(event, false)
            end

            % load F0
            if ~isempty(app.data.imgAnalysisData.F0)
                % recalculate F0, maybe consider to save whole matrix of
                % F0, if also some fitting of baseline is implemented
                % not only averages per frames
                app.CheckBox_F0ForEachFrame.Value = ...
                    app.data.imgAnalysisData.calcF0ForEachFrame;
                app.calculate_selfRatio(event)
            end
            
            % load detected events
            app.data.detectedEvents = app.data.imgAnalysisData.detectedEvents;
            % rescale axes and respective uicontrols based on size of image
            simButtonEvent = struct(...
                'Source', struct('Tag', 'detectEvents', ...
                                 'Text', 'detect events', ...
                                 'Type', 'uibutton') );
            showImgAndRescaleAxes(app, simButtonEvent)
            % show detected events with image
            showFilteredImgWithEventsBoundaries(app, simButtonEvent, false, true)

            % reset flag for loading analyzed image series
            app.data.loadAnalyzedImgSeriesFlag = false;

        end

        % Menu selected function: Menu_convertMatFileToTIFFs
        function convertMatFileToTIFFs(app, event)
            keyboard


        end

        % Button pushed function: Button_butterworthFilter
        function applyButterworthFilter(app, event)
            % create dialog window
            % in pixels
            w_fig = app.UIFigure.Position(3)-200;
            h_fig = app.UIFigure.Position(4);
            d = 30;
            d_pnl = 10;
            w_ax = w_fig-2*d;
            h_ax = h_fig/3;
            w_edit = 175;
            h_edit = 30;
            posFig = round([ ...
                round(app.UIFigure.Position(1)+app.UIFigure.Position(3)/2-w_fig/2), ...
                round(app.UIFigure.Position(2)+app.UIFigure.Position(4)/2-h_fig/2), ...
                w_fig, ...
                h_fig ...
                ]);
            uiFig = uifigure( ...
                'WindowStyle','normal',... % modal normal
                'Units', 'pixels', ...
                'Position', posFig, ...
                'Name', 'Apply Butterworth filter to image series.');
            % add uiaxes with average temporal profile of image series and 
            % calculated amplitude spectrum
            ax_prof = uiaxes(uiFig, 'Position',[d h_fig-d-h_ax w_ax h_ax]);
            ax_spectrum = uiaxes(uiFig, 'Position',[d h_fig-2*d-2*h_ax w_ax h_ax]);
            % panel for parameters
            pnl = uipanel(uiFig, ...
                'Position',round([(w_fig-ax_prof.Position(3)/3)/2 ...
                                   d ...
                                   ax_prof.Position(3)/3 ...
                                   h_ax-4*d]), ...
                'Title','Parameters of filtering:', ...
                'FontSize',18, 'FontWeight','bold');
            % add numeric edit boxes to change parameters of filtering
            lbl_butterOrder = uilabel(pnl, ...
                'Text','order of filter', 'HorizontalAlignment','center', ...
                'FontSize',16, 'VerticalAlignment', 'bottom',...
                'Position',round([ ...
                (pnl.Position(3)/3-d_pnl-w_edit)/2 ...
                pnl.Position(4)-2*d_pnl-h_edit ...
                w_edit h_edit]));
            edit_butterOrder = uieditfield(pnl, 'numeric', ...
                'Value',4, 'FontSize',16, 'HorizontalAlignment','center', ...
                'Position',round([ ...
                (pnl.Position(3)/3-d_pnl-w_edit/3)/2 ...
                pnl.Position(4)-3*d_pnl-2*h_edit ...
                w_edit/3 h_edit]), 'Tag','butterOrder');
            
            lbl_widthOfBandStop = uilabel(pnl, ...
                'Text','width of bandstop (Hz)', 'HorizontalAlignment','center', ...
                'FontSize',16, 'VerticalAlignment', 'bottom',...
                'Position',round([ ...
                (pnl.Position(3)/3-d_pnl-w_edit)/2 ...
                pnl.Position(4)-4*d_pnl-3*h_edit ...
                w_edit h_edit]));
            edit_widthOfBandStop = uieditfield(pnl, 'numeric', ...
                'Value',1, 'FontSize',16, 'HorizontalAlignment','center', ...
                'Position',round([ ...
                (pnl.Position(3)/3-d_pnl-w_edit/3)/2 ...
                pnl.Position(4)-5*d_pnl-4*h_edit ...
                w_edit/3 h_edit]), 'Tag','widthOfBandStop', ...
                'ValueChangedFcn',{@app.filterDataWithButterworthFilter, ...
                uiFig, ax_prof, ax_spectrum});

            lbl_RMS_multipl = uilabel(pnl, ...
                'Text','multiplier of RMS noise', 'HorizontalAlignment','center', ...
                'FontSize',16, 'VerticalAlignment', 'bottom',...
                'Position',round([ ...
                (pnl.Position(3)/3-d_pnl-w_edit)/2+pnl.Position(3)/3 ...
                pnl.Position(4)-2*d_pnl-h_edit ...
                w_edit h_edit]));
            edit_peakPromin = uieditfield(pnl, 'numeric', ...
                'Value',1, 'FontSize',16, 'HorizontalAlignment','center', ...
                'Position',round([ ...
                (pnl.Position(3)/3-d_pnl-w_edit/3)/2+pnl.Position(3)/3 ...
                pnl.Position(4)-3*d_pnl-2*h_edit ...
                w_edit/3 h_edit]), 'Tag','peakPromin', ...
                'ValueChangedFcn',{@app.filterDataWithButterworthFilter, ...
                uiFig, ax_prof, ax_spectrum});
            
            lbl_minFreq = uilabel(pnl, ...
                'Text','freq filtered (Hz) >=', 'HorizontalAlignment','center', ...
                'FontSize',16, 'VerticalAlignment', 'bottom',...
                'Position',round([ ...
                (pnl.Position(3)/3-d_pnl-w_edit)/2+pnl.Position(3)/3 ...
                pnl.Position(4)-4*d_pnl-3*h_edit ...
                w_edit h_edit]));
            edit_minFreq = uieditfield(pnl, 'numeric', ...
                'Value',7, 'FontSize',16, 'HorizontalAlignment','center', ...
                'Position',round([ ...
                (pnl.Position(3)/3-d_pnl-w_edit/3)/2+pnl.Position(3)/3 ...
                pnl.Position(4)-5*d_pnl-4*h_edit ...
                w_edit/3 h_edit]), 'Tag','minFreq', ...
                'ValueChangedFcn',{@app.filterDataWithButterworthFilter, ...
                uiFig, ax_prof, ax_spectrum});
            
            % add pushbuttons - filter, accept
            h_pb = round((pnl.InnerPosition(4)-4*d_pnl)/3);
            w_pb = pnl.InnerPosition(3)/3-4*d_pnl;
            pb_filter = uibutton(pnl, 'push', ...
                'Text','filter data','FontSize',18, 'FontWeight','bold', ...
                'Position',round([ ...
                (pnl.InnerPosition(3)/3-w_pb)/2+2*pnl.InnerPosition(3)/3 ...
                pnl.InnerPosition(4)-h_pb-d_pnl w_pb h_pb]), ...
                'ButtonPushedFcn',{@app.filterDataWithButterworthFilter, ...
                uiFig, ax_prof, ax_spectrum});

            pb_accept = uibutton(pnl, 'push', ...
                'Text','accept','FontSize',18, 'FontWeight','bold', ...
                'Position',round([ ...
                (pnl.InnerPosition(3)/3-w_pb)/2+2*pnl.InnerPosition(3)/3 ...
                pnl.InnerPosition(4)-2*h_pb-2*d_pnl w_pb h_pb]), ...
                'ButtonPushedFcn',{@app.filterDataWithButterworthFilter, ...
                uiFig, ax_prof, ax_spectrum});
            
            pb_Cancel = uibutton(pnl, ...
                'Text', 'cancel', ...
                'Position',round([ ...
                (pnl.InnerPosition(3)/3-w_pb)/2+2*pnl.InnerPosition(3)/3 ...
                pnl.InnerPosition(4)-3*h_pb-3*d_pnl w_pb h_pb]), ...
                'FontSize',18, 'FontWeight','normal', ...
                'ButtonPushedFcn', @(h,~)delete(h.Parent.Parent));

            % filter data using default values
            app.filterDataWithButterworthFilter([], [], ...
                uiFig, ax_prof, ax_spectrum)


        end

        % Menu selected function: Menu_settings
        function setSettingsWindow(app, event)

            % create dialog window
            % in pixels
            w_fig = 1000;
            h_fig = 400;
            d = 10;
            w_pnl = w_fig-2*d;
            h_pnl = (h_fig-3*d)/2;
            h_pb = 60;
            w_pb = 150;

            posFig = round([round(app.UIFigure.Position(1)+app.UIFigure.Position(3)/2-w_fig/2), ...
                round(app.UIFigure.Position(2)+app.UIFigure.Position(4)/2-h_fig/2), ...
                w_fig, h_fig]);
            uifig = uifigure( ...
                'WindowStyle','modal',... % modal normal
                'Units', 'pixels', ...
                'Position', posFig, ...
                'Name', 'Settings', 'Tag','Settings');
            % create table with filters selection
            uipnl_f = uipanel(uifig, ...
                'Title','filters applied in sequence after loading of data:', ...
                'FontSize',16, 'FontWeight','bold', ...
                'Position',[d h_fig-d-h_pnl w_pnl h_pnl]);
            uit_f = uitable(uipnl_f, 'Data',app.data.defaultFiltersUsed, ...
                'ColumnEditable',[true, false,true, false,true, false,true, false,true], ...
                'FontSize',14, 'Tag','filtersSelectionTbl', ...
                'Position',[d d ...
                            uipnl_f.InnerPosition(3)-2*d ...
                            uipnl_f.InnerPosition(4)-2*d], ...
                'CellEditCallback',{@app.setFilterAndDlmSettings,uifig});
            s1_f = uistyle('FontWeight','bold');
            addStyle(uit_f, s1_f, 'column',1)
            s2_f = uistyle('HorizontalAlignment','center');
            addStyle(uit_f, s2_f, 'column',(2:9))

            % add panel to set up deep learning model settings
            uipnl_conda = uipanel(uifig, ...
                'Title','deep learning model settings:', ...
                'FontSize',16, 'FontWeight','bold', ...
                'Position',[d h_fig-2*d-2*h_pnl w_pnl-3*d-w_pb h_pnl]);
            % add table to set up classes of events in data
            uit_c_w = round(uipnl_conda.InnerPosition(3)/3);
            uit_c = uitable(uipnl_conda, ...
                'Data',app.data.DLMclasses, ...
                'ColumnEditable',[true, true], ...
                'FontSize',14, 'Tag','DLMclassesSelectionTbl', ...
                'Position',[d d ...
                            uit_c_w ...
                            uipnl_conda.InnerPosition(4)-2*d], ...
                'CellEditCallback',{@app.setFilterAndDlmSettings,uifig});
            addStyle(uit_c, s1_f, 'column',1)
            addStyle(uit_c, ...
                uistyle('HorizontalAlignment','center'), 'column',2)
            % conda environmet
            w_pb_conda = round((uipnl_conda.InnerPosition(3)-uit_c_w-5*d)/3);
            h_editTxt = 25;
            h_pb_conda = round(uipnl_conda.InnerPosition(4)-4*d-2.5*h_editTxt);
            % path to conda environment
            uibutton(uipnl_conda, 'push', ...
                'Text','get path to conda environment', ...
                'FontSize',16, 'FontWeight','bold', 'WordWrap','on', ...
                'Position',[2*d+uit_c_w, ...
                            uipnl_conda.InnerPosition(4)-d-h_pb_conda, ...
                            w_pb_conda, ...
                            h_pb_conda], ...
                'ButtonPushedFcn',{@app.getCondaEnvDirPath,uifig});
            % create conda environment
            uibutton(uipnl_conda, 'push', ...
                'Text','create required conda environment', ...
                'FontSize',16, 'FontWeight','bold', 'WordWrap','on',...
                'Position',[3*d+w_pb_conda+uit_c_w, ...
                            uipnl_conda.InnerPosition(4)-d-h_pb_conda, ...
                            w_pb_conda, ...
                            h_pb_conda], ...
                'ButtonPushedFcn',{@app.createCondaEnv,uifig});
            % create conda environment
            uibutton(uipnl_conda, 'push', ...
                'Text','train deep learning model', ...
                'FontSize',16, 'FontWeight','bold', 'WordWrap','on',...
                'Position',[4*d+2*w_pb_conda+uit_c_w, ...
                            uipnl_conda.InnerPosition(4)-d-h_pb_conda, ...
                            w_pb_conda, ...
                            h_pb_conda], ...
                'ButtonPushedFcn',{@app.trainDLM,uifig});
            % show path to conda environment
            uilabel(uipnl_conda, ...
                'Text',{'path to required conda environment'; ...
                '(e.g. /Users/name/opt/anaconda3/envs/xytCaSignals)'}, ...
                'FontSize',16, ...
                'Position',round([2*d+uit_c_w ...
                            uipnl_conda.InnerPosition(4)-2*d-h_pb_conda-1.5*h_editTxt ...
                            uipnl_conda.InnerPosition(3)-3*d-uit_c_w ...
                            1.5*h_editTxt]));
            uiedit_condaPath = uieditfield(uipnl_conda, 'text', ...
                'Value',app.data.condaEnvPath, 'FontSize',16, ...
                'Position',round([2*d+uit_c_w ...
                            uipnl_conda.InnerPosition(4)-2.5*d-h_pb_conda-2.5*h_editTxt ...
                            uipnl_conda.InnerPosition(3)-3*d-uit_c_w ...
                            h_editTxt]));
            
            % add edit fields to set pixel sizes 
            uilabel(uifig, 'Text','pixel size (µm):', ...
                'FontSize',16, 'HorizontalAlignment','center',...
                'Position',round([uipnl_conda.Position(3)+2*d ...
                            uipnl_conda.OuterPosition(4)-h_editTxt ...
                            w_pb ...
                            h_editTxt]));
            uiedit_pxSz = uieditfield(uifig, 'numeric', ...
                'Value',app.data.pxSz, ...
                'FontSize',16, 'FontWeight','bold', ...
                'HorizontalAlignment','center', ...
                'Position',round([uipnl_conda.Position(3)+2*d+w_pb/4 ...
                            uipnl_conda.OuterPosition(4)-2*h_editTxt ...
                            w_pb/2 ...
                            h_editTxt]));
            uilabel(uifig, 'Text','time step (ms):', ...
                'FontSize',16, 'HorizontalAlignment','center',...
                'Position',round([uipnl_conda.Position(3)+2*d ...
                            uipnl_conda.OuterPosition(4)-3*h_editTxt-d ...
                            w_pb ...
                            h_editTxt]));
            uiedit_pxSzT = uieditfield(uifig, 'numeric', ...
                'Value',app.data.pxSzT, ...
                'FontSize',16, 'FontWeight','bold',...
                'HorizontalAlignment','center', ...
                'Position',round([uipnl_conda.Position(3)+2*d+w_pb/4 ...
                            uipnl_conda.OuterPosition(4)-4*h_editTxt-d ...
                            w_pb/2 ...
                            h_editTxt]));
            hObjs.uiTblFilt = uit_f;
            hObjs.uiTblClasses = uit_c;
            hObjs.uiTblFilt = uit_f;
            hObjs.uiEditCondaPath = uiedit_condaPath;
            hObjs.uiEditPxSz = uiedit_pxSz;
            hObjs.uiEditPxSzT = uiedit_pxSzT;
            setappdata(uifig, 'hObjs', hObjs)

            % add pushbutton, accept and save filters and path to conda
            % environment
            uibutton(uifig, 'push', ...
                'Text','Save & Close', ...
                'FontSize',18, 'FontWeight','bold', ...
                'Position',[uipnl_conda.Position(3)+2*d, ...
                            uipnl_conda.Position(4)-4*h_editTxt-2*d-h_pb, ...
                            w_pb, ...
                            h_pb], ...
                'ButtonPushedFcn',{@app.setFilterAndDlmSettings,uifig});

            
        end

        % Callback function: UITable_filters, 
        % ...and 1 other component
        function setAndApplyFilters(app, event)
            switch event.Source.Type
                case 'uitable'
                    % set up filter parameteres
                    app.setFilterAndDlmSettings(event.Source, ...
                        event, app.UIFigure)

                case 'uibutton'
                    % filter data in sequence with filters selected in
                    % filter table
                    % check if there are some data
                    if isempty(app.data.imgData)
                        return
                    end
                    % show state
                    app.stateLampLabel.Text = 'busy';
                    app.Lamp_state.Color = [1 0 0];
                    drawnow
                    % add progressbar
                    h_dlg = uiprogressdlg(app.UIFigure, ...
                        'Indeterminate','on', ...
                        'Message', '', ...
                        'Title', 'Filtering image series.');
                    % get filters names and params
                    filtersSeq = app.UITable_filters.Data;
                    filtersSeq(filtersSeq.filter=='none',:) = [];
                    % filter data
                    imgDataFilt = app.data.imgDataRaw;
                    if ~isempty(filtersSeq)
                        for idx = 1:height(filtersSeq)
                            imgDataFilt = app.filterImgSeries(imgDataFilt, ...
                                filtersSeq(idx,:), h_dlg);
                        end
                    end
                    % save caxis limits
                    app.data.imgDataCaxisLimits = ...
                        prctile(imgDataFilt, [1 99], 'all');
                    % save filtered data
                    app.data.filtersUsed = filtersSeq;
                    app.data.imgData = imgDataFilt;
                    % show selected frame from image series in respective tab
                    switch event.Source.Tag
                        case 'filter_img'
                            % with cell mask if calculated
                            showFilteredImgWithCellMask(app, event, true)
                        case 'filter_img_events'
                            % with detected events
                            showFilteredImgWithEventsBoundaries(app, event, true, false)
                    end
                    % close progress bar
                    close(h_dlg)
                    % show state
                    app.stateLampLabel.Text = 'ready';
                    app.Lamp_state.Color = [0 1 0];
                    drawnow
            end

            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @scrollEventsWithKeyArrows, true);
            app.Menu_openImg = uimenu(app.UIFigure);
            app.Menu_openImg.MenuSelectedFcn = createCallbackFcn(app, @openImg, true);
            app.Menu_openImg.Text = '     open image     ';
            app.Menu_openImg.Tag = 'menu_openImg';
            app.Menu_settings = uimenu(app.UIFigure);
            app.Menu_settings.MenuSelectedFcn = createCallbackFcn(app, @setSettingsWindow, true);
            app.Menu_settings.Text = '    settings    ';
            app.Menu_loadAnalyzedImgSeries = uimenu(app.UIFigure);
            app.Menu_loadAnalyzedImgSeries.MenuSelectedFcn = createCallbackFcn(app, @loadAnalyzedImgSeries, true);
            app.Menu_loadAnalyzedImgSeries.Text = '     load analyzed image series     ';
            app.Menu_loadAnalyzedImgSeries.Tag = 'menu_loadAnalyzedImgSeries';
            app.Menu_saveAxesAsImgs = uimenu(app.UIFigure);
            app.Menu_saveAxesAsImgs.MenuSelectedFcn = createCallbackFcn(app, @saveAxesContentToFile, true);
            app.Menu_saveAxesAsImgs.Text = '     save axes as images     ';
            app.Menu_convertMatFileToTIFFs = uimenu(app.UIFigure);
            app.Menu_convertMatFileToTIFFs.MenuSelectedFcn = createCallbackFcn(app, @convertMatFileToTIFFs, true);
            app.Menu_convertMatFileToTIFFs.Enable = 'off';
            app.Menu_convertMatFileToTIFFs.Text = '     convert .mat file to tifs     ';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab_CaEventsDetection = uitab(app.TabGroup);
            app.Tab_CaEventsDetection.Title = 'Ca events detection';
            app.Tab_CaEventsDetection.Tag = 'detectionTab';
            app.UIAxes_img = uiaxes(app.Tab_CaEventsDetection);
            title(app.UIAxes_img, 'xyt recording')
            xlabel(app.UIAxes_img, 'x (pixels)')
            ylabel(app.UIAxes_img, 'y (pixels)')
            zlabel(app.UIAxes_img, 'Z')
            app.UIAxes_img.Tag = 'img_ax';
            app.UIAxes_avrgCellSignal = uiaxes(app.Tab_CaEventsDetection);
            title(app.UIAxes_avrgCellSignal, 'average signal from cell area')
            xlabel(app.UIAxes_avrgCellSignal, 'frame')
            ylabel(app.UIAxes_avrgCellSignal, 'fluorescence')
            zlabel(app.UIAxes_avrgCellSignal, 'Z')
            app.UITable_imgMeta = uitable(app.Tab_CaEventsDetection);
            app.UITable_imgMeta.ColumnName = {'image path'; '# of channels'; '# of images'; 'img size x (px)'; 'img size y (px)'; 'pixel size (um)'; 'time step (ms)'};
            app.UITable_imgMeta.RowName = {};
            app.UITable_imgMeta.ColumnEditable = [false false false false false true true];
            app.UITable_imgMeta.CellEditCallback = createCallbackFcn(app, @imgMetadataTableCellEdit, true);
            app.UITable_imgMeta.Tag = 'metadataTable';
            app.Panel_caEventsDetection = uipanel(app.Tab_CaEventsDetection);
            app.Panel_caEventsDetection.Title = 'events detection';
            app.selectmethodDropDownLabel = uilabel(app.Panel_caEventsDetection);
            app.selectmethodDropDownLabel.Text = 'select method';
            app.DropDown_detectionMethod = uidropdown(app.Panel_caEventsDetection);
            app.DropDown_detectionMethod.Items = {'U-Net', 'threshold'};
            app.DropDown_detectionMethod.Tag = 'drop_methodToDetect';
            app.DropDown_detectionMethod.Value = 'threshold';
            app.Button_detectevents = uibutton(app.Panel_caEventsDetection, 'push');
            app.Button_detectevents.ButtonPushedFcn = createCallbackFcn(app, @detectCalciumEvents, true);
            app.Button_detectevents.Tag = 'detectEvents';
            app.Button_detectevents.Text = 'detect events';
            app.Panel_unetThresholds = uipanel(app.Panel_caEventsDetection);
            app.Panel_unetThresholds.Title = 'U-Net thresholds';
            app.puffEditFieldLabel = uilabel(app.Panel_unetThresholds);
            app.puffEditFieldLabel.Text = 'puff';
            app.EditField_puff_thr = uieditfield(app.Panel_unetThresholds, 'numeric');
            app.EditField_puff_thr.Limits = [0 1];
            app.EditField_puff_thr.Tooltip = {'regions detected with U-Net with probability higher than x are accepted as respective events. Choose number from [0,1]'};
            app.EditField_puff_thr.Value = 0.5;
            app.waveEditFieldLabel = uilabel(app.Panel_unetThresholds);
            app.waveEditFieldLabel.Text = 'wave';
            app.EditField_wave_thr = uieditfield(app.Panel_unetThresholds, 'numeric');
            app.EditField_wave_thr.Limits = [0 1];
            app.EditField_wave_thr.Tooltip = {'regions detected with U-Net with probability higher than x are accepted as respective events. Choose number from [0,1]'};
            app.EditField_wave_thr.Value = 0.5;
            app.sparkEditField_5Label = uilabel(app.Panel_unetThresholds);
            app.sparkEditField_5Label.Text = 'spark';
            app.EditField_spark_thr = uieditfield(app.Panel_unetThresholds, 'numeric');
            app.EditField_spark_thr.Limits = [0 1];
            app.EditField_spark_thr.Tooltip = {'regions detected with U-Net with probability higher than x are accepted as respective events. Choose number from [0,1]'};
            app.EditField_spark_thr.Value = 0.5;
            app.Panel_batchMode = uipanel(app.Panel_caEventsDetection);
            app.Panel_batchMode.Title = 'batch mode';
            app.Button_batchAnalysis = uibutton(app.Panel_batchMode, 'push');
            app.Button_batchAnalysis.ButtonPushedFcn = createCallbackFcn(app, @batchModeAnalysis, true);
            app.Button_batchAnalysis.Text = {'select folder'; '(image series, .tif)'};
            app.CheckBox_includeSubfolders = uicheckbox(app.Panel_batchMode);
            app.CheckBox_includeSubfolders.Text = 'include subfolders';
            app.CheckBox_includeSubfolders.Value = true;
            app.typeofprocessDropDownLabel = uilabel(app.Panel_batchMode);
            app.typeofprocessDropDownLabel.Text = 'type of process';
            app.DropDown_typeOfAnalysisInBatch = uidropdown(app.Panel_batchMode);
            app.DropDown_typeOfAnalysisInBatch.Items = {'detect & analyze events', 'detect events'};
            app.DropDown_typeOfAnalysisInBatch.Value = 'detect & analyze events';
            app.CheckBox_findCellMaskInBatch = uicheckbox(app.Panel_batchMode);
            app.CheckBox_findCellMaskInBatch.Text = 'find cell mask';
            app.CheckBox_findCellMaskInBatch.Value = true;
            app.CheckBox_calcSelfRatioInBatch = uicheckbox(app.Panel_batchMode);
            app.CheckBox_calcSelfRatioInBatch.Text = 'calculate self-ratio';
            app.CheckBox_calcSelfRatioInBatch.Value = true;
            app.CheckBox_saveDetection = uicheckbox(app.Panel_caEventsDetection);
            app.CheckBox_saveDetection.Text = {'save detection '; '(.mat file)'};
            app.CheckBox_saveDetection.Value = true;
            app.Panel_imgVisual = uipanel(app.Tab_CaEventsDetection);
            app.Panel_imgVisual.Title = 'image series visualization control';
            app.Slider_imgFrame = uislider(app.Panel_imgVisual);
            app.Slider_imgFrame.ValueChangedFcn = createCallbackFcn(app, @showImgInSeriesSelectedWithSlider, true);
            app.Slider_imgFrame.ValueChangingFcn = createCallbackFcn(app, @showImgInSeriesSelectedWithSlider, true);
            app.Slider_imgFrame.Tag = 'img_sld';
            app.Button_play = uibutton(app.Panel_imgVisual, 'push');
            app.Button_play.ButtonPushedFcn = createCallbackFcn(app, @playWholeImgSeries, true);
            app.Button_play.Tag = 'play_imgSeries';
            app.Button_play.Text = 'play';
            app.CheckBox_playInLoop = uicheckbox(app.Panel_imgVisual);
            app.CheckBox_playInLoop.Tag = 'playInLoop';
            app.CheckBox_playInLoop.Text = 'play in loop';
            app.Button_stop = uibutton(app.Panel_imgVisual, 'push');
            app.Button_stop.ButtonPushedFcn = createCallbackFcn(app, @stopPlayingImgSeries, true);
            app.Button_stop.Tag = 'stop_imgSeries';
            app.Button_stop.Text = 'stop';
            app.frame1SpinnerLabel = uilabel(app.Panel_imgVisual);
            app.frame1SpinnerLabel.Text = 'frame 1';
            app.Spinner_imgFrame = uispinner(app.Panel_imgVisual);
            app.Spinner_imgFrame.ValueChangingFcn = createCallbackFcn(app, @showImgInSeriesSelectedWithSlider, true);
            app.Spinner_imgFrame.RoundFractionalValues = 'on';
            app.Spinner_imgFrame.ValueChangedFcn = createCallbackFcn(app, @showImgInSeriesSelectedWithSlider, true);
            app.Spinner_imgFrame.Tag = 'frame_select';
            app.Spinner_imgFrame.Value = 1;
            app.framessEditFieldLabel = uilabel(app.Panel_imgVisual);
            app.framessEditFieldLabel.Text = 'frames/s';
            app.EditField_framesPerSec = uieditfield(app.Panel_imgVisual, 'numeric');
            app.EditField_framesPerSec.Tag = 'frameRate';
            app.EditField_framesPerSec.Tooltip = {'maximum is 50 frames per second'};
            app.EditField_framesPerSec.Value = 20;
            app.timemsLabel = uilabel(app.Panel_imgVisual);
            app.timemsLabel.Text = 'time (ms)';
            app.EditField_imgTime = uieditfield(app.Panel_imgVisual, 'numeric');
            app.EditField_imgTime.ValueDisplayFormat = '%5.3f';
            app.EditField_imgTime.Tag = 'showImgTime';
            app.stateLampLabel = uilabel(app.Panel_imgVisual);
            app.stateLampLabel.Text = 'state';
            app.Lamp_state = uilamp(app.Panel_imgVisual);
            app.Lamp_state.Tag = 'stateOfDetection';
            app.Lamp_state.Tooltip = {'show state of detection: events detection running (red), done (green)'};
            app.Panel_visualisation = uipanel(app.Panel_imgVisual);
            app.Panel_visualisation.Title = 'Lookup table';
            app.lookuptableDropDownLabel = uilabel(app.Panel_visualisation);
            app.lookuptableDropDownLabel.Text = 'lookup table';
            app.DropDown_lookuptable = uidropdown(app.Panel_visualisation);
            app.DropDown_lookuptable.Items = {'jet', 'parula', 'turbo', 'hot', 'gray'};
            app.DropDown_lookuptable.ValueChangedFcn = createCallbackFcn(app, @changeColormap, true);
            app.DropDown_lookuptable.Tag = 'selectColormap';
            app.DropDown_lookuptable.Value = 'jet';
            app.setglobalminEditFieldLabel = uilabel(app.Panel_visualisation);
            app.setglobalminEditFieldLabel.Text = 'set global min';
            app.EditField_setglobalmin = uieditfield(app.Panel_visualisation, 'numeric');
            app.EditField_setglobalmin.ValueDisplayFormat = '%11.2f';
            app.EditField_setglobalmin.ValueChangedFcn = createCallbackFcn(app, @changeColormap, true);
            app.EditField_setglobalmin.Tag = 'edit_minImg';
            app.setglobalmaxEditFieldLabel = uilabel(app.Panel_visualisation);
            app.setglobalmaxEditFieldLabel.Text = 'set global max';
            app.EditField_setglobalmax = uieditfield(app.Panel_visualisation, 'numeric');
            app.EditField_setglobalmax.ValueDisplayFormat = '%11.2f';
            app.EditField_setglobalmax.ValueChangedFcn = createCallbackFcn(app, @changeColormap, true);
            app.EditField_setglobalmax.Tag = 'edit_maxImg';
            app.Panel_cropImageSeries = uipanel(app.Panel_imgVisual);
            app.Panel_cropImageSeries.Title = 'Crop image series';
            app.Button_crop = uibutton(app.Panel_cropImageSeries, 'push');
            app.Button_crop.ButtonPushedFcn = createCallbackFcn(app, @cropImgSeries, true);
            app.Button_crop.Tag = 'cropImage';
            app.Button_crop.Text = 'select xy area to crop';
            app.endframeLabel = uilabel(app.Panel_cropImageSeries);
            app.endframeLabel.Text = 'end frame';
            app.EditField_cropEndFrame = uieditfield(app.Panel_cropImageSeries, 'numeric');
            app.EditField_cropEndFrame.Value = 1000;
            app.startframeLabel = uilabel(app.Panel_cropImageSeries);
            app.startframeLabel.Text = 'start frame';
            app.EditField_cropStartFrame = uieditfield(app.Panel_cropImageSeries, 'numeric');
            app.EditField_cropStartFrame.Value = 1;
            app.Button_restart = uibutton(app.Panel_cropImageSeries, 'push');
            app.Button_restart.ButtonPushedFcn = createCallbackFcn(app, @restartAnalysis, true);
            app.Button_restart.Tag = 'restartAnalysis';
            app.Button_restart.Text = 'restart';
            app.Panel_imgFilters = uipanel(app.Panel_imgVisual);
            app.Panel_imgFilters.Title = 'Filters applied to image series in sequence';
            app.filterimageserieswithselectedfiltersButton = uibutton(app.Panel_imgFilters, 'push');
            app.filterimageserieswithselectedfiltersButton.ButtonPushedFcn = createCallbackFcn(app, @setAndApplyFilters, true);
            app.filterimageserieswithselectedfiltersButton.Tag = 'filter_img';
            app.filterimageserieswithselectedfiltersButton.Text = {'filter image series '; 'with selected filters'};
            app.UITable_filters = uitable(app.Panel_imgFilters);
            app.UITable_filters.ColumnName = {'filter'; 'parameter #1'; 'p#1 value'; 'parameter #2'; 'p#2 value'; 'parameter #3'; 'p#3 value'; 'parameter #4'; 'p#4 value'};
            app.UITable_filters.RowName = {};
            app.UITable_filters.ColumnEditable = [true false true false true false true false true];
            app.UITable_filters.CellEditCallback = createCallbackFcn(app, @setAndApplyFilters, true);
            app.UITable_filters.Tag = 'filtersSelectionTbl';
            app.Button_butterworthFilter = uibutton(app.Panel_imgFilters, 'push');
            app.Button_butterworthFilter.ButtonPushedFcn = createCallbackFcn(app, @applyButterworthFilter, true);
            app.Button_butterworthFilter.Text = {'set parametes '; 'of Butterworth filter'};
            app.Panel_cellMask = uipanel(app.Tab_CaEventsDetection);
            app.Panel_cellMask.Title = 'cell mask';
            app.Button_cellMask = uibutton(app.Panel_cellMask, 'push');
            app.Button_cellMask.ButtonPushedFcn = createCallbackFcn(app, @findCellMask, true);
            app.Button_cellMask.Text = 'find cell mask';
            app.kernelsizeLabel = uilabel(app.Panel_cellMask);
            app.kernelsizeLabel.Text = 'kernel size';
            app.EditField_kernelSz_cellMask = uieditfield(app.Panel_cellMask, 'numeric');
            app.EditField_kernelSz_cellMask.Value = 5;
            app.CheckBox_cellMask_show = uicheckbox(app.Panel_cellMask);
            app.CheckBox_cellMask_show.ValueChangedFcn = createCallbackFcn(app, @showCellMask, true);
            app.CheckBox_cellMask_show.Text = 'show cell mask';
            app.CheckBox_cellMask_show.Value = true;
            app.Panel_cellMaskManual = uipanel(app.Panel_cellMask);
            app.Panel_cellMaskManual.Title = 'Manual correction of cell mask';
            app.Button_cellMask_manualCorrection = uibutton(app.Panel_cellMaskManual, 'push');
            app.Button_cellMask_manualCorrection.ButtonPushedFcn = createCallbackFcn(app, @cellMask_manualCorrection, true);
            app.Button_cellMask_manualCorrection.Tag = 'start corrections';
            app.Button_cellMask_manualCorrection.Text = 'start corrections';
            app.CheckBox_cellMask_manualCorrection_toAll = uicheckbox(app.Panel_cellMaskManual);
            app.CheckBox_cellMask_manualCorrection_toAll.Text = 'apply to all frames';
            app.CheckBox_cellMask_manualCorrection_toAll.Value = true;
            app.CheckBox_cellMask_manulalCorrection_fillHoles = uicheckbox(app.Panel_cellMaskManual);
            app.CheckBox_cellMask_manulalCorrection_fillHoles.Text = 'fill holes';
            app.CheckBox_cellMask_manulalCorrection_fillHoles.Value = true;
            app.CheckBox_cellMaskForEachFrame = uicheckbox(app.Panel_cellMask);
            app.CheckBox_cellMaskForEachFrame.ValueChangedFcn = createCallbackFcn(app, @checkBoxes_cellEdgePanel, true);
            app.CheckBox_cellMaskForEachFrame.Text = 'find for each frame';
            app.Tab_analysedEventsBrowser = uitab(app.TabGroup);
            app.Tab_analysedEventsBrowser.Title = 'analysed events browser';
            app.Tab_analysedEventsBrowser.Tag = 'analysisTab';
            app.UIAxes_img_events = uiaxes(app.Tab_analysedEventsBrowser);
            title(app.UIAxes_img_events, 'xyt recording')
            xlabel(app.UIAxes_img_events, 'x (pixels)')
            ylabel(app.UIAxes_img_events, 'y (pixels)')
            zlabel(app.UIAxes_img_events, 'Z')
            app.UIAxes_img_events.Tag = 'img_ax_events';
            app.Panel_detectedEvents = uipanel(app.Tab_analysedEventsBrowser);
            app.Panel_detectedEvents.Title = 'Detected events';
            app.UITable_events = uitable(app.Panel_detectedEvents);
            app.UITable_events.ColumnName = {'event ID'; 'type'; 'start frame'; 'end frame'; 'accepted'};
            app.UITable_events.RowName = {};
            app.UITable_events.ColumnEditable = [false true false false true];
            app.UITable_events.CellEditCallback = createCallbackFcn(app, @showSelectedEvent, true);
            app.UITable_events.CellSelectionCallback = createCallbackFcn(app, @showSelectedEvent, true);
            app.UITable_events.Tag = 'detectedEventsTable';
            app.SelectedeventPanel = uipanel(app.Tab_analysedEventsBrowser);
            app.SelectedeventPanel.Title = 'Selected event';
            app.UIAxes_eventTprof = uiaxes(app.SelectedeventPanel);
            title(app.UIAxes_eventTprof, 't-profile; event type (ID# 1)')
            xlabel(app.UIAxes_eventTprof, 'time (ms)')
            ylabel(app.UIAxes_eventTprof, 'amplitude (ΔF/F_0)')
            zlabel(app.UIAxes_eventTprof, 'Z')
            app.UIAxes_eventTprof.Tag = 'tProfile_ax';
            app.UIAxes_eventXYprof = uiaxes(app.SelectedeventPanel);
            title(app.UIAxes_eventXYprof, 'spatial profile in event maximum')
            xlabel(app.UIAxes_eventXYprof, 'x (µm)')
            ylabel(app.UIAxes_eventXYprof, 'y (µm)')
            zlabel(app.UIAxes_eventXYprof, 'F')
            app.UIAxes_eventXYprof.Tag = '2DGaussFit_ax';
            app.UITable_eventParams = uitable(app.SelectedeventPanel);
            app.UITable_eventParams.ColumnName = {'Amplitude (∆F/F0)'; 'TTP (ms)'; 'FDHM (ms)'; 'tauRise (ms)'; 'tauDecay (ms)'; 'FWHMx (µm)'; 'FWHMy (µm)'; 'WaveSpeed (µm/s)'};
            app.UITable_eventParams.RowName = {};
            app.DropDown_spatialProfile = uidropdown(app.SelectedeventPanel);
            app.DropDown_spatialProfile.Items = {'x-profile', 'y-profile'};
            app.DropDown_spatialProfile.ValueChangedFcn = createCallbackFcn(app, @showSelectedEvent, true);
            app.DropDown_spatialProfile.Value = 'x-profile';
            app.Button_refitEvent = uibutton(app.SelectedeventPanel, 'push');
            app.Button_refitEvent.ButtonPushedFcn = createCallbackFcn(app, @reanalyzeSelectedEventWindow, true);
            app.Button_refitEvent.Text = 'refit selected event';
            app.Panel_imgVisual_events = uipanel(app.Tab_analysedEventsBrowser);
            app.Panel_imgVisual_events.Title = 'image series detected events visualization control';
            app.Slider_imgFrame_events = uislider(app.Panel_imgVisual_events);
            app.Slider_imgFrame_events.ValueChangedFcn = createCallbackFcn(app, @showImgInSeriesSelectedWithSlider, true);
            app.Slider_imgFrame_events.ValueChangingFcn = createCallbackFcn(app, @showImgInSeriesSelectedWithSlider, true);
            app.Slider_imgFrame_events.Tag = 'img_sld_events';
            app.Button_play_events = uibutton(app.Panel_imgVisual_events, 'push');
            app.Button_play_events.ButtonPushedFcn = createCallbackFcn(app, @playWholeImgSeries, true);
            app.Button_play_events.Tag = 'play_imgSeries_events';
            app.Button_play_events.Text = 'play';
            app.CheckBox_playInLoop_events = uicheckbox(app.Panel_imgVisual_events);
            app.CheckBox_playInLoop_events.Tag = 'playInLoop_events';
            app.CheckBox_playInLoop_events.Text = 'play in loop';
            app.Button_stop_events = uibutton(app.Panel_imgVisual_events, 'push');
            app.Button_stop_events.ButtonPushedFcn = createCallbackFcn(app, @stopPlayingImgSeries, true);
            app.Button_stop_events.Tag = 'stop_imgSeries_events';
            app.Button_stop_events.Text = 'stop';
            app.frame1SpinnerLabel_2 = uilabel(app.Panel_imgVisual_events);
            app.frame1SpinnerLabel_2.Text = 'frame 1';
            app.Spinner_imgFrame_events = uispinner(app.Panel_imgVisual_events);
            app.Spinner_imgFrame_events.RoundFractionalValues = 'on';
            app.Spinner_imgFrame_events.ValueChangedFcn = createCallbackFcn(app, @showImgInSeriesSelectedWithSlider, true);
            app.Spinner_imgFrame_events.Tag = 'frame_select_events';
            app.Spinner_imgFrame_events.Value = 1;
            app.framessEditFieldLabel_2 = uilabel(app.Panel_imgVisual_events);
            app.framessEditFieldLabel_2.Text = 'frames/s';
            app.EditField_framesPerSec_events = uieditfield(app.Panel_imgVisual_events, 'numeric');
            app.EditField_framesPerSec_events.Tag = 'frameRate_events';
            app.EditField_framesPerSec_events.Tooltip = {'maximum is 50 frames per second'};
            app.EditField_framesPerSec_events.Value = 20;
            app.timemsLabel_2 = uilabel(app.Panel_imgVisual_events);
            app.timemsLabel_2.Text = 'time (ms)';
            app.EditField_imgTime_events = uieditfield(app.Panel_imgVisual_events, 'numeric');
            app.EditField_imgTime_events.ValueDisplayFormat = '%5.3f';
            app.EditField_imgTime_events.Tag = 'showImgTime_events';
            app.stateLampLabel_2 = uilabel(app.Panel_imgVisual_events);
            app.stateLampLabel_2.Text = 'state';
            app.Lamp_state_events = uilamp(app.Panel_imgVisual_events);
            app.Lamp_state_events.Tag = 'stateOfDetection';
            app.Lamp_state_events.Tooltip = {'show state of detection: events detection running (red), done (green)'};
            app.Panel_visualisation_events = uipanel(app.Panel_imgVisual_events);
            app.Panel_visualisation_events.Title = 'Visualisation';
            app.lookuptableDropDownLabel_3 = uilabel(app.Panel_visualisation_events);
            app.lookuptableDropDownLabel_3.Text = 'lookup table';
            app.DropDown_lookuptable_events = uidropdown(app.Panel_visualisation_events);
            app.DropDown_lookuptable_events.Items = {'jet', 'parula', 'turbo', 'hot', 'gray'};
            app.DropDown_lookuptable_events.ValueChangedFcn = createCallbackFcn(app, @changeColormap, true);
            app.DropDown_lookuptable_events.Tag = 'selectColormap_events';
            app.DropDown_lookuptable_events.Value = 'jet';
            app.setglobalminEditFieldLabel_2 = uilabel(app.Panel_visualisation_events);
            app.setglobalminEditFieldLabel_2.Text = 'set global min';
            app.EditField_setglobalmin_events = uieditfield(app.Panel_visualisation_events, 'numeric');
            app.EditField_setglobalmin_events.ValueDisplayFormat = '%11.2f';
            app.EditField_setglobalmin_events.ValueChangedFcn = createCallbackFcn(app, @changeColormap, true);
            app.EditField_setglobalmin_events.Tag = 'edit_minImg_events';
            app.setglobalmaxEditFieldLabel_2 = uilabel(app.Panel_visualisation_events);
            app.setglobalmaxEditFieldLabel_2.Text = 'set global max';
            app.EditField_setglobalmax_events = uieditfield(app.Panel_visualisation_events, 'numeric');
            app.EditField_setglobalmax_events.ValueDisplayFormat = '%11.2f';
            app.EditField_setglobalmax_events.ValueChangedFcn = createCallbackFcn(app, @changeColormap, true);
            app.EditField_setglobalmax_events.Tag = 'edit_maxImg_events';
            app.CheckBox_showevents_events = uicheckbox(app.Panel_imgVisual_events);
            app.CheckBox_showevents_events.ValueChangedFcn = createCallbackFcn(app, @showDetectedEvents_checkBoxes, true);
            app.CheckBox_showevents_events.Tag = 'check_showEvents';
            app.CheckBox_showevents_events.Text = 'show events';
            app.CheckBox_showevents_events.Value = true;
            app.CheckBox_showmask_events = uicheckbox(app.Panel_imgVisual_events);
            app.CheckBox_showmask_events.ValueChangedFcn = createCallbackFcn(app, @showDetectedEvents_checkBoxes, true);
            app.CheckBox_showmask_events.Tag = 'check_showMaskOfEvents';
            app.CheckBox_showmask_events.Tooltip = {'show mask of events'};
            app.CheckBox_showmask_events.Text = 'show masks of events';
            app.CheckBox_showmask_events.Value = true;
            app.CheckBox_showBB_events = uicheckbox(app.Panel_imgVisual_events);
            app.CheckBox_showBB_events.ValueChangedFcn = createCallbackFcn(app, @showDetectedEvents_checkBoxes, true);
            app.CheckBox_showBB_events.Tag = 'check_showBBofEvents';
            app.CheckBox_showBB_events.Tooltip = {'show bounding box of events'};
            app.CheckBox_showBB_events.Text = 'show BB of events';
            app.CheckBox_showBB_events.Value = true;
            app.CheckBox_showCellMask_events = uicheckbox(app.Panel_imgVisual_events);
            app.CheckBox_showCellMask_events.ValueChangedFcn = createCallbackFcn(app, @showDetectedEvents_checkBoxes, true);
            app.CheckBox_showCellMask_events.Tag = 'check_showCellMask_events';
            app.CheckBox_showCellMask_events.Text = 'show cell boundary';
            app.CheckBox_showCellMask_events.Value = true;
            app.CheckBox_showSelectedEvent = uicheckbox(app.Panel_imgVisual_events);
            app.CheckBox_showSelectedEvent.Tag = 'checkBox_showSelectedEvent';
            app.CheckBox_showSelectedEvent.Text = 'show only selected event from table';
            app.Button_save = uibutton(app.Tab_analysedEventsBrowser, 'push');
            app.Button_save.ButtonPushedFcn = createCallbackFcn(app, @saveAnalysis, true);
            app.Button_save.Text = 'save analysis';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CaSignalsAnalysisXYT

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @start_up_fcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end